classdef OPERA_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OPERA                        matlab.ui.Figure
        StandardizeLabel             matlab.ui.control.Label
        StandardizeSwitch            matlab.ui.control.Switch
        InfoModels_3                 matlab.ui.control.Button
        InfoModels_2                 matlab.ui.control.Button
        InfoResutls                  matlab.ui.control.Button
        BrowseButton_2               matlab.ui.control.Button
        InfoOutput                   matlab.ui.control.Button
        InfoModels                   matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        OutputoptionsPanel           matlab.ui.container.Panel
        ExperimentalvaluesCheckBox   matlab.ui.control.CheckBox
        IncludedescriptorvaluesCheckBox  matlab.ui.control.CheckBox
        KeepfulldescriptorsfilesCheckBox  matlab.ui.control.CheckBox
        NearestneighborsCheckBox     matlab.ui.control.CheckBox
        SeparatefilesCheckBox        matlab.ui.control.CheckBox
        ResultssummaryTextArea       matlab.ui.control.TextArea
        ResultssummaryTextAreaLabel  matlab.ui.control.Label
        ModelsPanel                  matlab.ui.container.Panel
        Caco2CheckBox                matlab.ui.control.CheckBox
        ClintCheckBox                matlab.ui.control.CheckBox
        FUBCheckBox                  matlab.ui.control.CheckBox
        ADMEpropertiesCheckBox       matlab.ui.control.CheckBox
        AcuteToxCATMoSCheckBox       matlab.ui.control.CheckBox
        ARCoMPARACheckBox            matlab.ui.control.CheckBox
        ERCERAPPCheckBox             matlab.ui.control.CheckBox
        KOCCheckBox                  matlab.ui.control.CheckBox
        KMCheckBox                   matlab.ui.control.CheckBox
        RBiodegCheckBox              matlab.ui.control.CheckBox
        BiodegCheckBox               matlab.ui.control.CheckBox
        AOHCheckBox                  matlab.ui.control.CheckBox
        LogBCFCheckBox               matlab.ui.control.CheckBox
        ToxicityendpointsCheckBox    matlab.ui.control.CheckBox
        EnvironmentalfateCheckBox    matlab.ui.control.CheckBox
        LogDCheckBox                 matlab.ui.control.CheckBox
        pKaCheckBox                  matlab.ui.control.CheckBox
        KOACheckBox                  matlab.ui.control.CheckBox
        HLCheckBox                   matlab.ui.control.CheckBox
        WSCheckBox                   matlab.ui.control.CheckBox
        VPCheckBox                   matlab.ui.control.CheckBox
        BPCheckBox                   matlab.ui.control.CheckBox
        PhyschempropertiesCheckBox   matlab.ui.control.CheckBox
        MPCheckBox                   matlab.ui.control.CheckBox
        LogPCheckBox                 matlab.ui.control.CheckBox
        CalculateButton              matlab.ui.control.Button
        OutputTextArea               matlab.ui.control.TextArea
        OutputTextAreaLabel          matlab.ui.control.Label
        InputTextArea                matlab.ui.control.TextArea
        InputLabel                   matlab.ui.control.Label
        BrowseButton                 matlab.ui.control.Button
    end

    properties (Access = public)
        Outpath % Description
        %standardize
        Version='2.9';
        SubVersion='2.9.6';
        Date='(October 2026)';
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %GETUSERDIR   return the user home directory.
            if ispc
                userDir = winqueryreg('HKEY_CURRENT_USER',...
                    ['Software\Microsoft\Windows\CurrentVersion\' ...
                    'Explorer\Shell Folders'],'Personal');
            else
                userDir = char(java.lang.System.getProperty('user.home'));
            end

            [filename, pathname] = uigetfile({'*.smi;*.sdf;*.mol;*.txt','Structure Files (*.smi,*.sdf,*.mol,*.txt)'}, 'Input File',userDir);
            if isequal(filename,0)||isequal(pathname,0)
                msgbox('No selected file', 'Warning','warn');
                return;
            end
            StructureFile= strcat(pathname, filename);
            FileOut= strcat(pathname, [strrep(filename,'.','-'),'_OPERA',app.Version,'Pred.csv']);
            app.InputTextArea.Value=StructureFile;
            app.OutputTextArea.Value=FileOut;
            app.Outpath=pathname;
            StructureFile=char(StructureFile);
            if ~exist(StructureFile,'file')
                msgbox('Input file does not exit or corrupt.','Error','error');
                return;
            else
                if strcmpi(StructureFile(length(StructureFile)-3:end),'.smi')
                    if ispc
                        [~, numlines] = system(['FINDSTR /R /N "^.*" ', strcat('"',char(StructureFile),'"') ,' | FIND /C ":"']);%Win
                    else
                        [~, numlines] = system(['cat ', strcat('"',char(StructureFile),'"'),' | sed "/^\s*$/d" | wc -l ']); %linux
                    end
                    app.ResultssummaryTextArea.Value=['Structures in SMILES file: ',numlines];
                elseif strcmpi(StructureFile(length(StructureFile)-3:end),'.txt')
                    if ispc
                        [~, numlines] = system(['FINDSTR /R /N "^.*" ', strcat('"',char(StructureFile),'"'),' | FIND /C ":"']);%win
                    else
                        [~, numlines] = system(['cat ', strcat('"',char(StructureFile),'"'),' | sed "/^\s*$/d" | wc -l ']); %linux
                    end
                    app.ResultssummaryTextArea.Value=['Found IDs in input text file: ',numlines];
                elseif strcmpi(StructureFile(length(StructureFile)-3:end),'.sdf')
                    if ispc
                        [~, numlines] = system(['FINDSTR /R /N "^.*\$\$\$\$" ', strcat('"',char(StructureFile),'"') ,' | FIND /C ":"']);%Win
                    else
                        [~, numlines] = system(['grep -F "\$\$\$\$" ', strcat('"',char(StructureFile),'"'), ' | wc -l']); %linux
                    end
                    app.ResultssummaryTextArea.Value=['Structures in SDF file: ',numlines];
                elseif strcmpi(StructureFile(length(StructureFile)-3:end),'.mol')
                    app.ResultssummaryTextArea.Value='Using structure in MOL file';
                else
                    msgbox('Unrecognized extension. Check input file', 'Error','error');
                    return;
                end
            end
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [filename, pathname] = uiputfile({'*.txt;*.csv','Text Files (*.txt,*.csv)'}, 'Output File');
            FileOut= strcat(pathname, filename);
            app.OutputTextArea.Value=FileOut;
            app.Outpath=pathname;
        end

        % Callback function: CalculateButton, ResultssummaryTextArea
        function CalculateButtonPushed(app, event)
            
            if isdeployed
                warning('off','MATLAB:table:ModifiedAndSavedVarnames');
                warning('off','MATLAB:table:RowsAddedExistingVars');
            else
                warning('on','MATLAB:table:ModifiedAndSavedVarnames');
                warning('on','MATLAB:table:RowsAddedExistingVars');
            end
            
            salt=0;
            FileSalt={};
            %             help=0;
            %             tox=0;
            %             pc=0;
            %             ef=0;
            fp=0;
            cdk=0;
            nf=0;
            f=0;
            sep = app.SeparatefilesCheckBox.Value;
            exp= app.ExperimentalvaluesCheckBox.Value;
            neighbors = app.NearestneighborsCheckBox.Value;
            printtDesc= app.IncludedescriptorvaluesCheckBox.Value;
            clean=app.KeepfulldescriptorsfilesCheckBox.Value;
            if strcmp(app.StandardizeSwitch.Value ,'On')
                standardize=1;
            else
                standardize=0;
            end
            
            StructureFile=char(app.InputTextArea.Value);
            %msgbox(StructureFile,'Error','error');
            %return;
            if isempty(StructureFile)
                msgbox('Select input file', 'Error','error');
                return;
            end
            FileOut=char(app.OutputTextArea.Value);
            if isempty(FileOut)
                msgbox('Enter output file', 'Error','error');
                return;
            end
            if ~exist(StructureFile,'file')
                msgbox('Input file does not exit or corrupt.','Error','error');
                return;
            end
            
            Mbox(1) = app.LogPCheckBox.Value;
            Mbox(2) = app.MPCheckBox.Value;
            Mbox(3) = app.BPCheckBox.Value;
            Mbox(4) = app.VPCheckBox.Value;
            Mbox(5) = app.WSCheckBox.Value;
            Mbox(6) = app.HLCheckBox.Value;
            Mbox(7) = app.KOACheckBox.Value;
            %Mbox(8) = app.RTCheckBox.Value;
            Mbox(9) = app.pKaCheckBox.Value;
            Mbox(10)= app.LogDCheckBox.Value;
            Mbox(11)= app.LogBCFCheckBox.Value;
            Mbox(12)= app.AOHCheckBox.Value;
            Mbox(13)= app.BiodegCheckBox.Value;
            Mbox(14)= app.RBiodegCheckBox.Value;
            Mbox(15)= app.KMCheckBox.Value;
            Mbox(16)= app.KOCCheckBox.Value;
            Mbox(17)= app.FUBCheckBox.Value;
            Mbox(18)= app.ClintCheckBox.Value;
            Mbox(19)= app.Caco2CheckBox.Value;
            Mbox(20)= app.ERCERAPPCheckBox.Value;
            Mbox(21)= app.ARCoMPARACheckBox.Value;
            Mbox(22)= app.AcuteToxCATMoSCheckBox.Value;
            %Mbox(22)= 0;
            Mbox(23)=app.PhyschempropertiesCheckBox.Value;
            
            if isempty(find(Mbox, 1)) %length(find(Mbox))==1
                msgbox('No models selected', 'Error','error');
                return;
                %app.ResultssummaryTextArea.Value=num2str(Mbox);
            else
                steps=length(find(Mbox))+2;%1;
            end
            
            %progress
            step=0;
            d = uiprogressdlg(app.OPERA,'Title','Please Wait','Message','Initializing and loading models...','Cancelable','on');
            timerAll=tic;
            
            Models= {'LogP','MP','BP','VP','WS','HL','KOA','RT','pKa', 'LogD','BCF', 'AOH', 'BioDeg', 'RBioDeg','KM',...
                'KOC','FuB', 'Clint','Caco2', 'CERAPP', 'CoMPARA', 'CATMoS','StrP'};
            prop=Models(Mbox);
            
            %GETUSERDIR   return the user home directory.
            if ispc
                userDir = winqueryreg('HKEY_CURRENT_USER',...
                    ['Software\Microsoft\Windows\CurrentVersion\' ...
                    'Explorer\Shell Folders'],'Personal');
                installdir=fullfile('C:','Program Files','OPERA','application');
            else
                userDir = char(java.lang.System.getProperty('user.home'));
                installdir=fullfile('/','usr','local','bin','OPERA','application');
            end
            %             if ~isdeployed
            %                 installdir=pwd;
            %             end
            
            if ~exist(fullfile(installdir,'padel-full-2.00.jar'),'file')
                
                if isdeployed
                    currentDir=ctfroot;
                    if exist(fullfile(currentDir,'OPERA_installdir.txt'),'file')
                        fid  = fopen(fullfile(currentDir,'OPERA_installdir.txt'),'r');
                        installdir=strip(fread(fid,'*char')');
                        fclose(fid);
                        %currentDir = pwd;
                        if ~exist(fullfile(installdir,'padel-full-2.00.jar'),'file')
                            %app.ResultssummaryTextArea.Value=currentDir;
                            uiwait(msgbox([{'Default install folder was changed during installation.'};...
                                {'You will be prompted to select the custom folder.'}], 'Warning','warning'));
                            installdir = uigetdir(userDir, 'Select installation folder');
                            if ~exist(fullfile(installdir,'padel-full-2.00.jar'),'file')
                                msgbox(sprintf('Wrong Installation folder.\nSelect "application" under "OPERA_UI".'), 'Error','error');
                                return;
                            end
                            fid  = fopen(fullfile(currentDir,'OPERA_installdir.txt'),'w');
                            fprintf(fid,'%s',installdir);
                            fclose(fid);
                        end
                    else
                        uiwait(msgbox([{'Default install folder was changed during installation.'};...
                            {'You will be prompted to select the custom folder.'}], 'Warning','warning'));
                        installdir = uigetdir(userDir, 'Select installation folder');
                        if ~exist(fullfile(installdir,'padel-full-2.00.jar'),'file')
                            msgbox(sprintf('Wrong Installation folder.\nSelect "application" under "OPERA_UI".'), 'Error','error');
                            return;
                        end
                        fid  = fopen(fullfile(currentDir,'OPERA_installdir.txt'),'w');
                        fprintf(fid,'%s',installdir);
                        fclose(fid);
                        
                    end
                    
                else
                    currentDir = pwd;
                    if exist(fullfile(currentDir,'OPERA_installdir.txt'),'file')
                        fid  = fopen(fullfile(currentDir,'OPERA_installdir.txt'),'r');
                        installdir=strip(fread(fid,'*char')');
                        fclose(fid);
                        %currentDir = pwd;
                        if ~exist(fullfile(installdir,'padel-full-2.00.jar'),'file')
                            %app.ResultssummaryTextArea.Value=currentDir;
                            uiwait(msgbox([{'Default install folder was changed during installation.'};...
                                {'You will be prompted to select the custom folder.'}], 'Warning','warning'));
                            installdir = uigetdir(userDir, 'Select installation folder');
                            if ~exist(fullfile(installdir,'padel-full-2.00.jar'),'file')
                                msgbox(sprintf('Wrong Installation folder.\nSelect "application" under "OPERA_UI".'), 'Error','error');
                                return;
                            end
                            fid  = fopen(fullfile(currentDir,'OPERA_installdir.txt'),'w');
                            fprintf(fid,'%s',installdir);
                            fclose(fid);
                        end
                    else
                        uiwait(msgbox([{'Default install folder was changed during installation.'};...
                            {'You will be prompted to select the custom folder.'}], 'Warning','warning'));
                        installdir = uigetdir(userDir, 'Select installation folder');
                        if ~exist(fullfile(installdir,'padel-full-2.00.jar'),'file')
                            msgbox(sprintf('Wrong Installation folder.\nSelect "application" under "OPERA_UI".'), 'Error','error');
                            return;
                        end
                        fid  = fopen(fullfile(currentDir,'OPERA_installdir.txt'),'w');
                        fprintf(fid,'%s',installdir);
                        fclose(fid);
                        
                    end
                end
            end
            
            if strcmpi(StructureFile(length(StructureFile)-3:end),'.smi')
                if ispc
                    [~, numlines] = system(['FINDSTR /R /N "^.*" ', strcat('"',char(StructureFile),'"'),' | FIND /C ":"']);%win
                else
                    [~, numlines] = system(['cat ', strcat('"',char(StructureFile),'"'),' | sed "/^\s*$/d" | wc -l ']); %linux
                end
                app.ResultssummaryTextArea.Value=['Loaded structures from SMILES file: ',numlines];
            elseif strcmpi(StructureFile(length(StructureFile)-3:end),'.txt')
                if ispc
                    [~, numlines] = system(['FINDSTR /R /N "^.*" ', strcat('"',char(StructureFile),'"'),' | FIND /C ":"']);%win
                else
                    [~, numlines] = system(['cat ', strcat('"',char(StructureFile),'"'),' | sed "/^\s*$/d" | wc -l ']); %linux
                end
                app.ResultssummaryTextArea.Value=['Found IDs in input text file: ',numlines];
            elseif strcmpi(StructureFile(length(StructureFile)-3:end),'.sdf')
                if ispc
                    [~, numlines] = system(['FINDSTR /R /N "^.*\$\$\$\$" ', strcat('"',char(StructureFile),'"') ,' | FIND /C ":"']);%win
                else
                    [~, numlines] = system(['grep -F "\$\$\$\$" ', strcat('"',char(StructureFile),'"'), ' | wc -l']); %linux
                end
                app.ResultssummaryTextArea.Value=['Loaded structures from SDF file: ',numlines];
            elseif strcmpi(StructureFile(length(StructureFile)-3:end),'.mol')
                app.ResultssummaryTextArea.Value='Loaded structure from MOL file';
                numlines='1';
            else
                msgbox('Unrecognized extension. Check input file', 'Error','error');
                return;
            end
            
            if str2double(numlines)==0
                msgbox('No structures found! Check the input file.','Error','error');
                return;
            end
            
            %train=load ('OPERA_models.mat', '-mat');
            
            data=upper(prop);
            [a,indLOGD]=ismember('LOGD',data);
            if a
                data=[data, 'PKA', 'LOGP'];
                data(indLOGD)=[];
            end
            train=load ('OPERA_models.mat', '-mat','DSSToxQSARr','StructError','labels','labels_cdk','labels_fp','labels_in','PadelVarIn','PadelVarOut','reorder_CDK',data{:});
            
            %Xlabels=train.labels;
            %XlabelsFP=train.labels_fp;
            
            %find structures
            if strcmpi(StructureFile(length(StructureFile)-3:end),'.txt')
                steps=steps+1;
                step=step+1;
                d.Value=step/steps;
                d.Message = 'Getting structures based on provided IDs.';
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                fid = fopen(StructureFile);
                indic = 1;
                while 1
                    tline = fgetl(fid);
                    if ~ischar(tline)
                        break
                    end
                    if ~isempty(tline)
                        strings{indic}=strtrim(tline);
                        indic = indic + 1;
                    end
                end
                fclose(fid);
                StructureFile=strcat(StructureFile(1:length(StructureFile)-3),'smi');
                fileID = fopen(StructureFile, 'w');
                %f=0;
                La=zeros(str2double(numlines),1);
                Lb=zeros(str2double(numlines),1);
                for i=1:str2double(numlines)
                    
                    if regexp(strings{i},'[0-9]+-[0-9]+-[0-9]')
                        [La(i),Lb(i)] = ismember(strings{i},train.DSSToxQSARr{:,2});
                        SearchID='CASRN';
                    elseif regexp(strings{i},'DTXSID[0-9]+')
                        [La(i),Lb(i)] = ismember(strings{i},train.DSSToxQSARr{:,3});
                        SearchID='DTXSID';
                    elseif regexp(strings{i},'DTXCID[0-9]+')
                        [La(i),Lb(i)] = ismember(strings{i},train.DSSToxQSARr{:,4});
                        SearchID='DTXCID';
                    elseif regexp(strings{i},'[A-Z]+-[A-Z]+-[A-Z]')
                        [La(i),Lb(i)] = ismember(strings{i},train.DSSToxQSARr{:,5});
                        SearchID='InChiKey';
                    end
                    if La(i)
                        f=f+1;
                        FoundBy{f,1}=SearchID;
                        fprintf(fileID,'%s\t%s\n',train.DSSToxQSARr{Lb(i),1},strings{i});
                        if ismember('logp',lower(prop))||ismember('mp',lower(prop))||ismember('Bp',lower(prop))||ismember('vp',lower(prop))||ismember('ws',lower(prop))||ismember('logd',lower(prop))||ismember('catmos',lower(prop))
                            salt=1;
                            %SaltInfo(f,1)=train.DSSToxQSARr.SaltInfo(Lb(i));
                            SaltIndex(f,1)=train.DSSToxQSARr.SaltInfo(Lb(i));
                        end
                    else
                        nf=nf+1;
                        nfID{nf,1}=strings{i};
                        err_index=0;
                        if regexp(strings{i},'[0-9]+-[0-9]+-[0-9]')
                            [~,err_index] = ismember(strings{i},train.StructError{:,2});
                        elseif regexp(strings{i},'DTXSID[0-9]+')
                            [~,err_index] = ismember(strings{i},train.StructError{:,3});
                        elseif regexp(strings{i},'DTXCID[0-9]+')
                            [~,err_index] = ismember(strings{i},train.StructError{:,4});
                        elseif regexp(strings{i},'[A-Z]+-[A-Z]+-[A-Z]')
                            [~,err_index] = ismember(strings{i},train.StructError{:,5});
                        end
                        if err_index
                            SearchError{nf,1}=['Error: ',char(train.StructError{err_index,6})];
                        else
                            SearchError{nf,1}='NotFound';
                        end
                    end
                end
                fclose(fileID);
                if nf>0 && f>0
                    FoundBy=[FoundBy; SearchError];
                end
                numlines=num2str(f);
                app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Found structures based on provided IDs: ',numlines];
                if f==0
                    msgbox('No matching structures found! Check the input file.','Error','error');
                    return;
                end
                %                 if ismember('mp',lower(prop)) && salt==0
                %                     %steps=steps+1;
                %                     %step=step+1;
                %                     %d.Value=step/steps;
                %                     %d.Message = 'Generating and loading salt info for MP.';
                %                     if d.CancelRequested
                %                         msgbox('Canceled by user.', 'Error','error');
                %                         return;
                %                     end
                %                     salt=1;
                %                     FileSalt=strcat(StructureFile(1:length(StructureFile)-4),'_SaltInfo','.csv');
                %                     SaltFile.Name=strings(find(La))';
                %                     SaltFile.SaltInfo=SaltInfo;
                %                     SaltFile=struct2table(SaltFile);
                %                     writetable(SaltFile,FileSalt,'Delimiter',',');
                %                     clear('SaltInfo','SaltFile');
                %                     %read SaltInfo
                %                     SaltIndex=readtable(FileSalt,'delimiter',',');
                %                     if size(SaltIndex,1)==f %size(Xin,1)
                %                         if cellfun(@ischar,table2cell(SaltIndex(1,end)))
                %                             Temp(:,:)=str2double(table2cell(SaltIndex(:,end)));
                %                         else
                %
                %                             Temp(:,:)=SaltIndex{:,end};
                %                         end
                %
                %                         clear('SaltIndex');
                %                         SaltIndex=Temp;
                %                         clear('Temp');
                %                         app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'The number of molecules with salt information: ', num2str(length(find(SaltIndex)))];
                %                     else
                %                         msgbox('The number of saltIDs and structures must match.', 'Error','error');
                %                         return;
                %                     end
                %
                %                     %res.SaltID=SaltIndex;
                %                     %End SaltInfo
                %                 end
                
                
            end
            
            %%
            
            
            %========== output files ==========
            ext=FileOut(length(FileOut)-3:end);
            
            if sep==1
                outputname=cell(size(prop));
                %FileOut=strrep(FileOut,ext,'');
                FileOut=FileOut(1:length(FileOut)-4);
                %app.ResultssummaryTextArea.Value=FileOut;
                for i=1:length(prop)
                    outputname{i}=char(strcat(FileOut, '_', prop(i), ext));
                    %app.ResultssummaryTextArea.Value=outputname{i};
                    [output(i),errmsg]=fopen(outputname{i},'w');
                    if ~isempty(errmsg)
                        msgbox(errmsg, 'Error','error');
                        return;
                    end
                end
                FileOut=outputname;
            else
                steps=steps+1;
                [output,errmsg]=fopen(FileOut,'w');
                if ~isempty(errmsg)
                    msgbox(errmsg, 'Error','error');
                    return;
                end
            end
            
            [L,~] =ismember({'pka','logd'},lower(prop));
            if find(L)
                fp=1;
                steps=steps+2;
            end
            [L,~] =ismember({'fub','clint','caco2','cerapp','compara','catmos'},lower(prop));
            if find(L)
                cdk=1;
                steps=steps+2;
            end
            
            %========== Standardize Structures ==========
            if standardize && f==0
                steps=steps+1;
                step=step+1;
                d.Value=step/steps;
                d.Message = 'Generating QSAR-ready structures.';
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                homedir = char(java.lang.System.getProperty('user.home'));
                if ~exist(fullfile(homedir,'knime-workspace'),'dir')
                    mkdir(fullfile(homedir,'knime-workspace'));
                end
                if ~exist(fullfile(homedir,'knime-workspace','QSAR-ready_2.5.10'),'dir')
                    mkdir(fullfile(homedir,'knime-workspace','QSAR-ready_2.5.10'));
                    [statusCp,messageCp] = copyfile(fullfile(installdir,'knime_4.5.1','knime-workspace','QSAR-ready_2.5.10'),fullfile(homedir,'knime-workspace','QSAR-ready_2.5.10'));
                    if ~statusCp && ~isempty(messageCp)
                        msgbox(messageCp, 'Error','error');
                        return;
                    end
                end
                if ~exist(fullfile(homedir,'Sample_input'),'dir')
                    mkdir(fullfile(homedir,'Sample_input'));
                end
                if ~exist(fullfile(homedir,'Sample_input','Sample_50.sdf'),'file')
                    [statusCp,messageCp] = copyfile(fullfile(installdir,'knime_4.5.1','Sample_input'),fullfile(homedir,'Sample_input'));
                    if ~statusCp && ~isempty(messageCp)
                        msgbox(messageCp, 'Error','error');
                        return;
                    end
                end
                
                [statusKnime,~] =system ([strcat('"',fullfile(installdir,'knime_4.5.1','knime'),'"')...
                    ' -reset -nosplash -nosave -application org.knime.product.KNIME_BATCH_APPLICATION -workflowDir='...
                    strcat('"',fullfile(homedir,'knime-workspace','QSAR-ready_2.5.10'),'"')...
                    ' -workflow.variable=cmd_input,' strcat('"',char(StructureFile),'"') ',String']);
                
                if statusKnime==0
                    salt=1;
                    FileSalt=strcat(StructureFile(1:length(StructureFile)-4),'_QSAR-ready_saltInfo.csv');
                    StructureFile=strcat(StructureFile(1:length(StructureFile)-4),'_QSAR-ready_smi.smi');
                    if ~exist(StructureFile,'file')
                        msgbox('No structures passed standardization. Check input file!', 'Error','error');
                        %rmdir(fullfile(homedir,'knime-workspace','QSAR-ready_2.5.10'),'s');
                        return;
                    end
                    if ispc
                        [~, numlines] = system(['FINDSTR /R /N "^.*" ', strcat('"',char(StructureFile),'"'),' | FIND /C ":"']);%win
                    else
                        [~, numlines] = system(['cat ', strcat('"',char(StructureFile),'"'),' | sed "/^\s*$/d" | wc -l ']); %linux
                    end
                    
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Standardized structures: ',numlines];
                    
                    %Start SaltInfo
                    if salt==1 && ~isempty(FileSalt) && (ismember('logp',lower(prop))||ismember('mp',lower(prop))||ismember('Bp',lower(prop))||ismember('vp',lower(prop))||ismember('ws',lower(prop))||ismember('logd',lower(prop))||ismember('catmos',lower(prop)))
                        %                         if verbose> 0
                        %                             disp('Reading file with salt information.');
                        %                         end
                        try
                            SaltIndex=readtable(FileSalt,'delimiter',',');
                        catch ME
                            if strcmp(ME.identifier,'MATLAB:readtable:OpenFailed')
                                msgbox('Unable to open salt information file', 'Error','error');
                                return;
                            else
                                msgbox(sprintf(ME.message), 'Error','error');
                                return;
                            end
                        end
                        %     if strcmpi(SaltIndex{1},'Name')
                        %         SaltIndex=SaltIndex(
                        if size(SaltIndex,1)==str2double(numlines)
                            if cellfun(@ischar,table2cell(SaltIndex(1,end)))
                                Temp(:,:)=str2double(table2cell(SaltIndex(:,end)));
                            else
                                
                                Temp(:,:)=SaltIndex{:,end};
                            end
                            clear('SaltIndex');
                            SaltIndex=Temp;
                            clear('Temp');
                            %                             if verbose> 0
                            %                                 disp(['The number of molecules with salt information:', num2str(length(find(SaltIndex)))]);
                            %                             end
                        else
                            msgbox('Problem in Salt Info file. Check the input file.','Error','error');
                            return;
                        end
                        %res.SaltID=SaltIndex;
                    end
                    %End SaltInfo
                else
                    msgbox('Standardization process failed. Check the input file.','Error','error');
                    return;
                end
            end
            
            %========== Molecular Descriptors ==========
            
            % Processors visible to the JVM.
            javaRuntime = java.lang.Runtime.getRuntime();
            nCPU = double(javaRuntime.availableProcessors());

            % Number of input molecules was already calculated by OPERA.
            nMolecules = max(1, floor(str2double(numlines)));

            % Conservative OPERA default:
            % - leave one processor available
            % - do not start more workers than molecules
            % - cap the general workstation default at 8
            padelThreads = max(1, min([nMolecules, 8, nCPU - 2]));

            % A small queue is sufficient to keep workers supplied.
            padelWaitingJobs = max(4, 4 * padelThreads);

            padelParallelOptions = sprintf( ...
                ' -threads %d -waitingjobs %d ', ...
                padelThreads, padelWaitingJobs);


            %Calculating PaDEL descriptors...
            step=step+1;
            d.Value=step/steps;
            d.Message = 'Calculating descriptors: PaDEL 2D';
            if d.CancelRequested
                msgbox('Canceled by user.', 'Error','error');
                return;
            end
            %InputDescCDK=strcat(app.Outpath,'PadelDesc.csv');
            InputDesc=strcat(StructureFile(1:length(StructureFile)-4),'_PadelDesc.csv');
            PaDELlogfile=strcat(StructureFile(1:length(StructureFile)-4),'_PaDELlogfile.log');
            %app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; InputDesc];
            %app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;fullfile(installdir,'padel-full-1.00.jar')];
            %InputDesc=app.InputTextArea.Value{:};
            timerPadel=tic;
            [statusDesc,cmdoutDesc] =system (['java -Djava.awt.headless=true -jar ' strcat('"',fullfile(installdir,'padel-full-2.00.jar'),'"')...
                ' -2d -removesalt -standardizenitro -detectaromaticity -retainorder ' padelParallelOptions ' -maxruntime 60000 -dir ' strcat('"',char(StructureFile),'"')...
                ' -file ' strcat('"',InputDesc,'"') ' > ' strcat('"',PaDELlogfile,'"')]); %strcat('"',app.Outpath,PaDELlogfile,'"')]);
            timerPadel=toc(timerPadel);
            
            if statusDesc~=0 && ~isempty(cmdoutDesc)
                msgbox(cmdoutDesc, 'Error','error');
                return;
            end
            
            
            %Loading of PaDEL descriptors file...
            step=step+1;
            d.Value=step/steps;
            d.Message = 'Checking PaDEL descriptors';
            if d.CancelRequested
                msgbox('Canceled by user.', 'Error','error');
                return;
            end
            
            try
                Xin=readtable(InputDesc,'delimiter',',','DatetimeType','text');
            catch ME
                if strcmp(ME.identifier,'MATLAB:readtable:OpenFailed')
                    msgbox('Unable to open PaDEL descriptors file', 'Error','error');
                    return;
                else
                    msgbox(sprintf(ME.message), 'Error','error');
                    return;
                end
            end
            Xlabels=Xin.Properties.VariableNames;
            if strcmpi(Xlabels{1},'Name') || strcmpi(Xlabels{1},'MoleculeID') || strcmpi(Xlabels{1},'Molecule')
                Xin.Properties.VariableNames{1}='Name';
                Xlabels=Xlabels(2:end);
                if isnumeric(Xin.Name)
                    Names=cellstr(num2str(Xin.Name));
                else
                    Names=cellstr(Xin.Name);
                end
                if isnumeric(Names) && strcmpi(ext,'.txt')
                    
                    for i=1:size(Xin,1)
                        MoleculeNames{i,1}=strcat('AUTOGEN_',num2str(Names(i)));
                    end
                else
                    MoleculeNames=Names;
                end
                %Xin=Xin{:,2:end};
                Xin=Xin(:,2:end);
            else
                
                for i=1:size(Xin,1)
                    MoleculeNames{i,1}=strcat('AUTOGEN_',num2str(i));
                end
            end
            if nf>0
                MoleculeNames=[MoleculeNames; nfID];
            end
            if size(Xin,1)==0 || size(Xin,2)==0
                msgbox('Empty PaDEL descriptors file! Check input structure(s).', 'Error','error');
                return;
            elseif size(Xin,1) < str2double(numlines)
                app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'Calculated molecules: ',num2str(size(Xin,1))];
                msgbox('PaDEL descriptors failed. Check input structure(s)!', 'Error','error');
                return;
            elseif salt==1 && (ismember('mp',lower(prop))||ismember('logp',lower(prop))||ismember('logd',lower(prop)))
                if size(SaltIndex,1)~=size(Xin,1)
                    msgbox('The number of saltIDs and structures must match.', 'Error','error');
                    return;
                end
            end
            i=1;
            Temp=zeros(size(Xin));
            while i<=length(Xlabels)
                if cellfun(@ischar,table2cell(Xin(1,i)))
                    Temp(:,i)=str2double(table2cell(Xin(:,i)));
                else
                    
                    Temp(:,i)=Xin{:,i};
                end
                i=i+1;
            end
            app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Calculated PaDEL descriptors: ' num2str(length(Xlabels)) ' (' num2str(round(timerPadel)) ' sec)'];
            
            clear('Xin');
            Xin=Temp;
            clear('Temp');
            
            %Calculating CDK Descriptors...
            if cdk==1
                step=step+1;
                d.Value=step/steps;
                d.Message = 'Calculating descriptors: CDK 2D';
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                %Bond_HA_r=Xin(:,466)./Xin(:,9);
                Amb_str=intersect(find((Xin(:,466)./Xin(:,9))>1.3),find(Xin(:,9)>50));
                Amb_str=unique(sort([Amb_str; find(Xin(:,9)>150)]));
                if ~isempty(Amb_str)%||~isempty(find(Xin(:,9)>150, 1))
                    Amb_str=num2str(Amb_str);
                    Amb_str=strjoin(num2cell(Amb_str(1:length(Amb_str))),', ');
                    %msgbox(sprintf(['Structure(s) number: ', Amb_str,' exceed recommended size limit.\nCDK descriptors might fail or take long time.']),'Error','error');
                    Selection=uiconfirm(app.OPERA,sprintf(['Structure(s) number: ', Amb_str,' exceed recommended size limit. CDK descriptors might fail or take long time.']),'Warning!',...
                        'Options',{'Continue','Cancel'},'Icon','warning','DefaultOption',2);%,'CancelOption',2);
                    if strcmp(Selection,'Cancel')
                        msgbox('Canceled by user.', 'Error','error');
                        return;
                    end
                end
                %InputDescCDK=strcat(app.Outpath,'CDKDesc.csv');
                InputDescCDK=strcat(StructureFile(1:length(StructureFile)-4),'_CDKDesc.csv');
                CDKlogfile=strcat(StructureFile(1:length(StructureFile)-4),'_CDKlogfile.log');
                CDKerr=strcat(StructureFile(1:length(StructureFile)-4),'_CDKerr.log');
                timerPadelCDK=tic;
                [statusDesc,cmdoutDesc] =system (['java -jar ' strcat('"',fullfile(installdir,'CDKDescUI-2.0.jar'),'"') ' -b -a -t all -o ' strcat('"',InputDescCDK,'"')...
                    ' ' strcat('"',char(StructureFile),'"') ' > ' strcat('"',CDKlogfile,'"') ' 2> ' strcat('"',CDKerr,'"')]); %strcat('"',app.Outpath,CDKlogfile,'"') ' 2> ' strcat('"',app.Outpath,CDKerr,'"')]);
                timerCDK=toc(timerPadelCDK);
                if statusDesc~=0 && ~isempty(cmdoutDesc)
                    msgbox(cmdoutDesc, 'Error','error');
                    return;
                end
                %end
                %Loading of CDK descriptors file...
                %if cdk==1
                step=step+1;
                d.Value=step/steps;
                d.Message = 'Checking CDK descriptors';
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                try
                    XinCDK=readtable(InputDescCDK,'delimiter','\t','DatetimeType','text');
                catch ME
                    if strcmp(ME.identifier,'MATLAB:readtable:OpenFailed')
                        msgbox('Unable to open CDK descriptors file', 'Error','error');
                        return;
                    else
                        %error(ME.message);
                        msgbox(ME.message, 'Error','error');
                        return;
                    end
                end
                if size(XinCDK,1)==0 || size(XinCDK,2)==0
                    msgbox('CDK descriptors failed. Check input structures!', 'Error','error');
                    return;
                end
                XinCDK(:,1)=[];
                if size(XinCDK,1)~=size(Xin,1)
                    msgbox('Mismatch between PaDEL and CDK descriptors files', 'Error','error');
                    return;
                elseif strcmpi(XinCDK.Properties.VariableNames(end),'Zagreb')
                    XlabelsCDK=XinCDK.Properties.VariableNames;
                elseif strcmpi(XinCDK.Properties.VariableNames(end),'nAcid')
                    XinCDK=XinCDK(:,train.reorder_CDK);
                    XlabelsCDK=XinCDK.Properties.VariableNames;
                else
                    msgbox('Check CDK descriptors file', 'Error','error');
                    return;
                end
                i=1;
                Temp=zeros(size(XinCDK));
                while i<=length(XlabelsCDK)
                    if cellfun(@ischar,table2cell(XinCDK(1,i)))
                        Temp(:,i)=str2double(table2cell(XinCDK(:,i)));
                    else
                        Temp(:,i)=XinCDK{:,i};
                    end
                    i=i+1;
                end
                
                app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Calculated CDK descriptors: ' num2str(length(XlabelsCDK)) ' (' num2str(round(timerCDK)) ' sec)'];
                clear('XinCDK');
                XinCDK=Temp;
                clear('Temp');
            end
            %Generating PaDEL fingerprints...
            if fp==1
                step=step+1;
                d.Value=step/steps;
                d.Message = 'Calculating descriptors: PaDEL fingerprints';
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                %InputDescFP=strcat(app.Outpath,'PadelFP.csv');
                InputDescFP=strcat(StructureFile(1:length(StructureFile)-4),'_PadelFP.csv');
                PaDELlogfileFP=strcat(StructureFile(1:length(StructureFile)-4),'_PaDELlogfileFP.log');
                timerPadelFP=tic;
                [statusDesc,cmdoutDesc] =system (['java -Djava.awt.headless=true -jar ' strcat('"',fullfile(installdir,'padel-full-2.00.jar'),'"')...
                    ' -fingerprints -descriptortypes ' strcat('"',fullfile(installdir,'desc_fp.xml'),'"') ' -removesalt -standardizenitro -detectaromaticity -retainorder ' padelParallelOptions ' -maxruntime 60000 -dir '...
                    strcat('"',char(StructureFile),'"') ' -file ' strcat('"',InputDescFP,'"') ' > ' strcat('"',PaDELlogfileFP,'"')]);%strcat('"',app.Outpath,PaDELlogfileFP,'"')]);
                timerPadelFP=toc(timerPadelFP);
                if statusDesc~=0 && ~isempty(cmdoutDesc)
                    msgbox(cmdoutDesc, 'Error','error');
                    return;
                end
                %end
                %Loading of fingerprints file...
                %if fp==1
                step=step+1;
                d.Value=step/steps;
                d.Message = 'Checking PaDEL fingerprints';
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                try
                    XinFP=readtable(InputDescFP,'delimiter',',','DatetimeType','text');
                catch ME
                    if strcmp(ME.identifier,'MATLAB:readtable:OpenFailed')
                        msgbox('Unable to open PaDEL fingerprints file', 'Error','error');
                        return;
                    else
                        msgbox(ME.message, 'Error','error');
                        return;
                    end
                end
                XlabelsFP=XinFP.Properties.VariableNames;
                if size(XinFP,1)==0 || size(XinFP,2)==0
                    msgbox('PaDEL fingerprints failed. Check input structures!', 'Error','error');
                    return;
                end
                XinFP=XinFP(:,2:end);
                if size(XinFP,1)~=size(Xin,1)
                    msgbox('Mismatch between descriptors and fingerprint files', 'Error','error');
                    return;
                else
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Generated PaDEL fingerprints: ' num2str(length(XlabelsFP)) ' (' num2str(round(timerPadelFP)) ' sec)'];
                end
            end


            if clean==0
                delete(InputDesc);
                %delete(strcat(app.Outpath,'PadelDesc.csv'));
                %delete(strcat(app.Outpath,PaDELlogfile));
                delete(PaDELlogfile);
                if fp==1
                    delete(InputDescFP);
                    %delete(strcat(app.Outpath,'PadelFP.csv'));
                    %delete(strcat(app.Outpath,PaDELlogfileFP));
                    delete(PaDELlogfileFP);
                end
                if cdk==1
                    delete(InputDescCDK);
                    %delete(strcat(app.Outpath,'CDKDesc.csv'));
                    %delete(strcat(app.Outpath,CDKlogfile));
                    %delete(strcat(app.Outpath,CDKerr));
                    delete(CDKlogfile);
                    delete(CDKerr);
                end
            end
            
            %========== Running the models ==========
            
            %---------- General Structural properties ----------%
            [Lia,Locb] = ismember('strp',lower(prop));
            if Lia
                %step=step+1;
                %d.Value=step/steps;
                %d.Message = 'Calculating general structural properties';
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                Xtest=Xin(:,train.STRP.Desc_i);
                Desc={'MolWeight','nbAtoms','nbHeavyAtoms','nbC','nbO','nbN','nbAromAtom','nbRing','nbHeteroRing','Sp3Sp2HybRatio','nbRotBd','nbHBdAcc','ndHBdDon','nbLipinskiFailures','TopoPolSurfAir','MolarRefract','CombDipolPolariz'};
                Xtest=array2table(Xtest,'VariableNames',Desc);
                T=array2table(MoleculeNames,'VariableNames',{'MoleculeID'});
                if nf>0 && (sep==1 || strcmpi(ext,'.txt'))
                    %T=[T; nfID];
                    %FoundBy=array2table(FoundBy,'VariableNames',{'FoundBy'});
                    T=[T array2table(FoundBy,'VariableNames',{'FoundBy'})];
                    Xtest(end+1:end+nf,:)=array2table(nan(nf,size(Xtest,2)));
                    T=[T Xtest];
                else
                    T=[T(1:end-nf,:) Xtest];
                end
                %T=[T Xtest];
                if sep==1
                    if strcmpi(ext,'.csv')
                        writetable(T,FileOut{Locb},'Delimiter',',');%,'QuoteStrings',true);
                        fclose(output(Locb));
                    elseif strcmpi(ext,'.txt')
                        fprintf(output(Locb(1)),'\n\n\t\t\t\t\t General Structural properties... \n\n			============================================================== \n\n');
                        for i=1:size(Xtest,1)
                            fprintf(output(Locb(1)),'\t Molecule %s:\n', MoleculeNames{i});
                            if nf>0
                                fprintf(output(Locb(1)),'\t FoundBy: %s\n\n', FoundBy{i});
                            end
                            for j=1:length(Desc)
                                fprintf(output(Locb(1)),'%s= %.3f\t;\t', Desc{j},Xtest{i,j});
                            end
                            fprintf(output(Locb(1)),'\n');
                        end
                        fclose(output(Locb(1)));
                    end
                    
                elseif strcmpi(ext,'.txt')
                    fprintf(output,'\n\n\t\t\t\t\t General Structural properties... \n\n			============================================================== \n\n');
                    for i=1:size(Xtest,1)
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if nf>0
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                        for j=1:length(Desc)
                            fprintf(output,'%s= %.3f\t;\t', Desc{j},Xtest{i,j});
                        end
                        fprintf(output,'\n');
                    end
                end
                res=table2struct(T,'ToScalar',true);
                
                if sep==1
                    resf.StrP=table2struct(T,'ToScalar',true);
                    clear('res');
                end
                clear('T');
            end
            
            DescMat=[];
            DescNames={};
            timerCalc=tic;
            %---------- PhysChem properties ----------
            %Predict LogP values
            [Lia,Locb] =ismember({'logp','logd'},lower(prop));
            if find(Lia)
                if Lia(1)
                    step=step+1;
                    d.Value=step/steps;
                end
                d.Message = 'Predicting LogP values';
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                if strcmpi(ext,'.txt') && sep==0 && Lia(1)
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogP values... \n\n			==============================================================n\n');
                end
                Desc=train.LOGP.Desc;
                Xtest=Xin(:,train.LOGP.Desc_i);
                if salt ==0
                    SaltIndex=zeros(size(Xtest,1),1);
                    
                    La=zeros(length(MoleculeNames),1);
                    Lb=zeros(length(MoleculeNames),1);
                    for i=1:length(MoleculeNames)
                        if ~contains(MoleculeNames(i),'AUTOGEN_')
                            if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,2});
                            elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,3});
                            elseif regexp(MoleculeNames{i},'DTXCID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,4});
                            elseif regexp(MoleculeNames{i},'[A-Z]+-[A-Z]+-[A-Z]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,5});
                            end
                            if La(i)
                                %salt=1;
                                SaltIndex(i,1)=train.DSSToxQSARr.SaltInfo(Lb(i));
                            end
                        end
                    end
                    if any(La)
                        salt=1;
                    end
                    if salt==1
                        app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Salt info. retrieved using the provided IDs'];
                        if any(~La)
                           app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'WARNING: Some IDs were not found!'];
                           app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; '(May affect predictions. Use Standardize)'];
                        end 
                    else
                        app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'WARNING: No salt info found. May affect predictions. Use standardize or provide IDs'];
                    end
                end
                
                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some LogP descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed LogP calculations. Check input and repeat calculation.'];
                    Xtest(isnan(Xtest(:,3)),3)=5.2043;
                end
                %%%

                AD=classical_leverage(train.LOGP.model.set.train(:,1:end-1),Xtest,'auto');
                Xtest=[Xtest SaltIndex];
                Desc=[Desc,'SaltIndex'];
                pred = nnrpred(Xtest,train.LOGP.model.set.train,train.LOGP.model.set.y,train.LOGP.model.set.K,train.LOGP.model.set.dist_type,train.LOGP.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogP_exp=NaN(size(Xtest,1),1);
                end
                res.LogP_pred(:,1)=round(pred.y_pred_weighted,2);
                res.LogP_predRange=cell(size(Xtest,1),1);
                SLogP=zeros(size(Xtest,1),1);
                %AD=classical_leverage(train.LOGP.model.set.train,Xtest,'auto');
                res.AD_LogP=abs(AD.inorout-1)';
                res.AD_LogP(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_LogP=zeros(size(Xtest,1),1);
                res.Conf_index_LogP=zeros(size(Xtest,1),1);
                if neighbors
                    LogP_CAS_neighbor=cell(size(Xtest,1),5);
                    LogP_InChiKey_neighbor=cell(size(Xtest,1),5);
                    LogP_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %LogP_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                LogP_Exp_neighbor=nan(size(Xtest,1),5);
                LogP_pred_neighbor=nan(size(Xtest,1),5);
                
                LOGP_CAS=strrep(strrep(join(train.LOGP.CAS,'|',2),'|||',''),'||','');
                LOGP_DTXSID=strrep(strrep(join(train.LOGP.DTXSID,'|',2),'|||',''),'||','');
                
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.LOGP.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.LOGP.DTXSID);
                        end
                        if Li
                            if Lo>size(train.LOGP.DTXSID,1)
                                Lo=mod(Lo,size(train.LOGP.DTXSID,1));
                            end
                            res.LogP_exp(i)=round(train.LOGP.model.set.y(Lo),2);
                        end
                    end
                    
                    LogP_Exp_neighbor(i,:)=round(train.LOGP.model.set.y(pred.neighbors(i,:)),2);
                    LogP_pred_neighbor(i,:)=round(train.LOGP.model.yc_weighted(pred.neighbors(i,:)),2);
                    
                    res.AD_index_LogP(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    
                    %                     res.Conf_index_LogP(i,1)=((1/(1+sqrt(((LogP_Exp_neighbor(i,:)-LogP_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_LogP(i,1))/2;
                    
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_LogP(i,1)=1;
                        res.AD_index_LogP(i,1)=1;
                    end
                    
                    SLogP(i)=std(LogP_Exp_neighbor(i,:),pred.w(i,:));
                    res.LogP_predRange{i,1}=strcat('[', num2str(round(max(res.LogP_pred(i,1)-SLogP(i),min(LogP_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.LogP_pred(i,1)+SLogP(i),max(LogP_Exp_neighbor(i,:))),2)),']');
                    
                    Std_index_LogP=(1-(std(LogP_Exp_neighbor(i,:),pred.w(i,:))/std(train.LOGP.model.set.y)));
                    res.Conf_index_LogP(i,1)=max(((1/(1+sqrt(((LogP_Exp_neighbor(i,:)-LogP_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_LogP(i,1)+Std_index_LogP)/3,0.1);
                    
                    if res.AD_index_LogP(i,1)>=0.6 && res.Conf_index_LogP(i,1)>=0.5
                        res.AD_LogP(i,1)=1;
                    elseif res.AD_index_LogP(i,1)<0.2 && res.Conf_index_LogP(i,1)<0.5
                        res.AD_LogP(i,1)=0;
                    end
                    if res.AD_index_LogP(i,1)==0
                        res.Conf_index_LogP(i,1)=0;
                    end
                    if isnan(res.AD_LogP(i,1))
                        res.AD_LogP(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.LogP_pred(i,1))
                        res.LogP_pred(i,1)=NaN;
                        res.LogP_predRange{i,1}='NA';
                        res.AD_LogP(i)=0;
                        res.AD_index_LogP(i)=0;
                        res.Conf_index_LogP(i,1)=0;
                    end
                    if salt==1 && isempty(FileSalt)
                        if La(i)==0
                            res.AD_index_LogP(i)=0.75*res.AD_index_LogP(i);
                            res.Conf_index_LogP(i,1)=0.75*res.Conf_index_LogP(i,1);
                        end
                    end
                    if Xin(i,12)==0
                        res.AD_LogP(i)=0;
                        res.AD_index_LogP(i)=res.AD_index_LogP(i)/2;
                        res.Conf_index_LogP(i,1)=res.Conf_index_LogP(i,1)/2;
                    end

                    res.AD_index_LogP(i,1)=round(res.AD_index_LogP(i,1),2);
                    res.Conf_index_LogP(i,1)=round(res.Conf_index_LogP(i,1),2);

                    if neighbors==1
                        %                         train.LOGP.CAS=strrep(strrep(join(train.LOGP.CAS,'|',2),'|||',''),'||','');
                        %                         train.LOGP.DTXSID=strrep(strrep(join(train.LOGP.DTXSID,'|',2),'|||',''),'||','');
                        LogP_CAS_neighbor(i,:)=LOGP_CAS(pred.neighbors(i,:));
                        LogP_InChiKey_neighbor(i,:)=train.LOGP.InChiKey(pred.neighbors(i,:));
                        LogP_DTXSID_neighbor(i,:)=LOGP_DTXSID(pred.neighbors(i,:));
                        %LogP_DSSTOXMPID_neighbor(i,:)=train.LOGP.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_LogP(i)~=0
                            res.LogP_CAS_neighbor(i,:)=LogP_CAS_neighbor(i,:);
                            res.LogP_InChiKey_neighbor(i,:)=LogP_InChiKey_neighbor(i,:);
                            res.LogP_DTXSID_neighbor(i,:)=LogP_DTXSID_neighbor(i,:);
                            %res.LogP_DSSTOXMPID_neighbor(i,:)=LogP_DSSTOXMPID_neighbor(i,:);
                            res.LogP_Exp_neighbor(i,:)=LogP_Exp_neighbor(i,:);
                            res.LogP_pred_neighbor(i,:)=LogP_pred_neighbor(i,:);
                        else
                            res.LogP_CAS_neighbor(i,:)=cell(1,5);
                            res.LogP_InChiKey_neighbor(i,:)=cell(1,5);
                            res.LogP_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.LogP_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.LogP_Exp_neighbor(i,:)=nan(1,5);
                            res.LogP_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1 && Lia(1)
                        fprintf(output(Locb(1)),'\t Molecule %s:\n', res.MoleculeID{i});
                        if exp
                            fprintf(output(Locb(1)),'LogP experimental= %.3f\n', res.LogP_exp(i));
                        end
                        fprintf(output(Locb(1)),'LogP predicted= %.3f\n', res.LogP_pred(i));
                        if res.AD_LogP(i)==1
                            fprintf(output(Locb(1)),'AD: inside\n');
                        else
                            fprintf(output(Locb(1)),'AD: outside\n');
                        end
                        fprintf(output(Locb(1)),'AD_index= %.2f\n', res.AD_index_LogP(i));
                        fprintf(output(Locb(1)),'Conf_index= %.2f\n', res.Conf_index_LogP(i));
                        if neighbors==1
                            fprintf(output(Locb(1)),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.LOGP.model.set.K,res.LogP_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(1)),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.LOGP.model.set.K, res.LogP_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(1)),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.LOGP.model.set.K, res.LogP_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0 && Lia(1)
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n',res.MoleculeID{i});
                        if exp
                            fprintf(output,'LogP experimental= %.3f\n', res.LogP_exp(i));
                        end
                        fprintf(output,'LogP predicted= %.3f\n', res.LogP_pred(i));
                        if res.AD_LogP(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_LogP(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_LogP(i));
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.LOGP.model.set.K, res.LogP_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.LOGP.model.set.K, res.LogP_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.LOGP.model.set.K, res.LogP_pred_neighbor(i,1:5));
                        end
                    end
                end
                if nf>0 && strcmpi(ext,'.txt') && Lia(1)
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(1)),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(1)),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv') && Lia(1)
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find((T{end,:})==0)}=nan(nf,find((T{end,:})==0));
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(1)},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(1)));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv') && Lia(1)
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                    
                end
                
                if sep==1
                    resf.LogP=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'LOGP');
                clear('LOGP_CAS');
                clear('LOGP_DTXSID');
                %end clean memory
            end

            %Predict MP values
            [Lia,Locb] =ismember('mp',lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                d.Message = 'Predicting MP values (Deg. C)';
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                Desc=train.MP.Desc;
                Xtest=Xin(:,train.MP.Desc_i);
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting MP values... \n\n			============================================================== \n\n');
                end
                
                if salt ==0
                    SaltIndex=zeros(size(Xtest,1),1);
                    
                    La=zeros(length(MoleculeNames),1);
                    Lb=zeros(length(MoleculeNames),1);
                    for i=1:length(MoleculeNames)
                        if ~contains(MoleculeNames(i),'AUTOGEN_')
                            if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,2});
                            elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,3});
                            elseif regexp(MoleculeNames{i},'DTXCID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,4});
                            elseif regexp(MoleculeNames{i},'[A-Z]+-[A-Z]+-[A-Z]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,5});
                            end
                            if La(i)
                                %salt=1;
                                SaltIndex(i,1)=train.DSSToxQSARr.SaltInfo(Lb(i));
                            end
                        end
                    end
                    if any(La)
                        salt=1;
                    end
                    if salt==1
                        app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Salt info. retrieved using the provided IDs'];
                        if any(~La)
                           app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'WARNING: Some IDs were not found!'];
                           app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; '(May affect predictions. Use Standardize)'];
                        end 
                    else
                        app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'WARNING: No salt info found. May affect predictions. Use standardize or provide IDs'];
                    end
                end

                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some MP descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed MP calculations. Check input and repeat calculation.'];
                    Xtest(find(isnan(Xtest(:,9))),9)=2.9492;
                    Xtest(find(isnan(Xtest(:,15))),15)=10.7431;
                end
                %%%


                AD=classical_leverage(train.MP.model.set.train(:,1:end-1),Xtest,'auto');
                Xtest=[Xtest SaltIndex];
                Desc=[Desc,'SaltIndex'];
                pred = nnrpred(Xtest,train.MP.model.set.train,train.MP.model.set.y,train.MP.model.set.K,train.MP.model.set.dist_type,train.MP.model.set.param.pret_type);
                pred.D=diag(pred.D);
                
                res.MoleculeID=MoleculeNames;
                if exp
                    res.MP_exp=NaN(size(Xtest,1),1);
                end
                res.MP_pred(:,1)=round(pred.y_pred_weighted);
                res.MP_predRange=cell(size(Xtest,1),1);
                res.AD_MP=abs(AD.inorout-1)';
                res.AD_MP(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_MP=zeros(size(Xtest,1),1);
                res.Conf_index_MP=zeros(size(Xtest,1),1);
                if neighbors
                    MP_CAS_neighbor=cell(size(Xtest,1),5);
                    MP_InChiKey_neighbor=cell(size(Xtest,1),5);
                    MP_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %MP_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                MP_Exp_neighbor=nan(size(Xtest,1),5);
                MP_pred_neighbor=nan(size(Xtest,1),5);
                
                MP_CAS=strrep(strrep(join(train.MP.CAS,'|',2),'|||',''),'||','');
                MP_DTXSID=strrep(strrep(join(train.MP.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.MP.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.MP.DTXSID);
                        end
                        if Li
                            if Lo>size(train.MP.DTXSID,1)
                                Lo=mod(Lo,size(train.MP.DTXSID,1));
                            end
                            res.MP_exp(i)=round(train.MP.model.set.y(Lo));
                        end
                    end
                    
                    MP_Exp_neighbor(i,:)=round(train.MP.model.set.y(pred.neighbors(i,:)));
                    MP_pred_neighbor(i,:)=round(train.MP.model.yc_weighted(pred.neighbors(i,:)));
                    res.AD_index_MP(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_MP(i,1)=((1/(1+sqrt(((MP_Exp_neighbor(i,:)-MP_pred_neighbor(i,:)).^2)*pred.w(i,:)')/50))+res.AD_index_MP(i,1))/2;
                    
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_MP(i,1)=1;
                        res.AD_index_MP(i,1)=1;
                    end
                    
                    SMP=std(MP_Exp_neighbor(i,:),pred.w(i,:));
                    res.MP_predRange{i,1}=strcat('[', num2str(round(max(res.MP_pred(i,1)-SMP,min(MP_Exp_neighbor(i,:))))),':',num2str(round(min(res.MP_pred(i,1)+SMP,max(MP_Exp_neighbor(i,:))))),']');
                    
                    Std_index_MP=(1-(std(MP_Exp_neighbor(i,:),pred.w(i,:))/std(train.MP.model.set.y)));
                    res.Conf_index_MP(i,1)=max(((1/(1+sqrt(((MP_Exp_neighbor(i,:)-MP_pred_neighbor(i,:)).^2)*pred.w(i,:)')/50))+res.AD_index_MP(i,1)+Std_index_MP)/3,0.1);
                    
                    if res.AD_index_MP(i,1)>=0.6 && res.Conf_index_MP(i,1)>=0.5
                        res.AD_MP(i,1)=1;
                    elseif res.AD_index_MP(i,1)<0.2 && res.Conf_index_MP(i,1)<0.5
                        res.AD_MP(i,1)=0;
                    end
                    if res.AD_index_MP(i,1)==0
                        res.Conf_index_MP(i,1)=0;
                    end
                    if isnan(res.AD_MP(i,1))
                        res.AD_MP(i,1)=0;
                    end


                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.MP_pred(i,1))
                        res.MP_pred(i,1)=NaN;
                        res.MP_predRange{i,1}='NA';
                        res.AD_MP(i)=0;
                        res.AD_index_MP(i)=0;
                        res.Conf_index_MP(i,1)=0;
                    end
                    if salt==1 && isempty(FileSalt)
                        if La(i)==0
                            res.AD_index_MP(i)=0.75*res.AD_index_MP(i);
                            res.Conf_index_MP(i,1)=0.75*res.Conf_index_MP(i,1);
                        end
                    end
                    if Xin(i,12)==0
                        res.AD_MP(i)=0;
                        res.AD_index_MP(i)=res.AD_index_MP(i)/2;
                        res.Conf_index_MP(i,1)=res.Conf_index_MP(i,1)/2;
                    end

                    res.AD_index_MP(i,1)=round(res.AD_index_MP(i,1),2);
                    res.Conf_index_MP(i,1)=round(res.Conf_index_MP(i,1),2);

                    if neighbors==1
                        %                         train.MP.CAS=strrep(strrep(join(train.MP.CAS,'|',2),'|||',''),'||','');
                        %                         train.MP.DTXSID=strrep(strrep(join(train.MP.DTXSID,'|',2),'|||',''),'||','');
                        MP_CAS_neighbor(i,:)=MP_CAS(pred.neighbors(i,:));
                        MP_InChiKey_neighbor(i,:)=train.MP.InChiKey(pred.neighbors(i,:));
                        MP_DTXSID_neighbor(i,:)=MP_DTXSID(pred.neighbors(i,:));
                        %MP_DSSTOXMPID_neighbor(i,:)=train.MP.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_MP(i)~=0
                            res.MP_CAS_neighbor(i,:)=MP_CAS_neighbor(i,:);
                            res.MP_InChiKey_neighbor(i,:)=MP_InChiKey_neighbor(i,:);
                            res.MP_DTXSID_neighbor(i,:)=MP_DTXSID_neighbor(i,:);
                            %res.MP_DSSTOXMPID_neighbor(i,:)=MP_DSSTOXMPID_neighbor(i,:);
                            res.MP_Exp_neighbor(i,:)=MP_Exp_neighbor(i,:);
                            res.MP_pred_neighbor(i,:)=MP_pred_neighbor(i,:);
                        else
                            res.MP_CAS_neighbor(i,:)=cell(1,5);
                            res.MP_InChiKey_neighbor(i,:)=cell(1,5);
                            res.MP_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.MP_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.MP_Exp_neighbor(i,:)=nan(1,5);
                            res.MP_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    if strcmpi(ext,'.txt') && sep==1
                        fprintf(output(Locb),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb),'MP experimental= %.3f\n', res.MP_exp(i));
                        end
                        fprintf(output(Locb),'MP predicted= %.3f\n', res.MP_pred(i));
                        if res.AD_MP(i)==1
                            fprintf(output(Locb),'AD: inside\n');
                        else
                            fprintf(output(Locb),'AD: outside\n');
                        end
                        fprintf(output(Locb),'AD_index= %.2f\n', res.AD_index_MP(i));
                        fprintf(output(Locb),'Conf_index= %.2f\n', res.Conf_index_MP(i));
                        if neighbors==1
                            fprintf(output(Locb),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.MP.model.set.K, res.MP_CAS_neighbor{i,1:5});
                            fprintf(output(Locb),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.MP.model.set.K, res.MP_Exp_neighbor(i,1:5));
                            fprintf(output(Locb),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.MP.model.set.K, res.MP_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'MP experimental= %.3f\n', res.MP_exp(i));
                        end
                        fprintf(output,'MP predicted= %.3f\n', res.MP_pred(i));
                        if res.AD_MP(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_MP(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_MP(i));
                        %CAS=strjoin(res.MP_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.MP.model.set.K, res.MP_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.MP.model.set.K, res.MP_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.MP.model.set.K, res.MP_pred_neighbor(i,1:5));
                        end
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.MP=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'MP');
                clear('MP_CAS');
                clear('MP_DTXSID');
                %end clean memory
            end
            %Predict BP values
            [Lia,Locb] =ismember('bp',lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message = 'Predicting BP values (Deg. C)';
                
                Desc=train.BP.Desc;
                Xtest=Xin(:,train.BP.Desc_i);
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting BP values... \n\n			============================================================== \n\n');
                end
                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some BP descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed BP calculations. Check input and repeat calculation.'];
                end
                %%%

                if salt ==0
                    SaltIndex=zeros(size(Xtest,1),1);
                    
                    La=zeros(length(MoleculeNames),1);
                    Lb=zeros(length(MoleculeNames),1);
                    for i=1:length(MoleculeNames)
                        if ~contains(MoleculeNames(i),'AUTOGEN_')
                            if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,2});
                            elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,3});
                            elseif regexp(MoleculeNames{i},'DTXCID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,4});
                            elseif regexp(MoleculeNames{i},'[A-Z]+-[A-Z]+-[A-Z]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,5});
                            end
                            if La(i)
                                %salt=1;
                                SaltIndex(i,1)=train.DSSToxQSARr.SaltInfo(Lb(i));
                            end
                        end
                    end
                    if any(La)
                        salt=1;
                    end
                    if salt==1
                        app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Salt info. retrieved using the provided IDs'];
                        if any(~La)
                           app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'WARNING: Some IDs were not found!'];
                           app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; '(May affect predictions. Use Standardize)'];
                        end 
                    else
                        app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'WARNING: No salt info found. May affect predictions. Use standardize or provide IDs'];
                    end
                end

                Xtest=[Xtest SaltIndex];
                Desc=[Desc,'SaltIndex'];

                pred = nnrpred(Xtest,train.BP.model.set.train,train.BP.model.set.y,train.BP.model.set.K,train.BP.model.set.dist_type,train.BP.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.BP_exp=NaN(size(Xtest,1),1);
                end
                res.BP_pred(:,1)=round(pred.y_pred_weighted);
                res.BP_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.BP.model.set.train(:,1:end-1),Xtest(:,1:end-1),'auto');
                res.AD_BP=abs(AD.inorout-1)';
                res.AD_BP(round(pred.dc(:,1),3)==0)=1;
                
                res.AD_index_BP=zeros(size(Xtest,1),1);
                res.Conf_index_BP=zeros(size(Xtest,1),1);
                if neighbors
                    BP_CAS_neighbor=cell(size(Xtest,1),5);
                    BP_InChiKey_neighbor=cell(size(Xtest,1),5);
                    BP_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %BP_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                BP_Exp_neighbor=nan(size(Xtest,1),5);
                BP_pred_neighbor=nan(size(Xtest,1),5);
                
                BP_CAS=strrep(strrep(join(train.BP.CAS,'|',2),'|||',''),'||','');
                BP_DTXSID=strrep(strrep(join(train.BP.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.BP.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.BP.DTXSID);
                        end
                        if Li
                            if Lo>size(train.BP.DTXSID,1)
                                Lo=mod(Lo,size(train.BP.DTXSID,1));
                            end
                            res.BP_exp(i)=round(train.BP.model.set.y(Lo));
                        end
                    end
                    
                    BP_Exp_neighbor(i,:)=round(train.BP.model.set.y(pred.neighbors(i,:)));
                    BP_pred_neighbor(i,:)=round(train.BP.model.yc_weighted(pred.neighbors(i,:)));
                    
                    %                 rmse=calc_reg_param(BP_Exp_neighbor(i,:),BP_pred_neighbor(i,:));
                    %                 res.Conf_index_BP(i,1)=1/(1+rmse.RMSEC/50);
                    
                    res.AD_index_BP(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_BP(i,1)=((1/(1+sqrt(((BP_Exp_neighbor(i,:)-BP_pred_neighbor(i,:)).^2)*pred.w(i,:)')/50))+res.AD_index_BP(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_BP(i,1)=1;
                        res.AD_index_BP(i,1)=1;
                    end
                    
                    SBP=std(BP_Exp_neighbor(i,:),pred.w(i,:));
                    res.BP_predRange{i,1}=strcat('[', num2str(round(max(res.BP_pred(i,1)-SBP,min(BP_Exp_neighbor(i,:))))),':',num2str(round(min(res.BP_pred(i,1)+SBP,max(BP_Exp_neighbor(i,:))))),']');
                    
                    Std_index_BP=(1-(std(BP_Exp_neighbor(i,:),pred.w(i,:))/std(train.BP.model.set.y)));
                    res.Conf_index_BP(i,1)=max(((1/(1+sqrt(((BP_Exp_neighbor(i,:)-BP_pred_neighbor(i,:)).^2)*pred.w(i,:)')/50))+res.AD_index_BP(i,1)+Std_index_BP)/3,0.1);
                    
                    if res.AD_index_BP(i,1)>=0.6 && res.Conf_index_BP(i,1)>=0.5
                        res.AD_BP(i,1)=1;
                    elseif res.AD_index_BP(i,1)<0.2 && res.Conf_index_BP(i,1)<0.5
                        res.AD_BP(i,1)=0;
                    end
                    if res.AD_index_BP(i,1)==0
                        res.Conf_index_BP(i,1)=0;
                    end
                    if isnan(res.AD_BP(i,1))
                        res.AD_BP(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.BP_pred(i,1))
                        res.BP_pred(i,1)=NaN;
                        res.BP_predRange{i,1}='NA';
                        res.AD_BP(i)=0;
                        res.AD_index_BP(i)=0;
                        res.Conf_index_BP(i,1)=0;
                    end
                    if salt==1 && isempty(FileSalt)
                        if La(i)==0
                            res.AD_index_BP(i)=0.75*res.AD_index_BP(i);
                            res.Conf_index_BP(i,1)=0.75*res.Conf_index_BP(i,1);
                        end
                    end
                    if Xin(i,12)==0
                        res.AD_BP(i)=0;
                        res.AD_index_BP(i)=res.AD_index_BP(i)/2;
                        res.Conf_index_BP(i,1)=res.Conf_index_BP(i,1)/2;
                    end
                    
                    res.AD_index_BP(i,1)=round(res.AD_index_BP(i,1),2);
                    res.Conf_index_BP(i,1)=round(res.Conf_index_BP(i,1),2);

                    if neighbors==1
                        %                         train.BP.CAS=strrep(strrep(join(train.BP.CAS,'|',2),'|||',''),'||','');
                        %                         train.BP.DTXSID=strrep(strrep(join(train.BP.DTXSID,'|',2),'|||',''),'||','');
                        BP_CAS_neighbor(i,:)=BP_CAS(pred.neighbors(i,:));
                        BP_InChiKey_neighbor(i,:)=train.BP.InChiKey(pred.neighbors(i,:));
                        BP_DTXSID_neighbor(i,:)=BP_DTXSID(pred.neighbors(i,:));
                        %BP_DSSTOXMPID_neighbor(i,:)=train.BP.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_BP(i)~=0
                            res.BP_CAS_neighbor(i,:)=BP_CAS_neighbor(i,:);
                            res.BP_InChiKey_neighbor(i,:)=BP_InChiKey_neighbor(i,:);
                            res.BP_DTXSID_neighbor(i,:)=BP_DTXSID_neighbor(i,:);
                            %res.BP_DSSTOXMPID_neighbor(i,:)=BP_DSSTOXMPID_neighbor(i,:);
                            res.BP_Exp_neighbor(i,:)=BP_Exp_neighbor(i,:);
                            res.BP_pred_neighbor(i,:)=BP_pred_neighbor(i,:);
                        else
                            res.BP_CAS_neighbor(i,:)=cell(1,5);
                            res.BP_InChiKey_neighbor(i,:)=cell(1,5);
                            res.BP_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.BP_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.BP_Exp_neighbor(i,:)=nan(1,5);
                            res.BP_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        
                        %res.Xtest=Xtest;
                        fprintf(output(Locb),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb),'BP experimental= %.3f\n', res.BP_exp(i));
                        end
                        fprintf(output(Locb),'BP predicted= %.3f\n', res.BP_pred(i));
                        if res.AD_BP(i)==1
                            fprintf(output(Locb),'AD: inside\n');
                        else
                            fprintf(output(Locb),'AD: outside\n');
                        end
                        fprintf(output(Locb),'AD_index= %.2f\n', res.AD_index_BP(i));
                        fprintf(output(Locb),'Conf_index= %.2f\n', res.Conf_index_BP(i));
                        %CAS=strjoin(res.BP_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.BP.model.set.K, res.BP_CAS_neighbor{i,1:5});
                            fprintf(output(Locb),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.BP.model.set.K, res.BP_Exp_neighbor(i,1:5));
                            fprintf(output(Locb),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.BP.model.set.K, res.BP_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'BP experimental= %.3f\n', res.BP_exp(i));
                        end
                        fprintf(output,'BP predicted= %.3f\n', res.BP_pred(i));
                        if res.AD_BP(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_BP(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_BP(i));
                        %CAS=strjoin(res.BP_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.BP.model.set.K, res.BP_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.BP.model.set.K, res.BP_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.BP.model.set.K, res.BP_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                

                if sep==1
                    resf.BP=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'BP');
                clear('BP_CAS');
                clear('BP_DTXSID');
                %end clean memory
                
            end
            %Predict VP values
            [Lia,Locb] =ismember({'vp','logvp'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message = 'Predicting LogVP values (Log10 mmHg)';
                Desc=train.VP.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting VP values in Log mmHg... \n\n			============================================================== \n\n');
                end
                
                Xtest=Xin(:,train.VP.Desc_i);
                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some VP descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed VP calculations. Check input and repeat calculation.'];
                    Xtest(isnan(Xtest(:,12)),12)=85.0430;
                end
                %%%

                if salt ==0
                    SaltIndex=zeros(size(Xtest,1),1);
                    
                    La=zeros(length(MoleculeNames),1);
                    Lb=zeros(length(MoleculeNames),1);
                    for i=1:length(MoleculeNames)
                        if ~contains(MoleculeNames(i),'AUTOGEN_')
                            if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,2});
                            elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,3});
                            elseif regexp(MoleculeNames{i},'DTXCID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,4});
                            elseif regexp(MoleculeNames{i},'[A-Z]+-[A-Z]+-[A-Z]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,5});
                            end
                            if La(i)
                                %salt=1;
                                SaltIndex(i,1)=train.DSSToxQSARr.SaltInfo(Lb(i));
                            end
                        end
                    end
                    if any(La)
                        salt=1;
                    end
                    if salt==1
                        app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Salt info. retrieved using the provided IDs'];
                        if any(~La)
                           app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'WARNING: Some IDs were not found!'];
                           app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; '(May affect predictions. Use Standardize)'];
                        end 
                    else
                        app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'WARNING: No salt info found. May affect predictions. Use standardize or provide IDs'];
                    end
                end

                Xtest=[Xtest SaltIndex];
                Desc=[Desc,'SaltIndex'];
                
                pred = nnrpred(Xtest,train.VP.model.set.train,train.VP.model.set.y,train.VP.model.set.K,train.VP.model.set.dist_type,train.VP.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogVP_exp=NaN(size(Xtest,1),1);
                end
                res.LogVP_pred(:,1)=round(pred.y_pred_weighted,2);
                res.VP_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.VP.model.set.train(:,1:end-1),Xtest(:,1:end-1),'auto');
                res.AD_VP=abs(AD.inorout-1)';
                res.AD_VP(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_VP=zeros(size(Xtest,1),1);
                res.Conf_index_VP=zeros(size(Xtest,1),1);
                if neighbors
                    LogVP_CAS_neighbor=cell(size(Xtest,1),5);
                    LogVP_InChiKey_neighbor=cell(size(Xtest,1),5);
                    LogVP_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %LogVP_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                LogVP_Exp_neighbor=nan(size(Xtest,1),5);
                LogVP_pred_neighbor=nan(size(Xtest,1),5);
                VP_CAS=strrep(strrep(join(train.VP.CAS,'|',2),'|||',''),'||','');
                VP_DTXSID=strrep(strrep(join(train.VP.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.VP.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.VP.DTXSID);
                        end
                        if Li
                            if Lo>size(train.VP.DTXSID,1)
                                Lo=mod(Lo,size(train.VP.DTXSID,1));
                            end
                            res.LogVP_exp(i)=round(train.VP.model.set.y(Lo));
                        end
                    end
                    
                    LogVP_Exp_neighbor(i,:)=round(train.VP.model.set.y(pred.neighbors(i,:)));
                    LogVP_pred_neighbor(i,:)=round(train.VP.model.yc_weighted(pred.neighbors(i,:)));
                    
                    %                 rmse=calc_reg_param(res.LogVP_Exp_neighbor(i,:),res.LogVP_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_VP(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_VP(i,1)=((1/(1+sqrt(((LogVP_Exp_neighbor(i,:)-LogVP_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+ res.AD_index_VP(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_VP(i,1)=1;
                        res.AD_index_VP(i,1)=1;
                    end
                    
                    SVP=std(LogVP_Exp_neighbor(i,:),pred.w(i,:));
                    res.VP_predRange{i,1}=strcat('[', num2str(round(max(res.LogVP_pred(i,1)-SVP,min(LogVP_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.LogVP_pred(i,1)+SVP,max(LogVP_Exp_neighbor(i,:))),2)),']');
                    
                    Std_index_VP=(1-(std(LogVP_Exp_neighbor(i,:),pred.w(i,:))/std(train.VP.model.set.y)));
                    res.Conf_index_VP(i,1)=max(((1/(1+sqrt(((LogVP_Exp_neighbor(i,:)-LogVP_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_VP(i,1)+Std_index_VP)/3,0.1);
                    
                    if res.AD_index_VP(i,1)>=0.6 && res.Conf_index_VP(i,1)>=0.5
                        res.AD_VP(i,1)=1;
                    elseif res.AD_index_VP(i,1)<0.2 && res.Conf_index_VP(i,1)<0.5
                        res.AD_VP(i,1)=0;
                    end
                    if res.AD_index_VP(i,1)==0
                        res.Conf_index_VP(i,1)=0;
                    end
                    if isnan(res.AD_VP(i,1))
                        res.AD_VP(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.LogVP_pred(i,1))
                        res.LogVP_pred(i,1)=NaN;
                        res.VP_predRange{i,1}='NA';
                        res.AD_VP(i)=0;
                        res.AD_index_VP(i)=0;
                        res.Conf_index_VP(i,1)=0;
                    end
                    if salt==1 && isempty(FileSalt)
                        if La(i)==0
                            res.AD_index_VP(i)=0.75*res.AD_index_VP(i);
                            res.Conf_index_VP(i,1)=0.75*res.Conf_index_VP(i,1);
                        end
                    end
                    if Xin(i,12)==0
                        res.AD_VP(i)=0;
                        res.AD_index_VP(i)=res.AD_index_VP(i)/2;
                        res.Conf_index_VP(i,1)=res.Conf_index_VP(i,1)/2;
                    end
                    
                    res.AD_index_VP(i,1)=round(res.AD_index_VP(i,1),2);
                    res.Conf_index_VP(i,1)=round(res.Conf_index_VP(i,1),2);

                    if neighbors==1
                        %                         train.VP.CAS=strrep(strrep(join(train.VP.CAS,'|',2),'|||',''),'||','');
                        %                         train.VP.DTXSID=strrep(strrep(join(train.VP.DTXSID,'|',2),'|||',''),'||','');
                        LogVP_CAS_neighbor(i,:)=VP_CAS(pred.neighbors(i,:));
                        LogVP_InChiKey_neighbor(i,:)=train.VP.InChiKey(pred.neighbors(i,:));
                        LogVP_DTXSID_neighbor(i,:)=VP_DTXSID(pred.neighbors(i,:));
                        %LogVP_DSSTOXMPID_neighbor(i,:)=train.VP.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_VP(i)~=0
                            res.LogVP_CAS_neighbor(i,:)=LogVP_CAS_neighbor(i,:);
                            res.LogVP_InChiKey_neighbor(i,:)=LogVP_InChiKey_neighbor(i,:);
                            res.LogVP_DTXSID_neighbor(i,:)=LogVP_DTXSID_neighbor(i,:);
                            %res.LogVP_DSSTOXMPID_neighbor(i,:)=LogVP_DSSTOXMPID_neighbor(i,:);
                            res.LogVP_Exp_neighbor(i,:)=LogVP_Exp_neighbor(i,:);
                            res.LogVP_pred_neighbor(i,:)=LogVP_pred_neighbor(i,:);
                        else
                            res.LogVP_CAS_neighbor(i,:)=cell(1,5);
                            res.LogVP_InChiKey_neighbor(i,:)=cell(1,5);
                            res.LogVP_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.LogVP_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.LogVP_Exp_neighbor(i,:)=nan(1,5);
                            res.LogVP_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogVP experimental= %.3f\n', res.LogVP_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogVP predicted= %.3f\n', res.LogVP_pred(i));
                        if res.AD_VP(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_VP(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_VP(i));
                        %CAS=strjoin(res.LogVP_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.VP.model.set.K, res.LogVP_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.VP.model.set.K, res.LogVP_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.VP.model.set.K, res.LogVP_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogVP experimental= %.3f\n', res.LogVP_exp(i));
                        end
                        fprintf(output,'LogVP predicted= %.3f\n', res.LogVP_pred(i));
                        if res.AD_VP(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_VP(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_VP(i));
                        %CAS=strjoin(res.LogVP_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.VP.model.set.K, res.LogVP_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.VP.model.set.K, res.LogVP_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.VP.model.set.K, res.LogVP_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.VP=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'VP');
                clear('VP_CAS');
                clear('VP_DTXSID');
                %end clean memory
                
            end
            
            %Predict WS values
            %case {'ws','logws'}
            [Lia,Locb] =ismember({'ws','logws'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message = 'Predicting LogWS values (Log10 M)';
                Desc=train.WS.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogWS values... \n\n			============================================================== \n\n');
                end
                
                Xtest=Xin(:,train.WS.Desc_i);
                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some WS descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed WS calculations. Check input and repeat calculation.'];
                    Xtest(find(isnan(Xtest(:,2))),2)=2;
                    Xtest(find(isnan(Xtest(:,3))),3)=3.4533;
                    Xtest(find(isnan(Xtest(:,4))),4)=0.0690;
                    Xtest(find(isnan(Xtest(:,6))),6)=2.0732;
                    Xtest(find(isnan(Xtest(:,7))),7)=1.2410;
                    Xtest(find(isnan(Xtest(:,8))),8)=0;
                    Xtest(find(isnan(Xtest(:,11))),11)=0;
                end
                %%%

                if salt ==0
                    SaltIndex=zeros(size(Xtest,1),1);
                    
                    La=zeros(length(MoleculeNames),1);
                    Lb=zeros(length(MoleculeNames),1);
                    for i=1:length(MoleculeNames)
                        if ~contains(MoleculeNames(i),'AUTOGEN_')
                            if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,2});
                            elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,3});
                            elseif regexp(MoleculeNames{i},'DTXCID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,4});
                            elseif regexp(MoleculeNames{i},'[A-Z]+-[A-Z]+-[A-Z]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,5});
                            end
                            if La(i)
                                %salt=1;
                                SaltIndex(i,1)=train.DSSToxQSARr.SaltInfo(Lb(i));
                            end
                        end
                    end
                    if any(La)
                        salt=1;
                    end
                    if salt==1
                        app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Salt info. retrieved using the provided IDs'];
                        if any(~La)
                           app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'WARNING: Some IDs were not found!'];
                           app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; '(May affect predictions. Use Standardize)'];
                        end 
                    else
                        app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'WARNING: No salt info found. May affect predictions. Use standardize or provide IDs'];
                    end
                end

                Xtest=[Xtest SaltIndex];
                Desc=[Desc,'SaltIndex'];


                pred = nnrpred(Xtest,train.WS.model.set.train,train.WS.model.set.y,train.WS.model.set.K,train.WS.model.set.dist_type,train.WS.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogWS_exp=NaN(size(Xtest,1),1);
                end
                res.LogWS_pred(:,1)=round(pred.y_pred_weighted,2);
                res.WS_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.WS.model.set.train(:,1:end-1),Xtest(:,1:end-1),'auto');
                res.AD_WS=abs(AD.inorout-1)';
                res.AD_WS(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_WS=zeros(size(Xtest,1),1);
                res.Conf_index_WS=zeros(size(Xtest,1),1);
                if neighbors
                    LogWS_CAS_neighbor=cell(size(Xtest,1),5);
                    LogWS_InChiKey_neighbor=cell(size(Xtest,1),5);
                    LogWS_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %LogWS_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                LogWS_Exp_neighbor=nan(size(Xtest,1),5);
                LogWS_pred_neighbor=nan(size(Xtest,1),5);
                WS_CAS=strrep(strrep(join(train.WS.CAS,'|',2),'|||',''),'||','');
                WS_DTXSID=strrep(strrep(join(train.WS.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.WS.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.WS.DTXSID);
                        end
                        if Li
                            if Lo>size(train.WS.DTXSID,1)
                                Lo=mod(Lo,size(train.WS.DTXSID,1));
                            end
                            res.LogWS_exp(i)=round(train.WS.model.set.y(Lo),2);
                        end
                    end
                    
                    LogWS_Exp_neighbor(i,:)=round(train.WS.model.set.y(pred.neighbors(i,:)),2);
                    LogWS_pred_neighbor(i,:)=round(train.WS.model.yc_weighted(pred.neighbors(i,:)),2);
                    
                    %                 rmse=calc_reg_param(res.LogWS_Exp_neighbor(i,:),res.LogWS_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_WS(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_WS(i,1)=((1/(1+sqrt(((LogWS_Exp_neighbor(i,:)-LogWS_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_WS(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_WS(i,1)=1;
                        res.AD_index_WS(i,1)=1;
                    end
                    
                    SWS=std(LogWS_Exp_neighbor(i,:),pred.w(i,:));
                    res.WS_predRange{i,1}=strcat('[', num2str(round(max(res.LogWS_pred(i,1)-SWS,min(LogWS_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.LogWS_pred(i,1)+SWS,max(LogWS_Exp_neighbor(i,:))),2)),']');
                    
                    Std_index_WS=(1-(std(LogWS_Exp_neighbor(i,:),pred.w(i,:))/std(train.WS.model.set.y)));
                    res.Conf_index_WS(i,1)=max(((1/(1+sqrt(((LogWS_Exp_neighbor(i,:)-LogWS_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_WS(i,1)+Std_index_WS)/3,0.1);
                    
                    if res.AD_index_WS(i,1)>=0.6 && res.Conf_index_WS(i,1)>=0.5
                        res.AD_WS(i,1)=1;
                    elseif res.AD_index_WS(i,1)<0.2 && res.Conf_index_WS(i,1)<0.5
                        res.AD_WS(i,1)=0;
                    end
                    if res.AD_index_WS(i,1)==0
                        res.Conf_index_WS(i,1)=0;
                    end
                    if isnan(res.AD_WS(i,1))
                        res.AD_WS(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.LogWS_pred(i,1))
                        res.LogWS_pred(i,1)=NaN;
                        res.WS_predRange{i,1}='NA';
                        res.AD_WS(i)=0;
                        res.AD_index_WS(i)=0;
                        res.Conf_index_WS(i,1)=0;
                    end
                    if salt==1 && isempty(FileSalt)
                        if La(i)==0
                            res.AD_index_WS(i)=0.75*res.AD_index_WS(i);
                            res.Conf_index_WS(i,1)=0.75*res.Conf_index_WS(i,1);
                        end
                    end
                    if Xin(i,12)==0
                        res.AD_WS(i)=0;
                        res.AD_index_WS(i)=res.AD_index_WS(i)/2;
                        res.Conf_index_WS(i,1)=res.Conf_index_WS(i,1)/2;
                    end
                    
                    res.AD_index_WS(i,1)=round(res.AD_index_WS(i,1),2);
                    res.Conf_index_WS(i,1)=round(res.Conf_index_WS(i,1),2);

                    if neighbors==1
                        %                         train.WS.CAS=strrep(strrep(join(train.WS.CAS,'|',2),'|||',''),'||','');
                        %                         train.WS.DTXSID=strrep(strrep(join(train.WS.DTXSID,'|',2),'|||',''),'||','');
                        LogWS_CAS_neighbor(i,:)=WS_CAS(pred.neighbors(i,:));
                        LogWS_InChiKey_neighbor(i,:)=train.WS.InChiKey(pred.neighbors(i,:));
                        LogWS_DTXSID_neighbor(i,:)=WS_DTXSID(pred.neighbors(i,:));
                        %LogWS_DSSTOXMPID_neighbor(i,:)=train.WS.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_WS(i)~=0
                            res.LogWS_CAS_neighbor(i,:)=LogWS_CAS_neighbor(i,:);
                            res.LogWS_InChiKey_neighbor(i,:)=LogWS_InChiKey_neighbor(i,:);
                            res.LogWS_DTXSID_neighbor(i,:)=LogWS_DTXSID_neighbor(i,:);
                            %res.LogWS_DSSTOXMPID_neighbor(i,:)=LogWS_DSSTOXMPID_neighbor(i,:);
                            res.LogWS_Exp_neighbor(i,:)=LogWS_Exp_neighbor(i,:);
                            res.LogWS_pred_neighbor(i,:)=LogWS_pred_neighbor(i,:);
                        else
                            res.LogWS_CAS_neighbor(i,:)=cell(1,5);
                            res.LogWS_InChiKey_neighbor(i,:)=cell(1,5);
                            res.LogWS_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.LogWS_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.LogWS_Exp_neighbor(i,:)=nan(1,5);
                            res.LogWS_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogWS experimental= %.3f\n', res.LogWS_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogWS predicted= %.3f\n', res.LogWS_pred(i));
                        if res.AD_WS(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_WS(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_WS(i));
                        %CAS=strjoin(res.LogWS_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.WS.model.set.K, res.LogWS_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.WS.model.set.K, res.LogWS_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.WS.model.set.K, res.LogWS_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogWS experimental= %.3f\n', res.LogWS_exp(i));
                        end
                        fprintf(output,'LogWS predicted= %.3f\n', res.LogWS_pred(i));
                        if res.AD_WS(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_WS(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_WS(i));
                        %CAS=strjoin(res.LogWS_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.WS.model.set.K, res.LogWS_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.WS.model.set.K, res.LogWS_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.WS.model.set.K, res.LogWS_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.WS=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'WS');
                clear('WS_CAS');
                clear('WS_DTXSID');
                %end clean memory
                
            end
            
            %Predict HL values
            %case {'hl','loghl'}
            [Lia,Locb] =ismember({'hl','loghl'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message = 'Predicting LogHL values (Log10 atm-m3/V)';
                
                Desc=train.HL.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogHL values... \n\n			============================================================== \n\n');
                end
                
                Xtest=Xin(:,train.HL.Desc_i);

                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some HL descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed HL calculations. Check input and repeat calculation.'];
                    Xtest(find(isnan(Xtest(:,4))),4)=0;
                    Xtest(find(isnan(Xtest(:,6))),6)=0;
                    Xtest(find(isnan(Xtest(:,9))),9)=57.1303;
                end
                %%%

                pred = nnrpred(Xtest,train.HL.model.set.train,train.HL.model.set.y,train.HL.model.set.K,train.HL.model.set.dist_type,train.HL.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogHL_exp=NaN(size(Xtest,1),1);
                end
                res.LogHL_pred(:,1)=round(pred.y_pred_weighted,2);
                res.HL_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.HL.model.set.train,Xtest,'auto');
                res.AD_HL=abs(AD.inorout-1)';
                res.AD_HL(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_HL=zeros(size(Xtest,1),1);
                res.Conf_index_HL=zeros(size(Xtest,1),1);
                if neighbors
                    HL_CAS_neighbor=cell(size(Xtest,1),5);
                    HL_InChiKey_neighbor=cell(size(Xtest,1),5);
                    HL_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %HL_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                LogHL_Exp_neighbor=nan(size(Xtest,1),5);
                LogHL_pred_neighbor=nan(size(Xtest,1),5);
                HL_CAS=strrep(strrep(join(train.HL.CAS,'|',2),'|||',''),'||','');
                HL_DTXSID=strrep(strrep(join(train.HL.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.HL.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.HL.DTXSID);
                        end
                        if Li
                            if Lo>size(train.HL.DTXSID,1)
                                Lo=mod(Lo,size(train.HL.DTXSID,1));
                            end
                            res.LogHL_exp(i)=round(train.HL.model.set.y(Lo),2);
                        end
                    end
                    
                    LogHL_Exp_neighbor(i,:)=round(train.HL.model.set.y(pred.neighbors(i,:)),2);
                    LogHL_pred_neighbor(i,:)=round(train.HL.model.yc_weighted(pred.neighbors(i,:)),2);
                    
                    %                 rmse=calc_reg_param(res.LogHL_Exp_neighbor(i,:),res.LogHL_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_HL(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_HL(i,1)=((1/(1+sqrt(((LogHL_Exp_neighbor(i,:)-LogHL_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_HL(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_HL(i,1)=1;
                        res.AD_index_HL(i,1)=1;
                    end
                    
                    SHL=std(LogHL_Exp_neighbor(i,:),pred.w(i,:));
                    res.HL_predRange{i,1}=strcat('[', num2str(round(max(res.LogHL_pred(i,1)-SHL,min(LogHL_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.LogHL_pred(i,1)+SHL,max(LogHL_Exp_neighbor(i,:))),2)),']');
                    
                    Std_index_HL=(1-(std(LogHL_Exp_neighbor(i,:),pred.w(i,:))/std(train.HL.model.set.y)));
                    res.Conf_index_HL(i,1)=max(((1/(1+sqrt(((LogHL_Exp_neighbor(i,:)-LogHL_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_HL(i,1)+Std_index_HL)/3,0.1);
                    
                    if res.AD_index_HL(i,1)>=0.6 && res.Conf_index_HL(i,1)>=0.5
                        res.AD_HL(i,1)=1;
                    elseif res.AD_index_HL(i,1)<0.2 && res.Conf_index_HL(i,1)<0.5
                        res.AD_HL(i,1)=0;
                    end
                    if res.AD_index_HL(i,1)==0
                        res.Conf_index_HL(i,1)=0;
                    end
                    if isnan(res.AD_HL(i,1))
                        res.AD_HL(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.LogHL_pred(i,1))
                        res.LogHL_pred(i,1)=NaN;
                        res.HL_predRange{i,1}='NA';
                        res.AD_HL(i)=0;
                        res.AD_index_HL(i)=0;
                        res.Conf_index_HL(i,1)=0;
                    end
                    if Xin(i,12)==0
                        res.AD_HL(i)=0;
                        res.AD_index_HL(i)=res.AD_index_HL(i)/2;
                        res.Conf_index_HL(i,1)=res.Conf_index_HL(i,1)/2;
                    end

                    res.AD_index_HL(i,1)=round(res.AD_index_HL(i,1),2);
                    res.Conf_index_HL(i,1)=round(res.Conf_index_HL(i,1),2);

                    if neighbors==1
                        %                         train.HL.CAS=strrep(strrep(join(train.HL.CAS,'|',2),'|||',''),'||','');
                        %                         train.HL.DTXSID=strrep(strrep(join(train.HL.DTXSID,'|',2),'|||',''),'||','');
                        HL_CAS_neighbor(i,:)=HL_CAS(pred.neighbors(i,:));
                        HL_InChiKey_neighbor(i,:)=train.HL.InChiKey(pred.neighbors(i,:));
                        HL_DTXSID_neighbor(i,:)=HL_DTXSID(pred.neighbors(i,:));
                        %HL_DSSTOXMPID_neighbor(i,:)=train.HL.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_HL(i)~=0
                            res.HL_CAS_neighbor(i,:)=HL_CAS_neighbor(i,:);
                            res.HL_InChiKey_neighbor(i,:)=HL_InChiKey_neighbor(i,:);
                            res.HL_DTXSID_neighbor(i,:)=HL_DTXSID_neighbor(i,:);
                            %res.HL_DSSTOXMPID_neighbor(i,:)=HL_DSSTOXMPID_neighbor(i,:);
                            res.LogHL_Exp_neighbor(i,:)=LogHL_Exp_neighbor(i,:);
                            res.LogHL_pred_neighbor(i,:)=LogHL_pred_neighbor(i,:);
                        else
                            res.HL_CAS_neighbor(i,:)=cell(1,5);
                            res.HL_InChiKey_neighbor(i,:)=cell(1,5);
                            res.HL_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.HL_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.LogHL_Exp_neighbor(i,:)=nan(1,5);
                            res.LogHL_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogHL experimental= %.3f\n', res.LogHL_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogHL predicted= %.3f\n', res.LogHL_pred(i));
                        if res.AD_HL(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_HL(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_HL(i));
                        %CAS=strjoin(res.HL_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.HL.model.set.K, res.HL_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.HL.model.set.K, res.LogHL_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.HL.model.set.K, res.LogHL_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogHL experimental= %.3f\n', res.LogHL_exp(i));
                        end
                        fprintf(output,'LogHL predicted= %.3f\n', res.LogHL_pred(i));
                        if res.AD_HL(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_HL(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_HL(i));
                        %CAS=strjoin(res.HL_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.HL.model.set.K, res.HL_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.HL.model.set.K, res.LogHL_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.HL.model.set.K, res.LogHL_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.HL=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'HL');
                clear('HL_CAS');
                clear('HL_DTXSID');
                %end clean memory
                
            end
            
            % %Predict RT values
            % %case {'rt'}
            % [Lia,Locb] =ismember('rt',lower(prop));
            % if find(Lia)
            %     step=step+1;
            %     d.Value=step/steps;
            %     if d.CancelRequested
            %         msgbox('Canceled by user.', 'Error','error');
            %         return;
            %     end
            %     d.Message = 'Predicting RT values (Mins.)';
            % 
            %     Desc=train.RT.Desc;
            % 
            %     if strcmpi(ext,'.txt') && sep==0
            %         fprintf(output,'\n\n\t\t\t\t\t Predicting RT values... \n\n			==============================================================  \n\n');
            %     end
            % 
            %     Xtest=Xin(:,train.RT.Desc_i);
            %%% Check for NaN
                % idx = all(isnan(Xtest), 1);
                % if any(idx)
                %     msgbox('Some RT descriptors failed. Check input and repeat calculation.', 'Error','error');
                %     return;
                % end
                % if any(any(isnan(Xtest)))
                %     app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed RT calculations. Check input and repeat calculation.'];
                % end
                % %%%
            % 
            %     pred = nnrpred(Xtest,train.RT.model.set.train,train.RT.model.set.y,train.RT.model.set.K,train.RT.model.set.dist_type,train.RT.model.set.scal);
            %     pred.D=diag(pred.D);
            %     predpls=plstest(Xtest,train.RT.model);
            % 
            %     res.MoleculeID=MoleculeNames;
            %     if exp
            %         res.RT_exp=NaN(size(Xtest,1),1);
            %     end
            %     res.RT_pred(:,1)=round(predpls.yc,2);
            %     AD=classical_leverage(train.RT.model.set.train,Xtest,'auto');
            %     res.AD_RT=abs(AD.inorout-1)';
            %     res.AD_RT(round(pred.dc(:,1),3)==0)=1;
            %     res.AD_index_RT=zeros(size(Xtest,1),1);
            %     res.Conf_index_RT=zeros(size(Xtest,1),1);
            %     if neighbors
            %         RT_CAS_neighbor=cell(size(Xtest,1),5);
            %         RT_DTXSID_neighbor=cell(size(Xtest,1),5);
            %     end
            %     RT_Exp_neighbor=nan(size(Xtest,1),5);
            %     RT_pred_neighbor=nan(size(Xtest,1),5);
            %     RT_CAS=strrep(strrep(join(train.RT.CAS,'|',2),'|||',''),'||','');
            %     RT_DTXSID=strrep(strrep(join(train.RT.DTXSID,'|',2),'|||',''),'||','');
            %     for i=1:size(Xtest,1)
            %         Li=0;
            %         if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
            %             if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
            %                 [Li,Lo] = ismember(MoleculeNames(i),train.RT.CAS);
            %             elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
            %                 [Li,Lo] = ismember(MoleculeNames{i},train.RT.DTXSID);
            %             end
            %             if Li
            %                 if Lo>size(train.RT.DTXSID,1)
            %                     Lo=mod(Lo,size(train.RT.DTXSID,1));
            %                 end
            %                 res.RT_exp(i)=round(train.RT.model.set.y(Lo),2);
            %             end
            %         end
            % 
            %         RT_Exp_neighbor(i,:)=round(train.RT.model.set.y(pred.neighbors(i,:)),2);
            %         RT_pred_neighbor(i,:)=round(train.RT.model.yc(pred.neighbors(i,:)),2);
            % 
            %         res.AD_index_RT(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
            %         %                     res.Conf_index_RT(i,1)=((1/(1+sqrt(((RT_Exp_neighbor(i,:)-RT_pred_neighbor(i,:)).^2)*pred.w(i,:)')/4.5))+res.AD_index_RT(i,1))/2;
            %         if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
            %             res.AD_RT(i,1)=1;
            %             res.AD_index_RT(i,1)=1;
            %         end
            % 
            %         SRT=std(RT_Exp_neighbor(i,:),pred.w(i,:));
            %         res.RT_predRange{i,1}=strcat('[', num2str(round(max(res.RT_pred(i,1)-SRT,min(RT_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.RT_pred(i,1)+SRT,max(RT_Exp_neighbor(i,:))),2)),']');
            % 
            %         Std_index_RT=(1-(std(RT_Exp_neighbor(i,:),pred.w(i,:))/std(train.RT.model.set.y)));
            %         res.Conf_index_RT(i,1)=max(((1/(1+sqrt(((RT_Exp_neighbor(i,:)-RT_pred_neighbor(i,:)).^2)*pred.w(i,:)')/4.5))+res.AD_index_RT(i,1)+Std_index_RT)/3,0.1);
            % 
            %         if res.AD_index_RT(i,1)>=0.6 && res.Conf_index_RT(i,1)>=0.5
            %             res.AD_RT(i,1)=1;
            %         elseif res.AD_index_RT(i,1)<0.2 && res.Conf_index_RT(i,1)<0.5
            %             res.AD_RT(i,1)=0;
            %         end
            %         if res.AD_index_RT(i,1)==0
            %             res.Conf_index_RT(i,1)=0;
            %         end
            %         if isnan(res.AD_RT(i,1))
            %             res.AD_RT(i,1)=0;
            %         end
            %         res.AD_index_RT(i,1)=round(res.AD_index_RT(i,1),3);
            %         res.Conf_index_RT(i,1)=round(res.Conf_index_RT(i,1),3);
            % 
            %         if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.RT_pred(i,1))
            %             res.RT_pred(i,1)=NaN;
            %             res.RT_predRange{i,1}='NA';
            %             res.AD_RT(i)=0;
            %             res.AD_index_RT(i)=0;
            %             res.Conf_index_RT(i,1)=0;
            %         end
            %         if Xin(i,12)==0
            %             res.AD_RT(i)=0;
            %             res.AD_index_RT(i)=res.AD_index_RT(i)/2;
            %             res.Conf_index_RT(i,1)=res.Conf_index_RT(i,1)/2;
            %         end
            %         if res.RT_pred(i,1)<0
            %             res.RT_pred(i,1)=0;
            %             res.RT_predRange{i,1}='NA';
            %             res.AD_RT(i)=0;
            %         end
            % 
            %         if neighbors==1
            %             %                         train.RT.CAS=strrep(strrep(join(train.RT.CAS,'|',2),'|||',''),'||','');
            %             %                         train.RT.DTXSID=strrep(strrep(join(train.RT.DTXSID,'|',2),'|||',''),'||','');
            %             RT_CAS_neighbor(i,:)=RT_CAS(pred.neighbors(i,:));
            %             RT_DTXSID_neighbor(i,:)=RT_DTXSID(pred.neighbors(i,:));
            %             if res.AD_index_RT(i)~=0
            %                 res.RT_CAS_neighbor(i,:)=RT_CAS_neighbor(i,:);
            %                 res.RT_DTXSID_neighbor(i,:)=RT_DTXSID_neighbor(i,:);
            %                 res.RT_Exp_neighbor(i,:)=RT_Exp_neighbor(i,:);
            %                 res.RT_pred_neighbor(i,:)=RT_pred_neighbor(i,:);
            %             else
            %                 res.RT_CAS_neighbor(i,:)=cell(1,5);
            %                 res.RT_DTXSID_neighbor(i,:)=cell(1,5);
            %                 res.RT_Exp_neighbor(i,:)=nan(1,5);
            %                 res.RT_pred_neighbor(i,:)=nan(1,5);
            %             end
            %         end
            % 
            %         if strcmpi(ext,'.txt') && sep==1
            %             %res.Xtest=Xtest;
            %             fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
            %             if exp
            %                 fprintf(output(Locb(find(Locb))),'RT experimental= %.3f\n', res.RT_exp(i));
            %             end
            %             fprintf(output(Locb(find(Locb))),'RT predicted= %.3f\n', res.RT_pred(i));
            %             if res.AD_RT(i)==1
            %                 fprintf(output(Locb(find(Locb))),'AD: inside\n');
            %             else
            %                 fprintf(output(Locb(find(Locb))),'AD: outside\n');
            %             end
            %             fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_RT(i));
            %             fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_RT(i));
            %             %CAS=strjoin(res.RT_CAS_neighbor(i,1:5),',\t');
            %             %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
            %             if neighbors==1
            %                 fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.RT.model.set.K, res.RT_CAS_neighbor{i,1:5});
            %                 fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.RT.model.set.K, res.RT_Exp_neighbor(i,1:5));
            %                 fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.RT.model.set.K, res.RT_pred_neighbor(i,1:5));
            %             end
            % 
            %         elseif strcmpi(ext,'.txt') && sep==0
            % 
            %             %res.Xtest=Xtest;
            %             fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
            %             if exp
            %                 fprintf(output,'RT experimental= %.3f\n', res.RT_exp(i));
            %             end
            %             fprintf(output,'RT predicted= %.3f\n', res.RT_pred(i));
            %             if res.AD_RT(i)==1
            %                 fprintf(output,'AD: inside\n');
            %             else
            %                 fprintf(output,'AD: outside\n');
            %             end
            %             fprintf(output,'AD_index= %.2f\n', res.AD_index_RT(i));
            %             fprintf(output,'Conf_index= %.2f\n', res.Conf_index_RT(i));
            %             %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
            %             if neighbors==1
            %                 fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.RT.model.set.K, res.RT_CAS_neighbor{i,1:5});
            %                 fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.RT.model.set.K, res.RT_Exp_neighbor(i,1:5));
            %                 fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.RT.model.set.K, res.RT_pred_neighbor(i,1:5));
            %             end
            % 
            %         end
            %     end
            %     if nf>0 && strcmpi(ext,'.txt')
            %         if sep==1
            %             for i=(f+1):(f+nf)
            %                 fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
            %                 fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
            %             end
            %         elseif sep==0
            %             for i=(f+1):(f+nf)
            %                 fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
            %                 fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
            %             end
            %         end
            %     end
            %     if sep==1 && strcmpi(ext,'.csv')
            %         if nf>0
            %             res=rmfield(res,'MoleculeID');
            %             T=struct2table(res);
            %             %                         T{end+1:end+nf,1:4}=nan(nf,4);
            %             T{end+1:end+nf,4}=nan(nf,1);
            %             for i=length(T{1:end-nf+1,1}):length(T{:,1})
            %                 for j=1:size(T(1,:),2)
            %                     if isnumeric(T{i,j})
            %                         T{i,j}=nan;
            %                     else
            %                         T{i,j}={'NA'};
            %                     end
            %                 end
            %             end
            %             %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
            %             %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
            %             T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
            %             Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
            %         else
            %             T=struct2table(res);
            %         end
            %         if printtDesc==1
            %             Xtest=array2table(Xtest,'VariableNames',Desc);
            % 
            %             T=[T Xtest];
            %             res.Descriptors=Xtest;
            %         end
            %         writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
            %         fclose(output(Locb(find(Locb))));
            %         clear('T');
            %     elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
            %         if nf>0
            %             Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
            %         end
            % 
            %         Xtest(:,ismember(Desc,DescNames))=[];
            % 
            %         Desc(ismember(Desc,DescNames))=[];
            % 
            %         DescNames=[DescNames Desc];
            % 
            %         DescMat=[DescMat Xtest];
            %     end
            % 
            %     if sep==1
            %         resf.RT=res;
            %         clear('res');
            %     end
            %     % Clean memory
            %     clear('Xtest');
            %     clear('pred');
            %     clear('predpls');
            %     clear('AD');
            %     train = rmfield(train, 'RT');
            %     clear('RT_CAS');
            %     clear('RT_DTXSID');
            %     %end clean memory
            % 
            % end
            % %end RT
            
            
            %Predict KOA values
            %case {'koa','logkoa'}
            [Lia,Locb] =ismember({'koa','logkoa'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting LogKOA values (Log10)';
                Desc=train.KOA.Desc;
                
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogKOA values... \n\n			==============================================================  \n\n');
                end
                
                Xtest=Xin(:,train.KOA.Desc_i);
                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some KOA descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed KOA calculations. Check input and repeat calculation.'];
                end
                %%%
                
                pred = nnrpred(Xtest,train.KOA.model.set.train,train.KOA.model.set.y,train.KOA.model.set.K,train.KOA.model.set.dist_type,train.KOA.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogKOA_exp=NaN(size(Xtest,1),1);
                end
                res.LogKOA_pred(:,1)=round(pred.y_pred_weighted,2);
                res.KOA_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.KOA.model.set.train,Xtest,'auto');
                res.AD_KOA=abs(AD.inorout-1)';
                res.AD_KOA(round(pred.dc(:,1),3)==0)=1;
                res.AD_index_KOA=zeros(size(Xtest,1),1);
                res.Conf_index_KOA=zeros(size(Xtest,1),1);
                if neighbors
                    KOA_CAS_neighbor=cell(size(Xtest,1),5);
                    KOA_InChiKey_neighbor=cell(size(Xtest,1),5);
                    KOA_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %KOA_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                LogKOA_Exp_neighbor=nan(size(Xtest,1),5);
                LogKOA_pred_neighbor=nan(size(Xtest,1),5);
                KOA_CAS=strrep(strrep(join(train.KOA.CAS,'|',2),'|||',''),'||','');
                KOA_DTXSID=strrep(strrep(join(train.KOA.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.KOA.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.KOA.DTXSID);
                        end
                        if Li
                            if Lo>size(train.KOA.DTXSID,1)
                                Lo=mod(Lo,size(train.KOA.DTXSID,1));
                            end
                            res.LogKOA_exp(i)=round(train.KOA.model.set.y(Lo),2);
                        end
                    end
                    
                    LogKOA_Exp_neighbor(i,:)=round(train.KOA.model.set.y(pred.neighbors(i,:)),2);
                    LogKOA_pred_neighbor(i,:)=round(train.KOA.model.yc_weighted(pred.neighbors(i,:)),2);
                    
                    %                 rmse=calc_reg_param(res.LogKOA_Exp_neighbor(i,:),res.LogKOA_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_KOA(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_KOA(i,1)=((1/(1+sqrt(((LogKOA_Exp_neighbor(i,:)-LogKOA_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_KOA(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_KOA(i,1)=1;
                        res.AD_index_KOA(i,1)=1;
                    end
                    
                    SKOA=std(LogKOA_Exp_neighbor(i,:),pred.w(i,:));
                    res.KOA_predRange{i,1}=strcat('[', num2str(round(max(res.LogKOA_pred(i,1)-SKOA,min(LogKOA_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.LogKOA_pred(i,1)+SKOA,max(LogKOA_Exp_neighbor(i,:))),2)),']');
                    
                    Std_index_KOA=(1-(std(LogKOA_Exp_neighbor(i,:),pred.w(i,:))/std(train.KOA.model.set.y)));
                    res.Conf_index_KOA(i,1)=max(((1/(1+sqrt(((LogKOA_Exp_neighbor(i,:)-LogKOA_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_KOA(i,1)+Std_index_KOA)/3,0.1);
                    
                    if res.AD_index_KOA(i,1)>=0.6 && res.Conf_index_KOA(i,1)>=0.5
                        res.AD_KOA(i,1)=1;
                    elseif res.AD_index_KOA(i,1)<0.2 && res.Conf_index_KOA(i,1)<0.5
                        res.AD_KOA(i,1)=0;
                    end
                    if res.AD_index_KOA(i,1)==0
                        res.Conf_index_KOA(i,1)=0;
                    end
                    if isnan(res.AD_KOA(i,1))
                        res.AD_KOA(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.LogKOA_pred(i,1))
                        res.LogKOA_pred(i,1)=NaN;
                        res.KOA_predRange{i,1}='NA';
                        res.AD_KOA(i)=0;
                        res.AD_index_KOA(i)=0;
                        res.Conf_index_KOA(i,1)=0;
                    end
                    if Xin(i,12)==0
                        res.AD_KOA(i)=0;
                        res.AD_index_KOA(i)=res.AD_index_KOA(i)/2;
                        res.Conf_index_KOA(i,1)=res.Conf_index_KOA(i,1)/2;
                    end
                    
                    res.AD_index_KOA(i,1)=round(res.AD_index_KOA(i,1),2);
                    res.Conf_index_KOA(i,1)=round(res.Conf_index_KOA(i,1),2);

                    if neighbors==1
                        %                         train.KOA.CAS=strrep(strrep(join(train.KOA.CAS,'|',2),'|||',''),'||','');
                        %                         train.KOA.DTXSID=strrep(strrep(join(train.KOA.DTXSID,'|',2),'|||',''),'||','');
                        KOA_CAS_neighbor(i,:)=KOA_CAS(pred.neighbors(i,:));
                        KOA_InChiKey_neighbor(i,:)=train.KOA.InChiKey(pred.neighbors(i,:));
                        KOA_DTXSID_neighbor(i,:)=KOA_DTXSID(pred.neighbors(i,:));
                        %KOA_DSSTOXMPID_neighbor(i,:)=train.KOA.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_KOA(i)~=0
                            res.KOA_CAS_neighbor(i,:)=KOA_CAS_neighbor(i,:);
                            res.KOA_InChiKey_neighbor(i,:)=KOA_InChiKey_neighbor(i,:);
                            res.KOA_DTXSID_neighbor(i,:)=KOA_DTXSID_neighbor(i,:);
                            %res.KOA_DSSTOXMPID_neighbor(i,:)=KOA_DSSTOXMPID_neighbor(i,:);
                            res.LogKOA_Exp_neighbor(i,:)=LogKOA_Exp_neighbor(i,:);
                            res.LogKOA_pred_neighbor(i,:)=LogKOA_pred_neighbor(i,:);
                        else
                            res.KOA_CAS_neighbor(i,:)=cell(1,5);
                            res.KOA_InChiKey_neighbor(i,:)=cell(1,5);
                            res.KOA_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.KOA_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.LogKOA_Exp_neighbor(i,:)=nan(1,5);
                            res.LogKOA_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogKOA experimental= %.3f\n', res.LogKOA_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogKOA predicted= %.3f\n', res.LogKOA_pred(i));
                        if res.AD_KOA(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_KOA(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_KOA(i));
                        %CAS=strjoin(res.KOA_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.KOA.model.set.K, res.KOA_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.KOA.model.set.K, res.LogKOA_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.KOA.model.set.K, res.LogKOA_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogKOA experimental= %.3f\n', res.LogKOA_exp(i));
                        end
                        fprintf(output,'LogKOA predicted= %.3f\n', res.LogKOA_pred(i));
                        if res.AD_KOA(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_KOA(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_KOA(i));
                        %CAS=strjoin(res.KOA_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.KOA.model.set.K, res.KOA_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.KOA.model.set.K, res.LogKOA_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.KOA.model.set.K, res.LogKOA_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.KOA=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'KOA');
                clear('KOA_CAS');
                clear('KOA_DTXSID');
                %end clean memory
            end
            
            %Predict pka values
            %case {'pka'}
            [Lia,Locb] =ismember({'pka','logd'},lower(prop));
            if find(Lia)
                if Lia(1)
                    step=step+1;
                    d.Value=step/steps;
                end
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting pKa values';
                
                Desc=train.PKA.Desc;
                %             Desc_a=train.PKA.Desc_a;
                %             Desc_b=train.PKA.Desc_b;
                
                
                if strcmpi(ext,'.txt') && sep==0 && Lia(1)
                    fprintf(output,'\n\n\t\t\t\t\t Predicting pKa values... \n\n			==============================================================  \n\n');
                end
                
                Xtest=Xin(:,train.PKA.Desc_i);
                Xtest_a=table2array(XinFP(:,train.PKA.Desc_ai));
                Xtest_b=table2array(XinFP(:,train.PKA.Desc_bi));

                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some pKa descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed pKa calculations. Check input and repeat calculation.'];
                end
                %%%
                
                pred = knnpred(Xtest,train.PKA.model.set.train,train.PKA.model.set.class,train.PKA.model.set.K,train.PKA.model.set.dist_type,train.PKA.model.set.param.pret_type);
                pred.D=diag(pred.D);
                pKa_a(:,1)=svmpredict([1:1:length(Xtest_a(:,1))]',Xtest_a,train.PKA.model_a,'-q');
                %AD_a = nnrpred(Xtest_a,train.PKA_a.model.set.train,train.PKA_a.model.set.y,train.PKA_a.model.set.K,train.PKA_a.model.set.dist_type,train.PKA_a.model.set.param.pret_type);
                
                pKa_b(:,1)=svmpredict([1:1:length(Xtest_b(:,1))]',Xtest_b,train.PKA.model_b,'-q');
                %AD_b = nnrpred(Xtest_b,train.PKA_b.model.set.train,train.PKA_b.model.set.y,train.PKA_b.model.set.K,train.PKA_b.model.set.dist_type,train.PKA_b.model.set.param.pret_type);
                
                res.MoleculeID=MoleculeNames;
                if exp
                    res.pKa_a_exp=NaN(size(Xtest,1),1);
                    res.pKa_b_exp=NaN(size(Xtest,1),1);
                end
                res.ionization=zeros(size(Xtest,1),1);
                pKa_ac_ba_amp=pred.class_pred;
                res.pKa_a_pred=round(pKa_a,2);
                res.pKa_a_predRange=cell(size(Xtest,1),1);
                res.pKa_b_pred=round(pKa_b,2);
                res.pKa_b_predRange=cell(size(Xtest,1),1);
                SpKa=zeros(size(Xtest,1),1);
                
                AD=classical_leverage(train.PKA.model.set.train,Xtest,'auto');
                res.AD_pKa=abs(AD.inorout-1)';
                res.AD_pKa(round(pred.dc(:,1),3)==0)=1;
                
                res.AD_index_pKa=zeros(size(Xtest,1),1);
                res.Conf_index_pKa=zeros(size(Xtest,1),1);
                if neighbors
                    pKa_CAS_neighbor=cell(size(Xtest,1),3);
                    pKa_InChiKey_neighbor=cell(size(Xtest,1),3);
                    pKa_DTXSID_neighbor=cell(size(Xtest,1),3);
                    %pKa_DSSTOXMPID_neighbor=cell(size(Xtest,1),3);
                end
                pKa_Exp_neighbor=nan(size(Xtest,1),3);
                pKa_pred_neighbor=nan(size(Xtest,1),3);
                PKA_CAS=strrep(strrep(join(train.PKA.CAS,'|',2),'|||',''),'||','');
                PKA_DTXSID=strrep(strrep(join(train.PKA.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.PKA.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.PKA.DTXSID);
                        end
                        if Li
                            res.pKa_a_exp(i,1)=round(train.PKA.model.set.y_exp(Lo,1),2);
                            res.pKa_b_exp(i,1)=round(train.PKA.model.set.y_exp(Lo,2),2);
                        end
                    end
                    %if sum(Xin(i,13:14))-sum(Xin(i,731:732))==0
                    %if Xin(i,747)==0 && (sum(Xin(i,13:14))-sum(Xin(i,[722 731:732]))==0 || (Xin(i,13)==Xin(i,731) && Xin(i,14)==2*Xin(i,13) && Xin(i,722)==0 && Xin(i,732)==0))
                    if XinFP{i,5911}==0 && Xin(i,747)==0 && (sum(Xin(i,13:14))-sum(Xin(i,[722 731:732]))==0 || (Xin(i,13)==Xin(i,731) && Xin(i,14)==2*Xin(i,13) && Xin(i,722)==0 && Xin(i,732)==0))
                        pKa_ac_ba_amp(i)=NaN;
                        res.ionization(i)=0;
                        res.pKa_a_pred(i)=NaN;
                        res.pKa_b_pred(i)=NaN;
                        
                    else
                        
                        if pred.class_pred(i)==1
                            res.pKa_b_pred(i,1)=NaN;
                            res.ionization(i)=1;
                        elseif pred.class_pred(i)==2 && XinFP{i,5911}==0 && Xin(i,747)==0
                            res.pKa_a_pred(i,1)=NaN;
                            res.ionization(i)=1;
                        elseif pred.class_pred(i)==3 || (pred.class_pred(i)==2 && (XinFP{i,5911}==1||Xin(i,747)==1))
                            res.ionization(i)=2;
                            
                        end
                    end
                    
                    
                    pKa_Exp_neighbor(i,:)=round(train.PKA.model.set.y(pred.neighbors(i,:)),2);
                    pKa_pred_neighbor(i,:)=round(train.PKA.model.set.yc(pred.neighbors(i,:)),2);
                    
                    %                 rmse=calc_reg_param(res.LogKOA_Exp_neighbor(i,:),res.LogKOA_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_pKa(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_pKa(i,1)=((1/(1+sqrt(((pKa_Exp_neighbor(i,:)-pKa_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_pKa(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_pKa(i,1)=1;
                        res.AD_index_pKa(i,1)=1;
                    end
                    
                    SpKa(i)=std(pKa_Exp_neighbor(i,:),pred.w(i,:));
                    if ~isnan(res.pKa_a_pred(i,1))
                        res.pKa_a_predRange{i,1}=strcat('[', num2str(round(res.pKa_a_pred(i,1)-SpKa(i),2)),':',num2str(round(res.pKa_a_pred(i,1)+SpKa(i),2)),']');
                    else
                        res.pKa_a_predRange{i,1}='NA';
                    end
                    if ~isnan(res.pKa_b_pred(i,1))
                        res.pKa_b_predRange{i,1}=strcat('[', num2str(round(res.pKa_b_pred(i,1)-SpKa(i),2)),':',num2str(round(res.pKa_b_pred(i,1)+SpKa(i),2)),']');
                    else
                        res.pKa_b_predRange{i,1}='NA';
                    end
                    
                    Std_index_pKa=(1-(std(pKa_Exp_neighbor(i,:),pred.w(i,:))/std(train.PKA.model.set.y)));
                    res.Conf_index_pKa(i,1)=max(((1/(1+sqrt(((pKa_Exp_neighbor(i,:)-pKa_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_pKa(i,1)+Std_index_pKa)/3,0.1);
                    
                    if res.AD_index_pKa(i,1)>=0.6 && res.Conf_index_pKa(i,1)>=0.5
                        res.AD_pKa(i,1)=1;
                    elseif res.AD_index_pKa(i,1)<0.2 && res.Conf_index_pKa(i,1)<0.5
                        res.AD_pKa(i,1)=0;
                    end
                    if res.AD_index_pKa(i,1)==0
                        res.Conf_index_pKa(i,1)=0;
                    end
                    if isnan(res.AD_pKa(i,1))
                        res.AD_pKa(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || (isnan(res.pKa_a_pred(i,1)) && isnan(res.pKa_b_pred(i,1)))
                        res.pKa_a_pred(i,1)=NaN;
                        res.pKa_a_predRange{i,1}='NA';
                        res.pKa_b_pred(i,1)=NaN;
                        res.pKa_b_predRange{i,1}='NA';
                        res.AD_pKa(i,1)=0;
                        res.AD_index_pKa(i,1)=0;
                        res.Conf_index_pKa(i,1)=0;
                        res.ionization(i)=0;
                        pKa_ac_ba_amp(i)=NaN;
                    end
                    if Xin(i,12)==0
                        res.AD_pKa(i)=0;
                        res.AD_index_pKa(i)=res.AD_index_pKa(i)/2;
                        res.Conf_index_pKa(i,1)=res.Conf_index_pKa(i,1)/2;
                    end
                    
                    res.AD_index_pKa(i,1)=round(res.AD_index_pKa(i,1),2);
                    res.Conf_index_pKa(i,1)=round(res.Conf_index_pKa(i,1),2);

                    if neighbors==1
                        %                         train.PKA.CAS=strrep(strrep(join(train.PKA.CAS,'|',2),'|||',''),'||','');
                        %                         train.PKA.DTXSID=strrep(strrep(join(train.PKA.DTXSID,'|',2),'|||',''),'||','');
                        pKa_CAS_neighbor(i,:)=PKA_CAS(pred.neighbors(i,:));
                        pKa_InChiKey_neighbor(i,:)=train.PKA.InChiKey(pred.neighbors(i,:));
                        pKa_DTXSID_neighbor(i,:)=PKA_DTXSID(pred.neighbors(i,:));
                        %pKa_DSSTOXMPID_neighbor(i,:)=train.PKA.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_pKa(i,1)~=0
                            res.pKa_CAS_neighbor(i,:)=pKa_CAS_neighbor(i,:);
                            res.pKa_InChiKey_neighbor(i,:)=pKa_InChiKey_neighbor(i,:);
                            res.pKa_DTXSID_neighbor(i,:)=pKa_DTXSID_neighbor(i,:);
                            %res.pKa_DSSTOXMPID_neighbor(i,:)=pKa_DSSTOXMPID_neighbor(i,:);
                            res.pKa_Exp_neighbor(i,:)=pKa_Exp_neighbor(i,:);
                            res.pKa_pred_neighbor(i,:)=pKa_pred_neighbor(i,:);
                        else
                            res.pKa_CAS_neighbor(i,:)=cell(1,3);
                            res.pKa_InChiKey_neighbor(i,:)=cell(1,3);
                            res.pKa_DTXSID_neighbor(i,:)=cell(1,3);
                            %res.pKa_DSSTOXMPID_neighbor(i,:)=cell(1,3);
                            res.pKa_Exp_neighbor(i,:)=nan(1,3);
                            res.pKa_pred_neighbor(i,:)=nan(1,3);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1 && Lia(1)
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(1)),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(1)),'pKa acidic and basic experimental= %.3f, %.3f\n', res.pKa_a_exp(i),res.pKa_b_exp(i));
                        end
                        fprintf(output(Locb(1)),'pKa acidic and basic predicted= %.3f, %.3f\n', res.pKa_a_pred(i),res.pKa_b_pred(i));
                        if res.AD_pKa(i)==1
                            fprintf(output(Locb(1)),'AD: inside\n');
                        else
                            fprintf(output(Locb(1)),'AD: outside\n');
                        end
                        fprintf(output(Locb(1)),'AD_index= %.2f\n', res.AD_index_pKa(i));
                        fprintf(output(Locb(1)),'Conf_index= %.2f\n', res.Conf_index_pKa(i));
                        %CAS=strjoin(res.RT_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(1)),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.PKA.model.set.K, res.pKa_CAS_neighbor{i,1:3});
                            fprintf(output(Locb(1)),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.PKA.model.set.K, res.pKa_Exp_neighbor(i,1:3));
                            fprintf(output(Locb(1)),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.PKA.model.set.K, res.pKa_pred_neighbor(i,1:3));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0 && Lia(1)
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'pKa acidic and basic experimental= %.3f, %.3f\n', res.pKa_a_exp(i),res.pKa_b_exp(i));
                        end
                        fprintf(output,'pKa acidic and basic predicted= %.3f, %.3f\n', res.pKa_a_pred(i),res.pKa_b_pred(i));
                        if res.AD_pKa(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_pKa(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_pKa(i));
                        %CAS=strjoin(res.KOA_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.PKA.model.set.K, res.pKa_CAS_neighbor{i,1:3});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.PKA.model.set.K, res.pKa_Exp_neighbor(i,1:3));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.PKA.model.set.K, res.pKa_pred_neighbor(i,1:3));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt') && Lia(1)
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(1)),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(1)),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv') && Lia(1)
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(1)},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(1)));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv') && Lia(1)
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.pKa=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('XinFP');
                clear('Xtest_a');
                clear('Xtest_b');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'PKA');
                clear('PKA_CAS');
                clear('PKA_DTXSID');
                %end clean memory
            end
            %Predict LogD values
            %case 'logd'
            [Lia,Locb] =ismember('logd',lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting LogD values (Log10)';
                
                res.MoleculeID=MoleculeNames;
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogD values... \n\n			==============================================================  \n\n');
                end
                if sep==1
                    
                    res.LogD55_pred=resf.LogP.LogP_pred;
                    res.LogD55_predRange=resf.LogP.LogP_predRange;
                    res.LogD74_pred=resf.LogP.LogP_pred;
                    res.LogD74_predRange=resf.LogP.LogP_predRange;
                    res.AD_LogD=resf.LogP.AD_LogP+resf.pKa.AD_pKa;
                    res.AD_LogD(find(res.AD_LogD==1))=0;
                    res.AD_LogD(find(res.AD_LogD==2))=1;
                    res.AD_index_LogD=0.5*resf.pKa.AD_index_pKa+0.5*resf.LogP.AD_index_LogP;
                    res.Conf_index_LogD=0.5*resf.pKa.Conf_index_pKa+0.5*resf.LogP.Conf_index_LogP;
                    
                    if neighbors==1
                        res.LogD_CAS_neighbor=resf.LogP.LogP_CAS_neighbor;
                        res.LogD_InChiKey_neighbor=resf.LogP.LogP_InChiKey_neighbor;
                        res.LogD_DTXSID_neighbor=resf.LogP.LogP_DTXSID_neighbor;
                        %res.LogD_DSSTOXMPID_neighbor=resf.LogP.LogP_DSSTOXMPID_neighbor;
                        %res.LogD_Exp_neighbor=LogP_Exp_neighbor;
                        %res.LogD_pred_neighbor=LogP_pred_neighbor;
                    end
                    

                    for i=1:length(res.LogD55_pred)
                        if Xin(i,12)==0
                            res.AD_LogD(i)=0;
                            res.AD_index_LogD(i)=res.AD_index_LogD(i)/2;
                            res.Conf_index_LogD(i,1)=res.Conf_index_LogD(i,1)/2;
                        end
                        if ~isnan(pKa_ac_ba_amp(i))
                            if pKa_ac_ba_amp(i)==1 && ~isnan(resf.pKa.pKa_a_pred(i,1))
                                res.LogD55_pred(i,1)=round(resf.LogP.LogP_pred(i,1)-log10(1+10^(5.5-resf.pKa.pKa_a_pred(i,1))),2);
                                SLogD55=SLogP(i)-log10(1+10^(5.5-SpKa(i)));
                                res.LogD74_pred(i,1)=round(resf.LogP.LogP_pred(i,1)-log10(1+10^(7.4-resf.pKa.pKa_a_pred(i,1))),2);
                                SLogD74=SLogP(i)-log10(1+10^(7.4-SpKa(i)));
                            elseif pKa_ac_ba_amp(i)==2 && ~isnan(resf.pKa.pKa_b_pred(i,1))
                                res.LogD55_pred(i,1)=round(resf.LogP.LogP_pred(i,1)-log10(1+10^(resf.pKa.pKa_b_pred(i,1)-5.5)),2);
                                SLogD55=SLogP(i)-log10(1+10^(SpKa(i)-5.5));
                                res.LogD74_pred(i,1)=round(resf.LogP.LogP_pred(i,1)-log10(1+10^(resf.pKa.pKa_b_pred(i,1)-7.4)),2);
                                SLogD74=SLogP(i)-log10(1+10^(SpKa(i)-7.4));
                            elseif pKa_ac_ba_amp(i)==3 && ~isnan(resf.pKa.pKa_a_pred(i,1))&& ~isnan(resf.pKa.pKa_b_pred(i,1))
                                res.LogD55_pred(i,1)=round(resf.LogP.LogP_pred(i,1)-log10(1+10^abs(0.5*resf.pKa.pKa_a_pred(i,1)+0.5*resf.pKa.pKa_b_pred(i,1)-5.5)),2);
                                SLogD55=SLogP(i)-log10(1+10^abs(SpKa(i)-5.5));
                                res.LogD74_pred(i,1)=round(resf.LogP.LogP_pred(i,1)-log10(1+10^abs(0.5*resf.pKa.pKa_a_pred(i,1)+0.5*resf.pKa.pKa_b_pred(i,1)-7.4)),2);
                                SLogD74=SLogP(i)-log10(1+10^abs(SpKa(i)-7.4));
                            end
                            res.LogD55_predRange{i,1}=strcat('[', num2str(round(min(res.LogD55_pred(i,1)-SLogD55,res.LogD55_pred(i,1)+SLogD55),2)),':',num2str(round(max(res.LogD55_pred(i,1)-SLogD55,res.LogD55_pred(i,1)+SLogD55),2)),']');
                            res.LogD74_predRange{i,1}=strcat('[', num2str(round(min(res.LogD74_pred(i,1)-SLogD74,res.LogD74_pred(i,1)+SLogD74),2)),':',num2str(round(max(res.LogD74_pred(i,1)-SLogD74,res.LogD74_pred(i,1)+SLogD74),2)),']');
                        end
                        
                        if strcmpi(ext,'.txt')
                            fprintf(output(Locb(1)),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(1)),'LogD pH 5.5 predicted= %.3f\n', res.LogD55_pred(i));
                            fprintf(output(Locb(1)),'LogD pH 7.4 predicted= %.3f\n', res.LogD74_pred(i));
                            if res.AD_LogD(i)==1
                                fprintf(output(Locb(1)),'AD: inside\n');
                            else
                                fprintf(output(Locb(1)),'AD: outside\n');
                            end
                            fprintf(output(Locb(1)),'AD_index= %.2f\n', res.AD_index_LogD(i));
                            fprintf(output(Locb(1)),'Conf_index= %.2f\n', res.Conf_index_LogD(i));
                            if neighbors==1
                                fprintf(output(Locb(1)),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.LOGP.model.set.K,resf.LogP.LogP_CAS_neighbor{i,1:5});
                                %fprintf(output(Locb(1)),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.LOGP.model.set.K, res.LogP_Exp_neighbor(i,1:5));
                                %fprintf(output(Locb(1)),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.LOGP.model.set.K, res.LogP_pred_neighbor(i,1:5));
                            end
                        end
                    end
                    if nf>0 && strcmpi(ext,'.txt')
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(1)),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(1)),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                    if strcmpi(ext,'.csv')
                        if nf>0
                            res=rmfield(res,'MoleculeID');
                            T=struct2table(res);
                            %                             T{end+1:end+nf,1:4}=nan(nf,4);
                            T{end+1:end+nf,1}=nan(nf,1);
                            for i=length(T{1:end-nf+1,1}):length(T{:,1})
                                for j=1:size(T(1,:),2)
                                    if isnumeric(T{i,j})
                                        T{i,j}=nan;
                                    else
                                        T{i,j}={'NA'};
                                    end
                                end
                            end
                            %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                            %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                            T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        else
                            T=struct2table(res);
                        end
                        writetable(T,FileOut{Locb(1)},'Delimiter',',');%,'QuoteStrings',true);
                        fclose(output(Locb(1)));
                        clear('T');
                    end
                    resf.LogD=res;
                    clear('res');
                    
                else
                    res.LogD55_pred=res.LogP_pred;
                    res.LogD55_predRange=res.LogP_predRange;
                    res.LogD74_pred=res.LogP_pred;
                    res.LogD74_predRange=res.LogP_predRange;
                    res.AD_LogD=res.AD_LogP+res.AD_pKa;
                    res.AD_LogD(find(res.AD_LogD==1))=0;
                    res.AD_LogD(find(res.AD_LogD==2))=1;
                    res.AD_index_LogD=0.5*res.AD_index_pKa+0.5*res.AD_index_LogP;
                    res.Conf_index_LogD=0.5*res.Conf_index_pKa+0.5*res.Conf_index_LogP;
                    
                    if neighbors==1
                        res.LogD_CAS_neighbor=res.LogP_CAS_neighbor;
                        res.LogD_InChiKey_neighbor=res.LogP_InChiKey_neighbor;
                        res.LogD_DTXSID_neighbor=res.LogP_DTXSID_neighbor;
                        %res.LogD_DSSTOXMPID_neighbor=res.LogP_DSSTOXMPID_neighbor;
                        %res.LogD_Exp_neighbor=LogP_Exp_neighbor;
                        %res.LogD_pred_neighbor=LogP_pred_neighbor;
                    end
                    
                    for i=1:length(res.LogD55_pred)
                        if ~isnan(pKa_ac_ba_amp(i))
                            if pKa_ac_ba_amp(i)==1 && ~isnan(res.pKa_a_pred(i,1))
                                res.LogD55_pred(i,1)=round(res.LogP_pred(i,1)-log10(1+10^(5.5-res.pKa_a_pred(i,1))),2);
                                SLogD55=SLogP(i)-log10(1+10^(5.5-SpKa(i)));
                                res.LogD74_pred(i,1)=round(res.LogP_pred(i,1)-log10(1+10^(7.4-res.pKa_a_pred(i,1))),2);
                                SLogD74=SLogP(i)-log10(1+10^(7.4-SpKa(i)));
                            elseif pKa_ac_ba_amp(i)==2 && ~isnan(res.pKa_b_pred(i,1))
                                res.LogD55_pred(i,1)=round(res.LogP_pred(i,1)-log10(1+10^(res.pKa_b_pred(i,1)-5.5)),2);
                                SLogD55=SLogP(i)-log10(1+10^(SpKa(i)-5.5));
                                res.LogD74_pred(i,1)=round(res.LogP_pred(i,1)-log10(1+10^(res.pKa_b_pred(i,1)-7.4)),2);
                                SLogD74=SLogP(i)-log10(1+10^(SpKa(i)-7.4));
                            elseif pKa_ac_ba_amp(i)==3 && ~isnan(res.pKa_a_pred(i,1)) && ~isnan(res.pKa_b_pred(i,1))
                                res.LogD55_pred(i,1)=round(res.LogP_pred(i,1)-log10(1+10^abs(0.5*res.pKa_a_pred(i,1)+0.5*res.pKa_b_pred(i,1)-5.5)),2);
                                SLogD55=SLogP(i)-log10(1+10^abs(SpKa(i)-5.5));
                                res.LogD74_pred(i,1)=round(res.LogP_pred(i,1)-log10(1+10^abs(0.5*res.pKa_a_pred(i,1)+0.5*res.pKa_b_pred(i,1)-7.4)),2);
                                SLogD74=SLogP(i)-log10(1+10^abs(SpKa(i)-7.4));
                            end
                            res.LogD55_predRange{i,1}=strcat('[', num2str(round(min(res.LogD55_pred(i,1)-SLogD55,res.LogD55_pred(i,1)+SLogD55),2)),':',num2str(round(max(res.LogD55_pred(i,1)-SLogD55,res.LogD55_pred(i,1)+SLogD55),2)),']');
                            res.LogD74_predRange{i,1}=strcat('[', num2str(round(min(res.LogD74_pred(i,1)-SLogD74,res.LogD74_pred(i,1)+SLogD74),2)),':',num2str(round(max(res.LogD74_pred(i,1)-SLogD74,res.LogD74_pred(i,1)+SLogD74),2)),']');
                        end
                        
                        if strcmpi(ext,'.txt')
                            fprintf(output,'\t Molecule %s:\n',res.MoleculeID{i});
                            fprintf(output,'LogD pH 5.5 predicted= %.3f\n', res.LogD55_pred(i));
                            fprintf(output,'LogD pH 7.4 predicted= %.3f\n', res.LogD74_pred(i));
                            if res.AD_LogD(i)==1
                                fprintf(output,'AD: inside\n');
                            else
                                fprintf(output,'AD: outside\n');
                            end
                            fprintf(output,'AD_index= %.2f\n', res.AD_index_LogD(i));
                            fprintf(output,'Conf_index= %.2f\n', res.Conf_index_LogD(i));
                            if neighbors==1
                                fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.LOGP.model.set.K, res.LogP_CAS_neighbor{i,1:5});
                                %fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.LOGP.model.set.K, res.LogP_Exp_neighbor(i,1:5));
                                %fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.LOGP.model.set.K, res.LogP_pred_neighbor(i,1:5));
                            end
                            
                        end
                        
                        
                    end
                    if nf>0 && strcmpi(ext,'.txt')
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                clear('SpKa');
                clear('SLogP');
            end
            
            %  Env. Fate Endpoints
            
            %---------- Env. Fate Endpoints ----------\n');
            
            %Predict AOH values
            %case {'aop','logoh','aoh'}
            [Lia,Locb] =ismember({'aop','logoh','aoh'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting LogOH values (Log10 cm3/molecule-sec)';
                Desc=train.AOH.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogOH values... \n\n			============================================================== \n\n');
                end
                
                Xtest=Xin(:,train.AOH.Desc_i);
                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some AOH descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed AOH calculations. Check input and repeat calculation.'];
                end
                %%%
                
                pred = nnrpred(Xtest,train.AOH.model.set.train,train.AOH.model.set.y,train.AOH.model.set.K,train.AOH.model.set.dist_type,train.AOH.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogOH_exp=NaN(size(Xtest,1),1);
                end
                res.LogOH_pred(:,1)=round(pred.y_pred_weighted,2);
                res.LogOH_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.AOH.model.set.train,Xtest,'auto');
                res.AD_AOH=abs(AD.inorout-1)';
                res.AD_AOH(round(pred.dc(:,1),3)==0)=1;
                
                %res.AD_index=1./(1+nanmedian(pred.dc,2));
                
                
                %             res.AD_index=1./(1+median(pred.dc,2));
                %             if isnan(res.AD_index)
                %                 res.AD_index=0;
                %             end
                
                res.AD_index_AOH=zeros(size(Xtest,1),1);
                res.Conf_index_AOH=zeros(size(Xtest,1),1);
                if neighbors
                    AOH_CAS_neighbor=cell(size(Xtest,1),5);
                    AOH_InChiKey_neighbor=cell(size(Xtest,1),5);
                    AOH_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %AOH_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                LogOH_Exp_neighbor=nan(size(Xtest,1),5);
                LogOH_pred_neighbor=nan(size(Xtest,1),5);
                AOH_CAS=strrep(strrep(join(train.AOH.CAS,'|',2),'|||',''),'||','');
                AOH_DTXSID=strrep(strrep(join(train.AOH.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.AOH.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.AOH.DTXSID);
                        end
                        if Li
                            if Lo>size(train.AOH.DTXSID,1)
                                Lo=mod(Lo,size(train.AOH.DTXSID,1));
                            end
                            res.LogOH_exp(i)=round(train.AOH.model.set.y(Lo),2);
                        end
                    end
                    
                    LogOH_Exp_neighbor(i,:)=round(train.AOH.model.set.y(pred.neighbors(i,:)),2);
                    LogOH_pred_neighbor(i,:)=round(train.AOH.model.yc_weighted(pred.neighbors(i,:)),2);
                    
                    %                 rmse=calc_reg_param(res.LogOH_Exp_neighbor(i,:),res.LogOH_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_AOH(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_AOH(i,1)=((1/(1+sqrt(((LogOH_Exp_neighbor(i,:)-LogOH_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_AOH(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_AOH(i,1)=1;
                        res.AD_index_AOH(i,1)=1;
                    end
                    
                    SAOH=std(LogOH_Exp_neighbor(i,:),pred.w(i,:));
                    res.LogOH_predRange{i,1}=strcat('[', num2str(round(max(res.LogOH_pred(i,1)-SAOH,min(LogOH_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.LogOH_pred(i,1)+SAOH,max(LogOH_Exp_neighbor(i,:))),2)),']');
                    
                    Std_index_AOH=(1-(std(LogOH_Exp_neighbor(i,:),pred.w(i,:))/std(train.AOH.model.set.y)));
                    res.Conf_index_AOH(i,1)=max(((1/(1+sqrt(((LogOH_Exp_neighbor(i,:)-LogOH_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_AOH(i,1)+Std_index_AOH)/3,0.1);
                    
                    if res.AD_index_AOH(i,1)>=0.6 && res.Conf_index_AOH(i,1)>=0.5
                        res.AD_AOH(i,1)=1;
                    elseif res.AD_index_AOH(i,1)<0.2 && res.Conf_index_AOH(i,1)<0.5
                        res.AD_AOH(i,1)=0;
                    end
                    if res.AD_index_AOH(i,1)==0
                        res.Conf_index_AOH(i,1)=0;
                    end
                    if isnan(res.AD_AOH(i,1))
                        res.AD_AOH(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.LogOH_pred(i,1))
                        res.LogOH_pred(i,1)=NaN;
                        res.LogOH_predRange{i,1}='NA';
                        res.AD_AOH(i)=0;
                        res.AD_index_AOH(i)=0;
                        res.Conf_index_AOH(i,1)=0;
                    end
                    if Xin(i,12)==0
                        res.AD_AOH(i)=0;
                        res.AD_index_AOH(i)=res.AD_index_AOH(i)/2;
                        res.Conf_index_AOH(i,1)=res.Conf_index_AOH(i,1)/2;
                    end
                    
                    res.AD_index_AOH(i,1)=round(res.AD_index_AOH(i,1),2);
                    res.Conf_index_AOH(i,1)=round(res.Conf_index_AOH(i,1),2);

                    if neighbors==1
                        %                         train.AOH.CAS=strrep(strrep(join(train.AOH.CAS,'|',2),'|||',''),'||','');
                        %                         train.AOH.DTXSID=strrep(strrep(join(train.AOH.DTXSID,'|',2),'|||',''),'||','');
                        AOH_CAS_neighbor(i,:)=AOH_CAS(pred.neighbors(i,:));
                        AOH_InChiKey_neighbor(i,:)=train.AOH.InChiKey(pred.neighbors(i,:));
                        AOH_DTXSID_neighbor(i,:)=AOH_DTXSID(pred.neighbors(i,:));
                        %AOH_DSSTOXMPID_neighbor(i,:)=train.AOH.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_AOH(i)~=0
                            res.AOH_CAS_neighbor(i,:)=AOH_CAS_neighbor(i,:);
                            res.AOH_InChiKey_neighbor(i,:)=AOH_InChiKey_neighbor(i,:);
                            res.AOH_DTXSID_neighbor(i,:)=AOH_DTXSID_neighbor(i,:);
                            %res.AOH_DSSTOXMPID_neighbor(i,:)=AOH_DSSTOXMPID_neighbor(i,:);
                            res.LogOH_Exp_neighbor(i,:)=LogOH_Exp_neighbor(i,:);
                            res.LogOH_pred_neighbor(i,:)=LogOH_pred_neighbor(i,:);
                        else
                            res.AOH_CAS_neighbor(i,:)=cell(1,5);
                            res.AOH_InChiKey_neighbor(i,:)=cell(1,5);
                            res.AOH_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.AOH_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.LogOH_Exp_neighbor(i,:)=nan(1,5);
                            res.LogOH_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        
                        %res.Xtest=Xtest;
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogOH experimental= %.3f\n', res.LogOH_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogOH predicted= %.3f\n', res.LogOH_pred(i));
                        if res.AD_AOH(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_AOH(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_AOH(i));
                        %CAS=strjoin(res.AOH_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.AOH.model.set.K, res.AOH_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.AOH.model.set.K, res.LogOH_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.AOH.model.set.K, res.LogOH_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogOH experimental= %.3f\n', res.LogOH_exp(i));
                        end
                        fprintf(output,'LogOH predicted= %.3f\n', res.LogOH_pred(i));
                        if res.AD_AOH(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_AOH(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_AOH(i));
                        %CAS=strjoin(res.AOH_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.AOH.model.set.K, res.AOH_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.AOH.model.set.K, res.LogOH_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.AOH.model.set.K, res.LogOH_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.AOH=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'AOH');
                clear('AOH_CAS');
                clear('AOH_DTXSID');
                %end clean memory
                
            end
            
            %Predict BCF values
            %case {'bcf', 'logbcf'}
            [Lia,Locb] =ismember({'bcf','logbcf'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting LogBCF values (Log10)';
                
                Desc=train.BCF.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogBCF values... \n\n			============================================================== \n\n');
                end
                
                Xtest=Xin(:,train.BCF.Desc_i);

                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some BCF descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed BCF calculations. Check input and repeat calculation.'];
                end
                %%%
                
                pred = nnrpred(Xtest,train.BCF.model.set.train,train.BCF.model.set.y,train.BCF.model.set.K,train.BCF.model.set.dist_type,train.BCF.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogBCF_exp=NaN(size(Xtest,1),1);
                end
                res.LogBCF_pred(:,1)=round(pred.y_pred_weighted,2);
                res.BCF_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.BCF.model.set.train,Xtest,'auto');
                res.AD_BCF=abs(AD.inorout-1)';
                res.AD_BCF(round(pred.dc(:,1),3)==0)=1;
                
                res.AD_index_BCF=zeros(size(Xtest,1),1);
                res.Conf_index_BCF=zeros(size(Xtest,1),1);
                if neighbors
                    LogBCF_CAS_neighbor=cell(size(Xtest,1),5);
                    LogBCF_InChiKey_neighbor=cell(size(Xtest,1),5);
                    LogBCF_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %LogBCF_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                LogBCF_Exp_neighbor=nan(size(Xtest,1),5);
                LogBCF_pred_neighbor=nan(size(Xtest,1),5);
                BCF_CAS=strrep(strrep(join(train.BCF.CAS,'|',2),'|||',''),'||','');
                BCF_DTXSID=strrep(strrep(join(train.BCF.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.BCF.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.BCF.DTXSID);
                        end
                        if Li
                            if Lo>size(train.BCF.DTXSID,1)
                                Lo=mod(Lo,size(train.BCF.DTXSID,1));
                            end
                            res.LogBCF_exp(i)=round(train.BCF.model.set.y(Lo),2);
                        end
                    end
                    
                    LogBCF_Exp_neighbor(i,:)=round(train.BCF.model.set.y(pred.neighbors(i,:)),2);
                    LogBCF_pred_neighbor(i,:)=round(train.BCF.model.yc_weighted(pred.neighbors(i,:)),2);
                    
                    %                 rmse=calc_reg_param(res.LogBCF_Exp_neighbor(i,:),res.LogBCF_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    %res.Conf_index2(i,1)=(res.Conf_index(i)*res.AD_index(i))^0.5;
                    
                    res.AD_index_BCF(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_BCF(i,1)=((1/(1+sqrt(((LogBCF_Exp_neighbor(i,:)-LogBCF_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_BCF(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_BCF(i,1)=1;
                        res.AD_index_BCF(i,1)=1;
                    end
                    
                    SBCF=std(LogBCF_Exp_neighbor(i,:),pred.w(i,:));
                    res.BCF_predRange{i,1}=strcat('[', num2str(round(max(res.LogBCF_pred(i,1)-SBCF,min(LogBCF_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.LogBCF_pred(i,1)+SBCF,max(LogBCF_Exp_neighbor(i,:))),2)),']');
                    
                    Std_index_BCF=(1-(std(LogBCF_Exp_neighbor(i,:),pred.w(i,:))/std(train.BCF.model.set.y)));
                    res.Conf_index_BCF(i,1)=max(((1/(1+sqrt(((LogBCF_Exp_neighbor(i,:)-LogBCF_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_BCF(i,1)+Std_index_BCF)/3,0.1);
                    
                    if res.AD_index_BCF(i,1)>=0.6 && res.Conf_index_BCF(i,1)>=0.5
                        res.AD_BCF(i,1)=1;
                    elseif res.AD_index_BCF(i,1)<0.2 && res.Conf_index_BCF(i,1)<0.5
                        res.AD_BCF(i,1)=0;
                    end
                    if res.AD_index_BCF(i,1)==0
                        res.Conf_index_BCF(i,1)=0;
                    end
                    if isnan(res.AD_BCF(i,1))
                        res.AD_BCF(i,1)=0;
                    end
                   
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.LogBCF_pred(i,1))
                        res.LogBCF_pred(i,1)=NaN;
                        res.BCF_predRange{i,1}='NA';
                        res.AD_BCF(i)=0;
                        res.AD_index_BCF(i)=0;
                        res.Conf_index_BCF(i,1)=0;
                    end
                    if Xin(i,12)==0
                        res.AD_BCF(i)=0;
                        res.AD_index_BCF(i)=res.AD_index_BCF(i)/2;
                        res.Conf_index_BCF(i,1)=res.Conf_index_BCF(i,1)/2;
                    end
                    
                    res.AD_index_BCF(i,1)=round(res.AD_index_BCF(i,1),2);
                    res.Conf_index_BCF(i,1)=round(res.Conf_index_BCF(i,1),2);

                    if neighbors==1
                        %                         train.BCF.CAS=strrep(strrep(join(train.BCF.CAS,'|',2),'|||',''),'||','');
                        %                         train.BCF.DTXSID=strrep(strrep(join(train.BCF.DTXSID,'|',2),'|||',''),'||','');
                        LogBCF_CAS_neighbor(i,:)=BCF_CAS(pred.neighbors(i,:));
                        LogBCF_InChiKey_neighbor(i,:)=train.BCF.InChiKey(pred.neighbors(i,:));
                        LogBCF_DTXSID_neighbor(i,:)=BCF_DTXSID(pred.neighbors(i,:));
                        %LogBCF_DSSTOXMPID_neighbor(i,:)=train.BCF.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_BCF(i)~=0
                            res.LogBCF_CAS_neighbor(i,:)=LogBCF_CAS_neighbor(i,:);
                            res.LogBCF_InChiKey_neighbor(i,:)=LogBCF_InChiKey_neighbor(i,:);
                            res.LogBCF_DTXSID_neighbor(i,:)=LogBCF_DTXSID_neighbor(i,:);
                            %res.LogBCF_DSSTOXMPID_neighbor(i,:)=LogBCF_DSSTOXMPID_neighbor(i,:);
                            res.LogBCF_Exp_neighbor(i,:)=LogBCF_Exp_neighbor(i,:);
                            res.LogBCF_pred_neighbor(i,:)=LogBCF_pred_neighbor(i,:);
                        else
                            res.LogBCF_CAS_neighbor(i,:)=cell(1,5);
                            res.LogBCF_InChiKey_neighbor(i,:)=cell(1,5);
                            res.LogBCF_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.LogBCF_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.LogBCF_Exp_neighbor(i,:)=nan(1,5);
                            res.LogBCF_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogBCF experimental= %.3f\n', res.LogBCF_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogBCF predicted= %.3f\n', res.LogBCF_pred(i));
                        if res.AD_BCF(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_BCF(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_BCF(i));
                        %CAS=strjoin(res.LogBCF_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.BCF.model.set.K, res.LogBCF_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.BCF.model.set.K, res.LogBCF_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.BCF.model.set.K, res.LogBCF_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogBCF experimental= %.3f\n', res.LogBCF_exp(i));
                        end
                        fprintf(output,'LogBCF predicted= %.3f\n', res.LogBCF_pred(i));
                        if res.AD_BCF(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_BCF(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_BCF(i));
                        %CAS=strjoin(res.LogBCF_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.BCF.model.set.K, res.LogBCF_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.BCF.model.set.K, res.LogBCF_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.BCF.model.set.K, res.LogBCF_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.BCF=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'BCF');
                clear('BCF_CAS');
                clear('BCF_DTXSID');
                %end clean memory
            end
            
            %Predict Biodegradability values
            %case {'biohc','biohl','biodeg','biodeghl'}
            [Lia,Locb] =ismember({'biohc','biohl','biodeg','biodeghl'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting Biodeg. half-life (Log10 days)';
                Desc=train.BIODEG.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting Biodegradability in LogHalfLife... \n\n			============================================================== \n\n');
                end
                
                Xtest=Xin(:,train.BIODEG.Desc_i);

                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some Biodeg descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed Biodeg calculations. Check input and repeat calculation.'];
                end
                %%%
                
                pred = nnrpred(Xtest,train.BIODEG.model.set.train,train.BIODEG.model.set.y,train.BIODEG.model.set.K,train.BIODEG.model.set.dist_type,train.BIODEG.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.BioDeg_exp=NaN(size(Xtest,1),1);
                end
                res.BioDeg_LogHalfLife_pred(:,1)=round(pred.y_pred_weighted,2);
                res.BioDeg_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.BIODEG.model.set.train,Xtest,'auto');
                res.AD_BioDeg=abs(AD.inorout-1)';
                res.AD_BioDeg(round(pred.dc(:,1),3)==0)=1;
                
                res.AD_index_BioDeg=zeros(size(Xtest,1),1);
                res.Conf_index_BioDeg=zeros(size(Xtest,1),1);
                if neighbors
                    BioDeg_CAS_neighbor=cell(size(Xtest,1),5);
                    BioDeg_InChiKey_neighbor=cell(size(Xtest,1),5);
                    BioDeg_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %BioDeg_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                BioDeg_LogHalfLife_Exp_neighbor=nan(size(Xtest,1),5);
                BioDeg_LogHalfLife_pred_neighbor=nan(size(Xtest,1),5);
                BIODEG_CAS=strrep(strrep(join(train.BIODEG.CAS,'|',2),'|||',''),'||','');
                BIODEG_DTXSID=strrep(strrep(join(train.BIODEG.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.BIODEG.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.BIODEG.DTXSID);
                        end
                        if Li
                            if Lo>size(train.BIODEG.DTXSID,1)
                                Lo=mod(Lo,size(train.BIODEG.DTXSID,1));
                            end
                            res.BioDeg_exp(i)=round(train.BIODEG.model.set.y(Lo),2);
                        end
                    end
                    
                    BioDeg_LogHalfLife_Exp_neighbor(i,:)=round(train.BIODEG.model.set.y(pred.neighbors(i,:)),2);
                    BioDeg_LogHalfLife_pred_neighbor(i,:)=round(train.BIODEG.model.yc_weighted(pred.neighbors(i,:)),2);
                    
                    %                 rmse=calc_reg_param(res.BioDeg_LogHalfLife_Exp_neighbor(i,:),res.BioDeg_LogHalfLife_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_BioDeg(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_BioDeg(i,1)=((1/(1+sqrt(((BioDeg_LogHalfLife_Exp_neighbor(i,:)-BioDeg_LogHalfLife_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_BioDeg(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_BioDeg(i,1)=1;
                        res.AD_index_BioDeg(i,1)=1;
                    end
                    
                    SBioDeg=std(BioDeg_LogHalfLife_Exp_neighbor(i,:),pred.w(i,:));
                    res.BioDeg_predRange{i,1}=strcat('[', num2str(round(max(res.BioDeg_LogHalfLife_pred(i,1)-SBioDeg,min(BioDeg_LogHalfLife_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.BioDeg_LogHalfLife_pred(i,1)+SBioDeg,max(BioDeg_LogHalfLife_Exp_neighbor(i,:))),2)),']');
                    
                    Std_index_BioDeg=(1-(std(BioDeg_LogHalfLife_Exp_neighbor(i,:),pred.w(i,:))/std(train.BIODEG.model.set.y)));
                    res.Conf_index_BioDeg(i,1)=max(((1/(1+sqrt(((BioDeg_LogHalfLife_Exp_neighbor(i,:)-BioDeg_LogHalfLife_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_BioDeg(i,1)+Std_index_BioDeg)/3,0.1);
                    
                    if res.AD_index_BioDeg(i,1)>=0.6 && res.Conf_index_BioDeg(i,1)>=0.5
                        res.AD_BioDeg(i,1)=1;
                    elseif res.AD_index_BioDeg(i,1)<0.2 && res.Conf_index_BioDeg(i,1)<0.5
                        res.AD_BioDeg(i,1)=0;
                    end
                    if res.AD_index_BioDeg(i,1)==0
                        res.Conf_index_BioDeg(i,1)=0;
                    end
                    if isnan(res.AD_BioDeg(i,1))
                        res.AD_BioDeg(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.BioDeg_LogHalfLife_pred(i,1))
                        res.BioDeg_LogHalfLife_pred(i,1)=NaN;
                        res.BioDeg_predRange{i,1}='NA';
                        res.AD_BioDeg(i)=0;
                        res.AD_index_BioDeg(i)=0;
                        res.Conf_index_BioDeg(i,1)=0;
                    end
                    if Xin(i,12)==0
                        res.AD_BioDeg(i)=0;
                        res.AD_index_BioDeg(i)=res.AD_index_BioDeg(i)/2;
                        res.Conf_index_BioDeg(i,1)=res.Conf_index_BioDeg(i,1)/2;
                    end

                    res.AD_index_BioDeg(i,1)=round(res.AD_index_BioDeg(i,1),2);
                    res.Conf_index_BioDeg(i,1)=round(res.Conf_index_BioDeg(i,1),2);

                    if neighbors==1
                        %                         train.BIODEG.CAS=strrep(strrep(join(train.BIODEG.CAS,'|',2),'|||',''),'||','');
                        %                         train.BIODEG.DTXSID=strrep(strrep(join(train.BIODEG.DTXSID,'|',2),'|||',''),'||','');
                        BioDeg_CAS_neighbor(i,:)=BIODEG_CAS(pred.neighbors(i,:));
                        BioDeg_InChiKey_neighbor(i,:)=train.BIODEG.InChiKey(pred.neighbors(i,:));
                        BioDeg_DTXSID_neighbor(i,:)=BIODEG_DTXSID(pred.neighbors(i,:));
                        %BioDeg_DSSTOXMPID_neighbor(i,:)=train.BIODEG.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_BioDeg(i)~=0
                            res.BioDeg_CAS_neighbor(i,:)=BioDeg_CAS_neighbor(i,:);
                            res.BioDeg_InChiKey_neighbor(i,:)=BioDeg_InChiKey_neighbor(i,:);
                            res.BioDeg_DTXSID_neighbor(i,:)=BioDeg_DTXSID_neighbor(i,:);
                            %res.BioDeg_DSSTOXMPID_neighbor(i,:)=BioDeg_DSSTOXMPID_neighbor(i,:);
                            res.BioDeg_LogHalfLife_Exp_neighbor(i,:)=BioDeg_LogHalfLife_Exp_neighbor(i,:);
                            res.BioDeg_LogHalfLife_pred_neighbor(i,:)=BioDeg_LogHalfLife_pred_neighbor(i,:);
                        else
                            res.BioDeg_CAS_neighbor(i,:)=cell(1,5);
                            res.BioDeg_InChiKey_neighbor(i,:)=cell(1,5);
                            res.BioDeg_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.BioDeg_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.BioDeg_LogHalfLife_Exp_neighbor(i,:)=nan(1,5);
                            res.BioDeg_LogHalfLife_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'BioDeg_LogHalfLife experimental= %.3f\n', res.BioDeg_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'BioDeg_LogHalfLife predicted= %.3f\n', res.BioDeg_LogHalfLife_pred(i));
                        if res.AD_BioDeg(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_BioDeg(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_BioDeg(i));
                        %CAS=strjoin(res.BioDeg_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.BIODEG.model.set.K, res.BioDeg_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.BIODEG.model.set.K, res.BioDeg_LogHalfLife_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.BIODEG.model.set.K, res.BioDeg_LogHalfLife_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'BioDeg_LogHalfLife experimental= %.3f\n', res.BioDeg_exp(i));
                        end
                        fprintf(output,'BioDeg_LogHalfLife predicted= %.3f\n', res.BioDeg_LogHalfLife_pred(i));
                        if res.AD_BioDeg(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_BioDeg(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_BioDeg(i));
                        %CAS=strjoin(res.BioDeg_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.BIODEG.model.set.K, res.BioDeg_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.BIODEG.model.set.K, res.BioDeg_LogHalfLife_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.BIODEG.model.set.K, res.BioDeg_LogHalfLife_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.BioDeg=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'BIODEG');
                clear('BIODEG_CAS');
                clear('BIODEG_DTXSID');
                %end clean memory
            end
            %Predict RBiodeg values
            %case {'biowin','rb','readybiodeg','rbiodeg'}
            [Lia,Locb] =ismember({'biowin','rb','readybiodeg','rbiodeg'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting Ready-Biodegradability (Binary 0/1)';
                
                Desc=train.RBIODEG.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting Ready Biodegradability... \n\n			============================================================== \n\n');
                end
                
                Xtest=Xin(:,train.RBIODEG.Desc_i);

                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some RBiodeg descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed RBiodeg calculations. Check input and repeat calculation.'];
                end
                %%%
                
                pred = knnpred(Xtest,train.RBIODEG.model.set.train,train.RBIODEG.model.set.class,train.RBIODEG.model.set.K,train.RBIODEG.model.set.dist_type,train.RBIODEG.model.set.param.pret_type);
                pred.D=diag(pred.D);
                %pred.w = (ones(1,train.RBIODEG.model.set.K)./train.RBIODEG.model.set.K)';
                
                res.MoleculeID=MoleculeNames;
                if exp
                    res.ReadyBiodeg_exp=NaN(size(Xtest,1),1);
                end
                res.ReadyBiodeg_pred(:,1)=pred.class_pred-1;
                AD=classical_leverage(train.RBIODEG.model.set.train,Xtest,'auto');
                res.AD_ReadyBiodeg=abs(AD.inorout-1)';
                res.AD_ReadyBiodeg(round(pred.dc(:,1),3)==0)=1;
                %
                
                %res.dc=pred.dc;
                res.AD_index_ReadyBiodeg=1./(1+nanmedian(pred.dc,2));
                res.AD_index_ReadyBiodeg(isnan(res.AD_index_ReadyBiodeg))=0;
                
                res.Conf_index_ReadyBiodeg=zeros(size(Xtest,1),1);
                if neighbors
                    ReadyBiodeg_CAS_neighbor=cell(size(Xtest,1),5);
                    ReadyBiodeg_InChiKey_neighbor=cell(size(Xtest,1),5);
                    ReadyBiodeg_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %ReadyBiodeg_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                ReadyBiodeg_Exp_neighbor=nan(size(Xtest,1),5);
                ReadyBiodeg_pred_neighbor=nan(size(Xtest,1),5);
                RBIODEG_CAS=strrep(strrep(join(train.RBIODEG.CAS,'|',2),'|||',''),'||','');
                RBIODEG_DTXSID=strrep(strrep(join(train.RBIODEG.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.RBIODEG.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.RBIODEG.DTXSID);
                        end
                        if Li
                            if Lo>size(train.RBIODEG.DTXSID,1)
                                Lo=mod(Lo,size(train.RBIODEG.DTXSID,1));
                            end
                            res.ReadyBiodeg_exp(i)=train.RBIODEG.model.set.class(Lo);
                        end
                    end
                    
                    ReadyBiodeg_Exp_neighbor(i,:)=train.RBIODEG.model.set.class(pred.neighbors(i,:))-1;
                    ReadyBiodeg_pred_neighbor(i,:)=train.RBIODEG.model.class_calc(pred.neighbors(i,:))-1;
                    
                    rmse=calc_reg_param(ReadyBiodeg_Exp_neighbor(i,:),ReadyBiodeg_pred_neighbor(i,:));
                    res.Conf_index_ReadyBiodeg(i,1)=((1/(1+rmse.RMSEC))+res.AD_index_ReadyBiodeg(i))/2;
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.ReadyBiodeg_pred(i,1))
                        res.ReadyBiodeg_pred(i,1)=NaN;
                        res.AD_ReadyBiodeg(i)=0;
                        res.AD_index_ReadyBiodeg(i)=0;
                        res.Conf_index_ReadyBiodeg(i,1)=0;
                    end
                    if Xin(i,12)==0
                        res.AD_ReadyBiodeg(i)=0;
                        res.AD_index_ReadyBiodeg(i)=res.AD_index_ReadyBiodeg(i)/2;
                        res.Conf_index_ReadyBiodeg(i,1)=res.Conf_index_ReadyBiodeg(i,1)/2;
                    end
                    
                    %                res.Conf_index(i,1)=1/(1+sqrt(((res.ReadyBiodeg_Exp_neighbor(i,:)-res.ReadyBiodeg_pred_neighbor(i,:)).^2)*pred.w(i,:)'));
                    if neighbors==1
                        %                         train.RBIODEG.CAS=strrep(strrep(join(train.RBIODEG.CAS,'|',2),'|||',''),'||','');
                        %                         train.RBIODEG.DTXSID=strrep(strrep(join(train.RBIODEG.DTXSID,'|',2),'|||',''),'||','');
                        ReadyBiodeg_CAS_neighbor(i,:)=RBIODEG_CAS(pred.neighbors(i,:));
                        ReadyBiodeg_InChiKey_neighbor(i,:)=train.RBIODEG.InChiKey(pred.neighbors(i,:));
                        ReadyBiodeg_DTXSID_neighbor(i,:)=RBIODEG_DTXSID(pred.neighbors(i,:));
                        %ReadyBiodeg_DSSTOXMPID_neighbor(i,:)=train.RBIODEG.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_ReadyBiodeg(i)~=0
                            res.ReadyBiodeg_CAS_neighbor(i,:)=ReadyBiodeg_CAS_neighbor(i,:);
                            res.ReadyBiodeg_InChiKey_neighbor(i,:)=ReadyBiodeg_InChiKey_neighbor(i,:);
                            res.ReadyBiodeg_DTXSID_neighbor(i,:)=ReadyBiodeg_DTXSID_neighbor(i,:);
                            %res.ReadyBiodeg_DSSTOXMPID_neighbor(i,:)=ReadyBiodeg_DSSTOXMPID_neighbor(i,:);
                            res.ReadyBiodeg_Exp_neighbor(i,:)=ReadyBiodeg_Exp_neighbor(i,:);
                            res.ReadyBiodeg_pred_neighbor(i,:)=ReadyBiodeg_pred_neighbor(i,:);
                        else
                            res.ReadyBiodeg_CAS_neighbor(i,:)=cell(1,5);
                            res.ReadyBiodeg_InChiKey_neighbor(i,:)=cell(1,5);
                            res.ReadyBiodeg_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.ReadyBiodeg_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.ReadyBiodeg_Exp_neighbor(i,:)=nan(1,5);
                            res.ReadyBiodeg_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'ReadyBiodeg experimental= %.3f\n', res.ReadyBiodeg_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'ReadyBiodeg predicted= %d\n', res.ReadyBiodeg_pred(i));
                        if res.AD_ReadyBiodeg(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_ReadyBiodeg(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_ReadyBiodeg(i));
                        %CAS=strjoin(res.ReadyBiodeg_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.RBIODEG.model.set.K, res.ReadyBiodeg_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15d,%15d,%15d,%15d,%15d\n',train.RBIODEG.model.set.K, res.ReadyBiodeg_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14d,%15d,%15d,%15d,%15d\n\n',train.RBIODEG.model.set.K, res.ReadyBiodeg_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'ReadyBiodeg experimental= %.3f\n', res.ReadyBiodeg_exp(i));
                        end
                        fprintf(output,'ReadyBiodeg predicted= %d\n', res.ReadyBiodeg_pred(i));
                        if res.AD_ReadyBiodeg(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_ReadyBiodeg(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_ReadyBiodeg(i));
                        %CAS=strjoin(res.ReadyBiodeg_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.RBIODEG.model.set.K, res.ReadyBiodeg_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15d,%15d,%15d,%15d,%15d\n',train.RBIODEG.model.set.K, res.ReadyBiodeg_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14d,%15d,%15d,%15d,%15d\n\n',train.RBIODEG.model.set.K, res.ReadyBiodeg_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    
                    
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.RBioDeg=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'RBIODEG');
                clear('RBIODEG_CAS');
                clear('RBIODEG_DTXSID');
                %end clean memory
                
            end
            %Predict KM values
            %case {'km','logkm'}
            [Lia,Locb] =ismember({'km','logkm'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting LogKm half-life values (Log10 days)';
                Desc=train.KM.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogKmHL values... \n\n			==============================================================  \n\n');
                end
                
                Xtest=Xin(:,train.KM.Desc_i);
                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some KM descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed KM calculations. Check input and repeat calculation.'];
                end
                %%%
                
                pred = nnrpred(Xtest,train.KM.model.set.train,train.KM.model.set.y,train.KM.model.set.K,train.KM.model.set.dist_type,train.KM.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogKM_exp=NaN(size(Xtest,1),1);
                end
                res.LogKM_pred(:,1)=round(pred.y_pred_weighted,2);
                res.KM_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.KM.model.set.train,Xtest,'auto');
                res.AD_KM=abs(AD.inorout-1)';
                res.AD_KM(round(pred.dc(:,1),3)==0)=1;
                
                res.AD_index_KM=zeros(size(Xtest,1),1);
                res.Conf_index_KM=zeros(size(Xtest,1),1);
                if neighbors
                    KM_CAS_neighbor=cell(size(Xtest,1),5);
                    KM_InChiKey_neighbor=cell(size(Xtest,1),5);
                    KM_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %KM_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                LogKM_Exp_neighbor=nan(size(Xtest,1),5);
                LogKM_pred_neighbor=nan(size(Xtest,1),5);
                KM_CAS=strrep(strrep(join(train.KM.CAS,'|',2),'|||',''),'||','');
                KM_DTXSID=strrep(strrep(join(train.KM.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.KM.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.KM.DTXSID);
                        end
                        if Li
                            if Lo>size(train.KM.DTXSID,1)
                                Lo=mod(Lo,size(train.KM.DTXSID,1));
                            end
                            res.LogKM_exp(i)=round(train.KM.model.set.y(Lo),2);
                        end
                    end
                    
                    LogKM_Exp_neighbor(i,:)=round(train.KM.model.set.y(pred.neighbors(i,:)),2);
                    LogKM_pred_neighbor(i,:)=round(train.KM.model.yc_weighted(pred.neighbors(i,:)),2);
                    
                    %                 rmse=calc_reg_param(res.LogKM_Exp_neighbor(i,:),res.LogKM_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_KM(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_KM(i,1)=((1/(1+sqrt(((LogKM_Exp_neighbor(i,:)-LogKM_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_KM(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_KM(i,1)=1;
                        res.AD_index_KM(i,1)=1;
                    end
                    
                    SKM=std(LogKM_Exp_neighbor(i,:),pred.w(i,:));
                    res.KM_predRange{i,1}=strcat('[', num2str(round(max(res.LogKM_pred(i,1)-SKM,min(LogKM_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.LogKM_pred(i,1)+SKM,max(LogKM_Exp_neighbor(i,:))),2)),']');
                    
                    Std_index_KM=(1-(std(LogKM_Exp_neighbor(i,:),pred.w(i,:))/std(train.KM.model.set.y)));
                    res.Conf_index_KM(i,1)=max(((1/(1+sqrt(((LogKM_Exp_neighbor(i,:)-LogKM_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_KM(i,1)+Std_index_KM)/3,0.1);
                    
                    if res.AD_index_KM(i,1)>=0.6 && res.Conf_index_KM(i,1)>=0.5
                        res.AD_KM(i,1)=1;
                    elseif res.AD_index_KM(i,1)<0.2 && res.Conf_index_KM(i,1)<0.5
                        res.AD_KM(i,1)=0;
                    end
                    if res.AD_index_KM(i,1)==0
                        res.Conf_index_KM(i,1)=0;
                    end
                    if isnan(res.AD_KM(i,1))
                        res.AD_KM(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.LogKM_pred(i,1))
                        res.LogKM_pred(i,1)=NaN;
                        res.KM_predRange{i,1}='NA';
                        res.AD_KM(i)=0;
                        res.AD_index_KM(i)=0;
                        res.Conf_index_KM(i,1)=0;
                    end
                    if Xin(i,12)==0
                        res.AD_KM(i)=0;
                        res.AD_index_KM(i)=res.AD_index_KM(i)/2;
                        res.Conf_index_KM(i,1)=res.Conf_index_KM(i,1)/2;
                    end
                    
                    res.AD_index_KM(i,1)=round(res.AD_index_KM(i,1),2);
                    res.Conf_index_KM(i,1)=round(res.Conf_index_KM(i,1),2);

                    if neighbors==1
                        %                         train.KM.CAS=strrep(strrep(join(train.KM.CAS,'|',2),'|||',''),'||','');
                        %                         train.KM.DTXSID=strrep(strrep(join(train.KM.DTXSID,'|',2),'|||',''),'||','');
                        KM_CAS_neighbor(i,:)=KM_CAS(pred.neighbors(i,:));
                        KM_InChiKey_neighbor(i,:)=train.KM.InChiKey(pred.neighbors(i,:));
                        KM_DTXSID_neighbor(i,:)=KM_DTXSID(pred.neighbors(i,:));
                        %KM_DSSTOXMPID_neighbor(i,:)=train.KM.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_KM(i)~=0
                            res.KM_CAS_neighbor(i,:)=KM_CAS_neighbor(i,:);
                            res.KM_InChiKey_neighbor(i,:)=KM_InChiKey_neighbor(i,:);
                            res.KM_DTXSID_neighbor(i,:)=KM_DTXSID_neighbor(i,:);
                            %res.KM_DSSTOXMPID_neighbor(i,:)=KM_DSSTOXMPID_neighbor(i,:);
                            res.LogKM_Exp_neighbor(i,:)=LogKM_Exp_neighbor(i,:);
                            res.LogKM_pred_neighbor(i,:)=LogKM_pred_neighbor(i,:);
                        else
                            res.KM_CAS_neighbor(i,:)=cell(1,5);
                            res.KM_InChiKey_neighbor(i,:)=cell(1,5);
                            res.KM_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.KM_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.LogKM_Exp_neighbor(i,:)=nan(1,5);
                            res.LogKM_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogKM experimental= %.3f\n', res.LogKM_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogKmHL predicted= %.3f\n', res.LogKM_pred(i));
                        if res.AD_KM(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_KM(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_KM(i));
                        %CAS=strjoin(res.KM_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.KM.model.set.K, res.KM_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.KM.model.set.K, res.LogKM_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.KM.model.set.K, res.LogKM_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogKM experimental= %.3f\n', res.LogKM_exp(i));
                        end
                        fprintf(output,'LogKmHL predicted= %.3f\n', res.LogKM_pred(i));
                        if res.AD_KM(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_KM(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_KM(i));
                        %CAS=strjoin(res.KM_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.KM.model.set.K, res.KM_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.KM.model.set.K, res.LogKM_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.KM.model.set.K, res.LogKM_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.KM=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'KM');
                clear('KM_CAS');
                clear('KM_DTXSID');
                %end clean memory
            end
            
            %Predict KOC values
            %case {'logkoc','koc'}
            [Lia,Locb] =ismember({'koc','logkoc'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting LogKoc values (Log10 L/Kg)';
                
                Desc=train.KOC.Desc;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting LogKoc values... \n\n			==============================================================  \n\n');
                end
                
                Xtest=Xin(:,train.KOC.Desc_i);
                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some KOC descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed KOC calculations. Check input and repeat calculation.'];
                end
                %%%
                
                pred = nnrpred(Xtest,train.KOC.model.set.train,train.KOC.model.set.y,train.KOC.model.set.K,train.KOC.model.set.dist_type,train.KOC.model.set.param.pret_type);
                pred.D=diag(pred.D);
                res.MoleculeID=MoleculeNames;
                if exp
                    res.LogKoc_exp=NaN(size(Xtest,1),1);
                end
                res.LogKoc_pred(:,1)=round(pred.y_pred_weighted,2);
                res.Koc_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.KOC.model.set.train,Xtest,'auto');
                res.AD_Koc=abs(AD.inorout-1)';
                res.AD_Koc(round(pred.dc(:,1),3)==0)=1;
                
                res.AD_index_Koc=zeros(size(Xtest,1),1);
                res.Conf_index_Koc=zeros(size(Xtest,1),1);
                if neighbors
                    Koc_CAS_neighbor=cell(size(Xtest,1),5);
                    Koc_InChiKey_neighbor=cell(size(Xtest,1),5);
                    Koc_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %Koc_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                LogKoc_Exp_neighbor=nan(size(Xtest,1),5);
                LogKoc_pred_neighbor=nan(size(Xtest,1),5);
                KOC_CAS=strrep(strrep(join(train.KOC.CAS,'|',2),'|||',''),'||','');
                KOC_DTXSID=strrep(strrep(join(train.KOC.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.KOC.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.KOC.DTXSID);
                        end
                        if Li
                            if Lo>size(train.KOC.DTXSID,1)
                                Lo=mod(Lo,size(train.KOC.DTXSID,1));
                            end
                            res.LogKoc_exp(i)=round(train.KOC.model.set.y(Lo),2);
                        end
                    end
                    
                    LogKoc_Exp_neighbor(i,:)=round(train.KOC.model.set.y(pred.neighbors(i,:)),2);
                    LogKoc_pred_neighbor(i,:)=round(train.KOC.model.yc_weighted(pred.neighbors(i,:)),2);
                    
                    %                 rmse=calc_reg_param(res.LogKoc_Exp_neighbor(i,:),res.LogKoc_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_Koc(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_Koc(i,1)=((1/(1+sqrt(((LogKoc_Exp_neighbor(i,:)-LogKoc_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_LogKoc(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_Koc(i,1)=1;
                        res.AD_index_Koc(i,1)=1;
                    end
                    
                    SKoc=std(LogKoc_Exp_neighbor(i,:),pred.w(i,:));
                    res.Koc_predRange{i,1}=strcat('[', num2str(round(max(res.LogKoc_pred(i,1)-SKoc,min(LogKoc_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.LogKoc_pred(i,1)+SKoc,max(LogKoc_Exp_neighbor(i,:))),2)),']');
                    
                    Std_index_Koc=(1-(std(LogKoc_Exp_neighbor(i,:),pred.w(i,:))/std(train.KOC.model.set.y)));
                    res.Conf_index_Koc(i,1)=max(((1/(1+sqrt(((LogKoc_Exp_neighbor(i,:)-LogKoc_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_Koc(i,1)+Std_index_Koc)/3,0.1);
                    
                    if res.AD_index_Koc(i,1)>=0.6 && res.Conf_index_Koc(i,1)>=0.5
                        res.AD_Koc(i,1)=1;
                    elseif res.AD_index_Koc(i,1)<0.2 && res.Conf_index_Koc(i,1)<0.5
                        res.AD_Koc(i,1)=0;
                    end
                    if res.AD_index_Koc(i,1)==0
                        res.Conf_index_Koc(i,1)=0;
                    end
                    if isnan(res.AD_Koc(i,1))
                        res.AD_Koc(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.LogKoc_pred(i,1))
                        res.LogKoc_pred(i,1)=NaN;
                        res.Koc_predRange{i,1}='NA';
                        res.AD_Koc(i)=0;
                        res.AD_index_Koc(i)=0;
                        res.Conf_index_Koc(i,1)=0;
                    end
                    if Xin(i,12)==0
                        res.AD_Koc(i)=0;
                        res.AD_index_Koc(i)=res.AD_index_Koc(i)/2;
                        res.Conf_index_Koc(i,1)=res.Conf_index_Koc(i,1)/2;
                    end
                    
                    res.AD_index_Koc(i,1)=round(res.AD_index_Koc(i,1),2);
                    res.Conf_index_Koc(i,1)=round(res.Conf_index_Koc(i,1),2);

                    if neighbors==1
                        %                         train.KOC.CAS=strrep(strrep(join(train.KOC.CAS,'|',2),'|||',''),'||','');
                        %                         train.KOC.DTXSID=strrep(strrep(join(train.KOC.DTXSID,'|',2),'|||',''),'||','');
                        Koc_CAS_neighbor(i,:)=KOC_CAS(pred.neighbors(i,:));
                        Koc_InChiKey_neighbor(i,:)=train.KOC.InChiKey(pred.neighbors(i,:));
                        Koc_DTXSID_neighbor(i,:)=KOC_DTXSID(pred.neighbors(i,:));
                        %Koc_DSSTOXMPID_neighbor(i,:)=train.KOC.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_Koc(i)~=0
                            res.Koc_CAS_neighbor(i,:)=Koc_CAS_neighbor(i,:);
                            res.Koc_InChiKey_neighbor(i,:)=Koc_InChiKey_neighbor(i,:);
                            res.Koc_DTXSID_neighbor(i,:)=Koc_DTXSID_neighbor(i,:);
                            %res.Koc_DSSTOXMPID_neighbor(i,:)=Koc_DSSTOXMPID_neighbor(i,:);
                            res.LogKoc_Exp_neighbor(i,:)=LogKoc_Exp_neighbor(i,:);
                            res.LogKoc_pred_neighbor(i,:)=LogKoc_pred_neighbor(i,:);
                        else
                            res.Koc_CAS_neighbor(i,:)=cell(1,5);
                            res.Koc_InChiKey_neighbor(i,:)=cell(1,5);
                            res.Koc_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.Koc_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.LogKoc_Exp_neighbor(i,:)=nan(1,5);
                            res.LogKoc_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'LogKOC experimental= %.3f\n', res.LogKoc_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'LogKOC predicted= %.3f\n', res.LogKoc_pred(i));
                        if res.AD_Koc(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_Koc(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_Koc(i));
                        %CAS=strjoin(res.Koc_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.KOC.model.set.K, res.Koc_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.KOC.model.set.K, res.LogKoc_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.KOC.model.set.K, res.LogKoc_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LogKOC experimental= %.3f\n', res.LogKoc_exp(i));
                        end
                        fprintf(output,'LogKOC predicted= %.3f\n', res.LogKoc_pred(i));
                        if res.AD_Koc(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_Koc(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_Koc(i));
                        %CAS=strjoin(res.Koc_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.KOC.model.set.K, res.Koc_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.KOC.model.set.K, res.LogKoc_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.KOC.model.set.K, res.LogKoc_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.KOC=res;
                    clear('res');
                end
                % Clean memory
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'KOC');
                clear('KOC_CAS');
                clear('KOC_DTXSID');
                %end clean memory
            end
            
            
            %------------- ADME Properties -----------------------%
            
            %Predict FUB values
            %case {'fub','fu'}
            [Lia,Locb] =ismember({'fu','fub'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting FUB values (fraction)';
                Desc=train.FUB.Desc;
                
                

                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting FUB values... \n\n			==============================================================  \n\n');
                end
                
                XinCDK_FUB=XinCDK(:,train.FUB.cdk_in);
                Xtest=[Xin(:,train.PadelVarIn(train.FUB.Padel_in)), XinCDK_FUB];
                
                Xtest=Xtest(:,train.FUB.Desc_i);
                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some FUB descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed FUB calculations. Check input and repeat calculation.'];
                end
                %%%
                
                pred = nnrpred(Xtest,train.FUB.model.set.train,train.FUB.model.set.y,train.FUB.model.set.K,train.FUB.model.set.dist_type,train.FUB.model.set.param.pret_type);
                pred.D=diag(pred.D);
                %pred.D=[];
                res.MoleculeID=MoleculeNames;
                if exp
                    res.FUB_exp=NaN(size(Xtest,1),1);
                end
                res.FUB_pred(:,1)=round(pred.y_pred_weighted,2);
                res.FUB_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.FUB.model.set.train,Xtest,'auto');
                res.AD_FUB=abs(AD.inorout-1)';
                res.AD_FUB(round(pred.dc(:,1),3)==0)=1;
                
                res.AD_index_FUB=zeros(size(Xtest,1),1);
                res.Conf_index_FUB=zeros(size(Xtest,1),1);
                if neighbors
                    FUB_CAS_neighbor=cell(size(Xtest,1),5);
                    FUB_InChiKey_neighbor=cell(size(Xtest,1),5);
                    FUB_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %FUB_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                FUB_Exp_neighbor=nan(size(Xtest,1),5);
                FUB_pred_neighbor=nan(size(Xtest,1),5);
                FUB_CAS=strrep(strrep(join(train.FUB.CAS,'|',2),'|||',''),'||','');
                FUB_DTXSID=strrep(strrep(join(train.FUB.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.FUB.CAS);
                        elseif regexp(MoleculeNames{i},'CHEMBL[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames(i),train.FUB.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.FUB.DTXSID);
                        end
                        if Li
                            if Lo>size(train.FUB.DTXSID,1)
                                Lo=mod(Lo,size(train.FUB.DTXSID,1));
                            end
                            res.FUB_exp(i)=round(train.FUB.model.set.y(Lo),2);
                        end
                    end
                    
                    FUB_Exp_neighbor(i,:)=round(train.FUB.model.set.y(pred.neighbors(i,:)),2);
                    FUB_pred_neighbor(i,:)=round(train.FUB.model.yc_weighted(pred.neighbors(i,:)),2);
                    
                    %                 rmse=calc_reg_param(res.FUB_Exp_neighbor(i,:),res.FUB_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_FUB(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_FUB(i,1)=((1/(1+sqrt(((FUB_Exp_neighbor(i,:)-FUB_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_FUB(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_FUB(i,1)=1;
                        res.AD_index_FUB(i,1)=1;
                    end
                    
                    SFUB=std(FUB_Exp_neighbor(i,:),pred.w(i,:));
                    res.FUB_predRange{i,1}=strcat('[', num2str(round(max(res.FUB_pred(i,1)-SFUB,min(FUB_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.FUB_pred(i,1)+SFUB,max(FUB_Exp_neighbor(i,:))),2)),']');
                    
                    Std_index_FUB=(1-(std(FUB_Exp_neighbor(i,:),pred.w(i,:))/std(train.FUB.model.set.y)));
                    res.Conf_index_FUB(i,1)=max(((1/(1+sqrt(((FUB_Exp_neighbor(i,:)-FUB_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_FUB(i,1)+Std_index_FUB)/3,0.1);
                    
                    if res.AD_index_FUB(i,1)>=0.6 && res.Conf_index_FUB(i,1)>=0.5
                        res.AD_FUB(i,1)=1;
                    elseif res.AD_index_FUB(i,1)<0.2 && res.Conf_index_FUB(i,1)<0.5
                        res.AD_FUB(i,1)=0;
                    end
                    if res.AD_index_FUB(i,1)==0
                        res.Conf_index_FUB(i,1)=0;
                    end
                    if isnan(res.AD_FUB(i,1))
                        res.AD_FUB(i,1)=0;
                    end

                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.FUB_pred(i,1))
                        res.FUB_pred(i,1)=NaN;
                        res.FUB_predRange{i,1}='NA';
                        res.AD_FUB(i)=0;
                        res.AD_index_FUB(i)=0;
                        res.Conf_index_FUB(i,1)=0;
                    end
                    if Xin(i,12)==0
                        res.AD_FUB(i)=0;
                        res.AD_index_FUB(i)=res.AD_index_FUB(i)/2;
                        res.Conf_index_FUB(i,1)=res.Conf_index_FUB(i,1)/2;
                    end

                    res.AD_index_FUB(i,1)=round(res.AD_index_FUB(i,1),2);
                    res.Conf_index_FUB(i,1)=round(res.Conf_index_FUB(i,1),2);
                    
                    if neighbors==1
                        %                         train.FUB.CAS=strrep(strrep(join(train.FUB.CAS,'|',2),'|||',''),'||','');
                        %                         train.FUB.DTXSID=strrep(strrep(join(train.FUB.DTXSID,'|',2),'|||',''),'||','');
                        FUB_CAS_neighbor(i,:)=FUB_CAS(pred.neighbors(i,:));
                        FUB_InChiKey_neighbor(i,:)=train.FUB.InChiKey(pred.neighbors(i,:));
                        FUB_DTXSID_neighbor(i,:)=FUB_DTXSID(pred.neighbors(i,:));
                        %FUB_DSSTOXMPID_neighbor(i,:)=train.FUB.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_FUB(i)~=0
                            res.FUB_CAS_neighbor(i,:)=FUB_CAS_neighbor(i,:);
                            res.FUB_InChiKey_neighbor(i,:)=FUB_InChiKey_neighbor(i,:);
                            res.FUB_DTXSID_neighbor(i,:)=FUB_DTXSID_neighbor(i,:);
                            %res.FUB_DSSTOXMPID_neighbor(i,:)=FUB_DSSTOXMPID_neighbor(i,:);
                            res.FUB_Exp_neighbor(i,:)=FUB_Exp_neighbor(i,:);
                            res.FUB_pred_neighbor(i,:)=FUB_pred_neighbor(i,:);
                        else
                            res.FUB_CAS_neighbor(i,:)=cell(1,5);
                            res.FUB_InChiKey_neighbor(i,:)=cell(1,5);
                            res.FUB_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.FUB_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.FUB_Exp_neighbor(i,:)=nan(1,5);
                            res.FUB_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'FUB experimental= %.3f\n', res.FUB_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'FUB predicted= %.3f\n', res.FUB_pred(i));
                        if res.AD_FUB(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_FUB(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_FUB(i));
                        %CAS=strjoin(res.FUB_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.FUB.model.set.K, res.FUB_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.FUB.model.set.K, res.FUB_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.FUB.model.set.K, res.FUB_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'FUB experimental= %.3f\n', res.FUB_exp(i));
                        end
                        fprintf(output,'FUB predicted= %.3f\n', res.FUB_pred(i));
                        if res.AD_FUB(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_FUB(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_FUB(i));
                        %CAS=strjoin(res.FUB_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.FUB.model.set.K, res.FUB_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.FUB.model.set.K, res.FUB_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.FUB.model.set.K, res.FUB_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.FUB=res;
                    clear('res');
                end
                % Clean memory
                clear('XinCDK_FUB');
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'FUB');
                clear('FUB_CAS');
                clear('FUB_DTXSID');
                %end clean memory
            end
            
            %Predict Clint values
            %case {'clint','cl'}
            [Lia,Locb] =ismember({'cl','clint'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting Clint values';
                Desc=train.CLINT.Descr;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting Clint values... \n\n			==============================================================  \n\n');
                end
                
                XinCDK_Clint=XinCDK(:,train.CLINT.cdk_in);
                Xtest=[Xin(:,train.PadelVarIn(train.CLINT.Padel_in)), XinCDK_Clint];
                Xtestc=Xtest(:,train.CLINT.Desc_ic);
                Xtest=Xtest(:,train.CLINT.Desc_ir);
                %%% Check for NaN
                idx = all(isnan([Xtest Xtestc]), 1);
                if any(idx)
                    msgbox('Some Clint descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan([Xtest Xtestc])))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed Clint calculations. Check input and repeat calculation.'];
                end
                %%%
                
                
                predc = knnpred(Xtestc,train.CLINT.modelc.set.train,train.CLINT.modelc.set.class,train.CLINT.modelc.set.K,train.CLINT.modelc.set.dist_type,train.CLINT.modelc.set.param.pret_type);
                predc.D=diag(predc.D);
                
                pred = nnrpred(Xtest,train.CLINT.model.set.train,train.CLINT.model.set.y,train.CLINT.model.set.K,train.CLINT.model.set.dist_type,train.CLINT.model.set.param.pret_type);
                pred.D=diag(pred.D);
                %pred.D=[];
                res.MoleculeID=MoleculeNames;
                if exp
                    res.Clint_exp=NaN(size(Xtest,1),1);
                end
                res.Clint_pred(find(predc.class_pred==2),1)=round(10.^pred.y_pred_weighted(find(predc.class_pred==2)),2);
                res.Clint_pred(find(predc.class_pred==1),1)=0;
                res.Clint_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.CLINT.model.set.train,Xtest,'auto');
                res.AD_Clint=abs(AD.inorout-1)';
                res.AD_Clint(round(pred.dc(:,1),3)==0)=1;
                
                res.AD_index_Clint=zeros(size(Xtest,1),1);
                res.Conf_index_Clint=zeros(size(Xtest,1),1);
                if neighbors
                    Clint_CAS_neighbor=cell(size(Xtest,1),5);
                    Clint_InChiKey_neighbor=cell(size(Xtest,1),5);
                    Clint_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %Clint_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                Clint_Exp_neighbor=nan(size(Xtest,1),5);
                Clint_pred_neighbor=nan(size(Xtest,1),5);
                
                 CLINT_CAS=strrep(strrep(join(train.CLINT.CAS,'|',2),'|||',''),'||','');
                 CLINT_DTXSID=strrep(strrep(join(train.CLINT.DTXSID,'|',2),'|||',''),'||','');

                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.CLINT.CAS);
                        elseif regexp(MoleculeNames{i},'CHEMBL[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames(i),train.CLINT.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.CLINT.DTXSID);
                        end
                        if Li
                            if Lo>size(train.CLINT.DTXSID,1)
                                Lo=mod(Lo,size(train.CLINT.DTXSID,1));
                            end
                            res.Clint_exp(i)=round(train.CLINT.modelc.y(Lo),2);
                        end
                    end
                    
                    if predc.class_pred(i)==1
                        pred.neighbors(i,:)=predc.neighbors(i,:);
                        pred.dc(i,:)=predc.dc(i,:);
                        pred.w(i,:)=predc.w(i,:);
                        CAS=CLINT_CAS;
                        DTXSID=CLINT_DTXSID;
                        InChiKey=train.CLINT.InChiKey;
                        Clint_Exp_neighbor(i,:)=round(train.CLINT.modelc.y(predc.neighbors(i,:)),2);
                        Clint_pred_neighbor(i,:)=round(train.CLINT.modelc.yc_weighted(predc.neighbors(i,:)),2);
                    else
                        pred.neighbors(i,:)=pred.neighbors(i,:);
                        pred.dc(i,:)=pred.dc(i,:);
                        pred.w(i,:)=pred.w(i,:);
                        Clint_Exp_neighbor(i,:)=round(10.^train.CLINT.model.set.y(pred.neighbors(i,:)),2);
                        Clint_pred_neighbor(i,:)=round(10.^train.CLINT.model.yc_weighted(pred.neighbors(i,:)),2);
                        CAS=CLINT_CAS([157:1010 1064:end],1);
                        DTXSID=CLINT_DTXSID([157:1010 1064:end],1);
                        InChiKey=train.CLINT.InChiKey([157:1010 1064:end],1);
                    end
                    %                     Clint_Exp_neighbor(i,:)=train.CLINT.model.set.y(pred.neighbors(i,:));
                    %                     Clint_pred_neighbor(i,:)=train.CLINT.model.yc_weighted(pred.neighbors(i,:));
                    
                    %                 rmse=calc_reg_param(res.Clint_Exp_neighbor(i,:),res.Clint_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_Clint(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_Clint(i,1)=((1/(1+sqrt(((Clint_Exp_neighbor(i,:)-Clint_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_Clint(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_Clint(i,1)=1;
                        res.AD_index_Clint(i,1)=1;
                    end
                    
                    SClint=std(Clint_Exp_neighbor(i,:),pred.w(i,:));
                    res.Clint_predRange{i,1}=strcat('[', num2str(round(max(res.Clint_pred(i,1)-SClint,min(Clint_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.Clint_pred(i,1)+SClint,max(Clint_Exp_neighbor(i,:))),2)),']');
                    Std_index_Clint=(1-(std(log10(Clint_Exp_neighbor(i,:)+1),pred.w(i,:))/std(train.CLINT.model.set.y)));
                    %             Std_index_Clint_2=(1-(std(Clint_Exp_neighbor(i,:),pred.w(i,:))/std(10.^CLINT.model.set.y)));
                    
                    res.Conf_index_Clint(i,1)=max(((1/(1+sqrt(((log10(Clint_Exp_neighbor(i,:)+1)-log10(Clint_pred_neighbor(i,:)+1)).^2)*pred.w(i,:)')))+res.AD_index_Clint(i,1)+Std_index_Clint)/3,0.1);
                    %             res.Conf_index_Clint_2(i,1)=((1/(1+sqrt(((Clint_Exp_neighbor(i,:)-Clint_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_Clint(i,1)+Std_index_Clint_2)/3;
                    
                    if res.AD_index_Clint(i,1)>=0.6 && res.Conf_index_Clint(i,1)>=0.5
                        res.AD_Clint(i,1)=1;
                    elseif res.AD_index_Clint(i,1)<0.2 && res.Conf_index_Clint(i,1)<0.5
                        res.AD_Clint(i,1)=0;
                    end
                    if res.AD_index_Clint(i,1)==0
                        res.Conf_index_Clint(i,1)=0;
                    end
                    if isnan(res.AD_Clint(i,1))
                        res.AD_Clint(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.Clint_pred(i,1))
                        res.Clint_pred(i,1)=NaN;
                        res.Clint_predRange{i,1}='NA';
                        res.AD_Clint(i)=0;
                        res.AD_index_Clint(i)=0;
                        res.Conf_index_Clint(i,1)=0;
                    end
                    if Xin(i,12)==0
                        res.AD_Clint(i)=0;
                        res.AD_index_Clint(i)=res.AD_index_Clint(i)/2;
                        res.Conf_index_Clint(i,1)=res.Conf_index_Clint(i,1)/2;
                    end
                    
                    res.AD_index_Clint(i,1)=round(res.AD_index_Clint(i,1),2);
                    res.Conf_index_Clint(i,1)=round(res.Conf_index_Clint(i,1),2);

                    if neighbors==1
                        %                         train.CLINT.CAS=strrep(strrep(join(train.CLINT.CAS,'|',2),'|||',''),'||','');
                        %                         train.CLINT.DTXSID=strrep(strrep(join(train.CLINT.DTXSID,'|',2),'|||',''),'||','');
                        Clint_CAS_neighbor(i,:)=CAS(pred.neighbors(i,:));
                        Clint_InChiKey_neighbor(i,:)=InChiKey(pred.neighbors(i,:));
                        Clint_DTXSID_neighbor(i,:)=DTXSID(pred.neighbors(i,:));
                        %Clint_DSSTOXMPID_neighbor(i,:)=train.CLINT.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_Clint(i)~=0
                            res.Clint_CAS_neighbor(i,:)=Clint_CAS_neighbor(i,:);
                            res.Clint_InChiKey_neighbor(i,:)=Clint_InChiKey_neighbor(i,:);
                            res.Clint_DTXSID_neighbor(i,:)=Clint_DTXSID_neighbor(i,:);
                            %res.Clint_DSSTOXMPID_neighbor(i,:)=Clint_DSSTOXMPID_neighbor(i,:);
                            res.Clint_Exp_neighbor(i,:)=Clint_Exp_neighbor(i,:);
                            res.Clint_pred_neighbor(i,:)=Clint_pred_neighbor(i,:);
                        else
                            res.Clint_CAS_neighbor(i,:)=cell(1,5);
                            res.Clint_InChiKey_neighbor(i,:)=cell(1,5);
                            res.Clint_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.Clint_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.Clint_Exp_neighbor(i,:)=nan(1,5);
                            res.Clint_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'Clint experimental= %.3f\n', res.Clint_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'Clint predicted= %.3f\n', res.Clint_pred(i));
                        if res.AD_Clint(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_Clint(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_Clint(i));
                        %CAS=strjoin(res.Clint_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CLINT.model.set.K, res.Clint_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.CLINT.model.set.K, res.Clint_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.CLINT.model.set.K, res.Clint_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'Clint experimental= %.3f\n', res.Clint_exp(i));
                        end
                        fprintf(output,'Clint predicted= %.3f\n', res.Clint_pred(i));
                        if res.AD_Clint(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_Clint(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_Clint(i));
                        %CAS=strjoin(res.Clint_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CLINT.model.set.K, res.Clint_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.CLINT.model.set.K, res.Clint_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.CLINT.model.set.K, res.Clint_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.Clint=res;
                    clear('res');
                end
                % Clean memory
                clear('XinCDK_Clint');
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'CLINT');
                clear('CAS');
                clear('DTXSID');
                clear('InChiKey');
                 clear('CLINT_CAS');
                 clear('CLINT_DTXSID');
                %end clean memory
            end
            
            %Predict CACO2 values
            %case {'CACO2','fu'}
            [Lia,Locb] =ismember({'caco2','logPapp'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting CACO2 values (logPapp)';
                Desc=train.CACO2.Desc;
                
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting CACO2 values... \n\n			==============================================================  \n\n');
                end
                
                XinCDK_CACO2=XinCDK(:,train.CACO2.cdk_in);
                Xtest=[Xin(:,train.PadelVarIn(train.CACO2.Padel_in)), XinCDK_CACO2];
                
                Xtest=Xtest(:,train.CACO2.Desc_i);
                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some CACO2 descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed CACO2 calculations. Check input and repeat calculation.'];
                end
                %%%
                
                pred = nnrpred(Xtest,train.CACO2.model.set.train,train.CACO2.model.set.y,train.CACO2.model.set.K,train.CACO2.model.set.dist_type,train.CACO2.model.set.param.pret_type);
                pred.D=diag(pred.D);
                %pred.D=[];
                res.MoleculeID=MoleculeNames;
                if exp
                    res.CACO2_exp=NaN(size(Xtest,1),1);
                end
                res.CACO2_pred(:,1)=round(pred.y_pred_weighted,2);
                res.CACO2_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.CACO2.model.set.train,Xtest,'auto');
                res.AD_CACO2=abs(AD.inorout-1)';
                res.AD_CACO2(round(pred.dc(:,1),3)==0)=1;
                
                res.AD_index_CACO2=zeros(size(Xtest,1),1);
                res.Conf_index_CACO2=zeros(size(Xtest,1),1);
                if neighbors
                    CACO2_CAS_neighbor=cell(size(Xtest,1),5);
                    CACO2_InChiKey_neighbor=cell(size(Xtest,1),5);
                    CACO2_DTXSID_neighbor=cell(size(Xtest,1),5);
                    %CACO2_DSSTOXMPID_neighbor=cell(size(Xtest,1),5);
                end
                CACO2_Exp_neighbor=nan(size(Xtest,1),5);
                CACO2_pred_neighbor=nan(size(Xtest,1),5);
                CACO2_CAS=strrep(strrep(join(train.CACO2.CAS,'|',2),'|||',''),'||','');
                CACO2_DTXSID=strrep(strrep(join(train.CACO2.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    Li=0;
                    if exp && ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li,Lo] = ismember(MoleculeNames(i),train.CACO2.CAS);
                        elseif regexp(MoleculeNames{i},'CHEMBL[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames(i),train.CACO2.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li,Lo] = ismember(MoleculeNames{i},train.CACO2.DTXSID);
                        end
                        if Li
                            if Lo>size(train.CACO2.DTXSID,1)
                                Lo=mod(Lo,size(train.CACO2.DTXSID,1));
                            end
                            res.CACO2_exp(i)=round(train.CACO2.model.set.y(Lo),2);
                        end
                    end
                    
                    CACO2_Exp_neighbor(i,:)=round(train.CACO2.model.set.y(pred.neighbors(i,:)),2);
                    CACO2_pred_neighbor(i,:)=round(train.CACO2.model.yc_weighted(pred.neighbors(i,:)),2);
                    
                    %                 rmse=calc_reg_param(res.CACO2_Exp_neighbor(i,:),res.CACO2_pred_neighbor(i,:));
                    %                 res.Conf_index(i,1)=1/(1+rmse.RMSEC);
                    
                    res.AD_index_CACO2(i,1)=1./(1+pred.dc(i,~isnan(pred.dc(i,:)))*pred.w(i,~isnan(pred.dc(i,:)))');
                    %                     res.Conf_index_CACO2(i,1)=((1/(1+sqrt(((CACO2_Exp_neighbor(i,:)-CACO2_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_CACO2(i,1))/2;
                    if Li || (pred.dc(i,1)==0 && pred.w(i,1)==1)
                        res.AD_CACO2(i,1)=1;
                        res.AD_index_CACO2(i,1)=1;
                    end
                    
                    SCACO2=std(CACO2_Exp_neighbor(i,:),pred.w(i,:));
                    res.CACO2_predRange{i,1}=strcat('[', num2str(round(max(res.CACO2_pred(i,1)-SCACO2,min(CACO2_Exp_neighbor(i,:))),2)),':',num2str(round(min(res.CACO2_pred(i,1)+SCACO2,max(CACO2_Exp_neighbor(i,:))),2)),']');
                    
                    Std_index_CACO2=(1-(std(CACO2_Exp_neighbor(i,:),pred.w(i,:))/std(train.CACO2.model.set.y)));
                    res.Conf_index_CACO2(i,1)=max(((1/(1+sqrt(((CACO2_Exp_neighbor(i,:)-CACO2_pred_neighbor(i,:)).^2)*pred.w(i,:)')))+res.AD_index_CACO2(i,1)+Std_index_CACO2)/3,0.1);
                    
                    if res.AD_index_CACO2(i,1)>=0.6 && res.Conf_index_CACO2(i,1)>=0.5
                        res.AD_CACO2(i,1)=1;
                    elseif res.AD_index_CACO2(i,1)<0.2 && res.Conf_index_CACO2(i,1)<0.5
                        res.AD_CACO2(i,1)=0;
                    end
                    if res.AD_index_CACO2(i,1)==0
                        res.Conf_index_CACO2(i,1)=0;
                    end
                    if isnan(res.AD_CACO2(i,1))
                        res.AD_CACO2(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(pred.dc(i,:)), 1)) || isnan(res.CACO2_pred(i,1))
                        res.CACO2_pred(i,1)=NaN;
                        res.CACO2_predRange{i,1}='NA';
                        res.AD_CACO2(i)=0;
                        res.AD_index_CACO2(i)=0;
                        res.Conf_index_CACO2(i,1)=0;
                    end
                    if Xin(i,12)==0
                        res.AD_CACO2(i)=0;
                        res.AD_index_CACO2(i)=res.AD_index_CACO2(i)/2;
                        res.Conf_index_CACO2(i,1)=res.Conf_index_CACO2(i,1)/2;
                    end

                    res.AD_index_CACO2(i,1)=round(res.AD_index_CACO2(i,1),2);
                    res.Conf_index_CACO2(i,1)=round(res.Conf_index_CACO2(i,1),2);

                    if neighbors==1
                        %                         train.CACO2.CAS=strrep(strrep(join(train.CACO2.CAS,'|',2),'|||',''),'||','');
                        %                         train.CACO2.DTXSID=strrep(strrep(join(train.CACO2.DTXSID,'|',2),'|||',''),'||','');
                        CACO2_CAS_neighbor(i,:)=CACO2_CAS(pred.neighbors(i,:));
                        CACO2_InChiKey_neighbor(i,:)=train.CACO2.InChiKey(pred.neighbors(i,:));
                        CACO2_DTXSID_neighbor(i,:)=CACO2_DTXSID(pred.neighbors(i,:));
                        %CACO2_DSSTOXMPID_neighbor(i,:)=train.CACO2.DSSTOXMPID(pred.neighbors(i,:));
                        if res.AD_index_CACO2(i)~=0
                            res.CACO2_CAS_neighbor(i,:)=CACO2_CAS_neighbor(i,:);
                            res.CACO2_InChiKey_neighbor(i,:)=CACO2_InChiKey_neighbor(i,:);
                            res.CACO2_DTXSID_neighbor(i,:)=CACO2_DTXSID_neighbor(i,:);
                            %res.CACO2_DSSTOXMPID_neighbor(i,:)=CACO2_DSSTOXMPID_neighbor(i,:);
                            res.CACO2_Exp_neighbor(i,:)=CACO2_Exp_neighbor(i,:);
                            res.CACO2_pred_neighbor(i,:)=CACO2_pred_neighbor(i,:);
                        else
                            res.CACO2_CAS_neighbor(i,:)=cell(1,5);
                            res.CACO2_InChiKey_neighbor(i,:)=cell(1,5);
                            res.CACO2_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.CACO2_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.CACO2_Exp_neighbor(i,:)=nan(1,5);
                            res.CACO2_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'CACO2 experimental= %.3f\n', res.CACO2_exp(i));
                        end
                        fprintf(output(Locb(find(Locb))),'CACO2 predicted= %.3f\n', res.CACO2_pred(i));
                        if res.AD_CACO2(i)==1
                            fprintf(output(Locb(find(Locb))),'AD: inside\n');
                        else
                            fprintf(output(Locb(find(Locb))),'AD: outside\n');
                        end
                        fprintf(output(Locb(find(Locb))),'AD_index= %.2f\n', res.AD_index_CACO2(i));
                        fprintf(output(Locb(find(Locb))),'Conf_index= %.2f\n', res.Conf_index_CACO2(i));
                        %CAS=strjoin(res.CACO2_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CACO2.model.set.K, res.CACO2_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.CACO2.model.set.K, res.CACO2_Exp_neighbor(i,1:5));
                            fprintf(output(Locb(find(Locb))),'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.CACO2.model.set.K, res.CACO2_pred_neighbor(i,1:5));
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        %res.Xtest=Xtest;
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'CACO2 experimental= %.3f\n', res.CACO2_exp(i));
                        end
                        fprintf(output,'CACO2 predicted= %.3f\n', res.CACO2_pred(i));
                        if res.AD_CACO2(i)==1
                            fprintf(output,'AD: inside\n');
                        else
                            fprintf(output,'AD: outside\n');
                        end
                        fprintf(output,'AD_index= %.2f\n', res.AD_index_CACO2(i));
                        fprintf(output,'Conf_index= %.2f\n', res.Conf_index_CACO2(i));
                        %CAS=strjoin(res.CACO2_CAS_neighbor(i,1:5),',\t');
                        %CAS=strrep([res.CAS_neighbors(i,1:5)],' ',', ');
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CACO2.model.set.K, res.CACO2_CAS_neighbor{i,1:5});
                            fprintf(output,'Exp of the %i nearest neighbors:%15.3f,%15.3f,%15.3f,%15.3f,%15.3f\n',train.CACO2.model.set.K, res.CACO2_Exp_neighbor(i,1:5));
                            fprintf(output,'Pred of the %i nearest neighbors:%14.3f,%15.3f,%15.3f,%15.3f,%15.3f\n\n',train.CACO2.model.set.K, res.CACO2_pred_neighbor(i,1:5));
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    Xtest(:,ismember(Desc,DescNames))=[];
                    
                    Desc(ismember(Desc,DescNames))=[];
                    
                    DescNames=[DescNames Desc];
                    
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.CACO2=res;
                    clear('res');
                end
                % Clean memory
                clear('XinCDK_CACO2');
                clear('Xtest');
                clear('pred');
                clear('AD');
                train = rmfield(train, 'CACO2');
                clear('CACO2_CAS');
                clear('CACO2_DTXSID');
                %end clean memory
            end
            
            % Tox properties
            
            %---------- Toxcity Endpoints ----------
            
            
            %Predict CERAPP endpoints
            %case {'CERAPP','ER'}
            [Lia,Locb] =ismember({'cerapp','er'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting Estrogen Receptor Activity (CERAPP)';
                Desc=train.CERAPP.DescIn;
                
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting CERAPP endpoints... \n\n			==============================================================  \n\n');
                end
                
                %XlabelsCDK
                XinCDK_CERAPP=XinCDK(:,train.CERAPP.cdk_in);
                Xtest=[Xin(:,train.PadelVarIn(train.CERAPP.Padel_in)), XinCDK_CERAPP];

                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some CERAPP descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed CERAPP calculations. Check input and repeat calculation.'];
                end
                %%%
                
                XtestAG=Xtest(:,train.CERAPP.model_AG.DescAG_i);
                XtestAN=Xtest(:,train.CERAPP.model_AN.DescAN_i);
                XtestBD=Xtest(:,train.CERAPP.model_BD.DescBD_i);
                
                predAG = knnpred2(XtestAG,train.CERAPP.model_AG.set.train,train.CERAPP.model_AG.set.class,train.CERAPP.model_AG.set.class_Exp_N,train.CERAPP.model_AG.set.K,train.CERAPP.model_AG.set.dist_type,train.CERAPP.model_AG.set.param.pret_type);
                %predAG.D=diag(predAG.D);
                predAG.D=[];
                predAN = knnpred2(XtestAN,train.CERAPP.model_AN.set.train,train.CERAPP.model_AN.set.class,train.CERAPP.model_AN.set.class_Exp_N,train.CERAPP.model_AN.set.K,train.CERAPP.model_AN.set.dist_type,train.CERAPP.model_AN.set.param.pret_type);
                %predAN.D=diag(predAN.D);
                predAN.D=[];
                predBD = knnpred2(XtestBD,train.CERAPP.model_BD.set.train,train.CERAPP.model_BD.set.class,train.CERAPP.model_BD.set.class_Exp_N,train.CERAPP.model_BD.set.K,train.CERAPP.model_BD.set.dist_type,train.CERAPP.model_BD.set.param.pret_type);
                %predBD.D=diag(predBD.D);
                predBD.D=[];
                
                res.MoleculeID=MoleculeNames;
                if exp
                    res.CERAPP_Ago_exp=cell(size(Xtest,1),1);
                end
                res.CERAPP_Ago_pred(:,1)=predAG.class_pred-1;
                AD=classical_leverage(train.CERAPP.model_AG.set.train,XtestAG,'auto');
                res.AD_CERAPP_Ago=abs(AD.inorout-1)';
                res.AD_index_CERAPP_Ago=zeros(size(XtestAG,1),1);
                %                 res.AD_index_CERAPP_Ago=1-test_pretreatment(predAG.dc(:,1),train.CERAPP.model_AG.set.dc_param);
                %                 res.AD_index_CERAPP_Ago(find(res.AD_index_CERAPP_Ago<0),1)=1./(1+predAG.dc(find(res.AD_index_CERAPP_Ago<0),1));
                res.AD_CERAPP_Ago(find(isnan(predAG.dc(:,1))))=0;
                res.CERAPP_Ago_pred(find(isnan(predAG.dc(:,1))))=NaN;
                %                 res.AD_index_CERAPP_Ago(find(isnan(predAG.dc(:,1))))=0;
                %                 res.AD_CERAPP_Ago(find(res.AD_index_CERAPP_Ago>0.5))=1;
                res.Conf_index_CERAPP_Ago=zeros(size(XtestAG,1),1);
                if exp
                    res.CERAPP_Anta_exp=cell(size(Xtest,1),1);
                end
                res.CERAPP_Anta_pred(:,1)=predAN.class_pred-1;
                AD=classical_leverage(train.CERAPP.model_AN.set.train,XtestAN,'auto');
                res.AD_CERAPP_Anta=abs(AD.inorout-1)';
                res.AD_index_CERAPP_Anta=zeros(size(XtestAN,1),1);
                %                 res.AD_index_CERAPP_Anta=1-test_pretreatment(predAN.dc(:,1),train.CERAPP.model_AN.set.dc_param);
                %                 res.AD_index_CERAPP_Anta(find(res.AD_index_CERAPP_Anta<0),1)=1./(1+predAN.dc(find(res.AD_index_CERAPP_Anta<0),1));
                res.CERAPP_Anta_pred(find(isnan(predAN.dc(:,1))))=NaN;
                res.AD_CERAPP_Anta(find(isnan(predAN.dc(:,1))))=0;
                %                 res.AD_index_CERAPP_Anta(find(isnan(predAN.dc(:,1))))=0;
                %                 res.AD_CERAPP_Anta(find(res.AD_index_CERAPP_Anta>0.5))=1;
                res.Conf_index_CERAPP_Anta=zeros(size(XtestAN,1),1);
                if exp
                    res.CERAPP_Bind_exp=cell(size(Xtest,1),1);
                end
                res.CERAPP_Bind_pred(:,1)=predBD.class_pred-1;
                AD=classical_leverage(train.CERAPP.model_BD.set.train,XtestBD,'auto');
                res.AD_CERAPP_Bind=abs(AD.inorout-1)';
                res.AD_index_CERAPP_Bind=zeros(size(XtestBD,1),1);
                %                 res.AD_index_CERAPP_Bind=1-test_pretreatment(predBD.dc(:,1),train.CERAPP.model_BD.set.dc_param);
                %                 res.AD_index_CERAPP_Bind(find(res.AD_index_CERAPP_Bind<0),1)=1./(1+predBD.dc(find(res.AD_index_CERAPP_Bind<0),1));
                res.CERAPP_Bind_pred(find(isnan(predBD.dc(:,1))))=NaN;
                res.AD_CERAPP_Bind(find(isnan(predBD.dc(:,1))))=0;
                %                 res.AD_index_CERAPP_Bind(find(isnan(predBD.dc(:,1))))=0;
                %                 res.AD_CERAPP_Bind(find(res.AD_index_CERAPP_Bind>0.5))=1;
                res.Conf_index_CERAPP_Bind=zeros(size(XtestBD,1),1);
                res.CERAPP_Bind_pred(find(res.CERAPP_Ago_pred(:,1)))=1;
                res.CERAPP_Bind_pred(find(res.CERAPP_Anta_pred(:,1)))=1;
                
                AG_CAS=strrep(strrep(join(train.CERAPP.model_AG.CAS,'|',2),'|||',''),'||','');
                AG_DTXSID=strrep(strrep(join(train.CERAPP.model_AG.DTXSID,'|',2),'|||',''),'||','');
                AN_CAS=strrep(strrep(join(train.CERAPP.model_AN.CAS,'|',2),'|||',''),'||','');
                AN_DTXSID=strrep(strrep(join(train.CERAPP.model_AN.DTXSID,'|',2),'|||',''),'||','');
                BD_CAS=strrep(strrep(join(train.CERAPP.model_BD.CAS,'|',2),'|||',''),'||','');
                BD_DTXSID=strrep(strrep(join(train.CERAPP.model_BD.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    res.AD_index_CERAPP_Ago(i,1)=1./(1+predAG.dc(i,~isnan(predAG.dc(i,:)))*predAG.w(i,~isnan(predAG.dc(i,:)))');
                    res.AD_index_CERAPP_Ago(i,1)=0.5*res.AD_index_CERAPP_Ago(i,1)+0.3*(~isnan(train.CERAPP.model_AG.set.class_Exp_N(predAG.neighbors(i,1),1)))+0.2*length(find(~isnan(train.CERAPP.model_AG.set.class_Exp_N(predAG.neighbors(i,:),1))))/5;
                    res.AD_index_CERAPP_Anta(i,1)=1./(1+predAN.dc(i,~isnan(predAN.dc(i,:)))*predAN.w(i,~isnan(predAN.dc(i,:)))');
                    res.AD_index_CERAPP_Anta(i,1)=0.5*res.AD_index_CERAPP_Anta(i,1)+0.3*(~isnan(train.CERAPP.model_AN.set.class_Exp_N(predAN.neighbors(i,1),1)))+0.2*length(find(~isnan(train.CERAPP.model_AN.set.class_Exp_N(predAG.neighbors(i,:),1))))/5;
                    res.AD_index_CERAPP_Bind(i,1)=1./(1+predBD.dc(i,~isnan(predBD.dc(i,:)))*predBD.w(i,~isnan(predBD.dc(i,:)))');
                    res.AD_index_CERAPP_Bind(i,1)=0.5*res.AD_index_CERAPP_Bind(i,1)+0.3*(~isnan(train.CERAPP.model_BD.set.class_Exp_N(predBD.neighbors(i,1),1)))+0.2*length(find(~isnan(train.CERAPP.model_BD.set.class_Exp_N(predBD.neighbors(i,:),1))))/5;
                    
                    Li_ag=0;
                    Li_an=0;
                    Li_bd=0;
                    if ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li_ag,Lo_ag] = ismember(MoleculeNames(i),train.CERAPP.model_AG.CAS);
                            [Li_an,Lo_an] = ismember(MoleculeNames(i),train.CERAPP.model_AN.CAS);
                            [Li_bd,Lo_bd] = ismember(MoleculeNames(i),train.CERAPP.model_BD.CAS);
                            if Li_ag
                                if Lo_ag>size(train.CERAPP.model_AG.CAS,1)
                                    Lo_ag=mod(Lo_ag,size(train.CERAPP.model_AG.CAS,1));
                                end
                                res.CERAPP_Ago_pred(i,1)=train.CERAPP.model_AG.set.class(Lo_ag)-1;
                                res.AD_CERAPP_Ago(i,1)=1;
                                res.AD_index_CERAPP_Ago(i,1)=1;
                                if exp
                                    res.CERAPP_Ago_exp(i,1)=train.CERAPP.model_AG.set.class_Exp(Lo_ag);
                                end
                            else
                                if exp
                                    res.CERAPP_Ago_exp(i,1)={'NA'};
                                end
                            end
                            if Li_an
                                if Lo_an>size(train.CERAPP.model_AN.CAS,1)
                                    Lo_an=mod(Lo_an,size(train.CERAPP.model_AN.CAS,1));
                                end
                                res.CERAPP_Anta_pred(i,1)=train.CERAPP.model_AN.set.class(Lo_an)-1;
                                res.AD_CERAPP_Anta(i,1)=1;
                                res.AD_index_CERAPP_Anta(i,1)=1;
                                if exp
                                    res.CERAPP_Anta_exp(i,1)=train.CERAPP.model_AN.set.class_Exp(Lo_an);
                                end
                            else
                                if exp
                                    res.CERAPP_Anta_exp(i,1)={'NA'};
                                end
                            end
                            if Li_bd
                                if Lo_bd>size(train.CERAPP.model_BD.CAS,1)
                                    Lo_bd=mod(Lo_bd,size(train.CERAPP.model_BD.CAS,1));
                                end
                                res.CERAPP_Bind_pred(i,1)=train.CERAPP.model_BD.set.class(Lo_bd)-1;
                                res.AD_CERAPP_Bind(i,1)=1;
                                res.AD_index_CERAPP_Bind(i,1)=1;
                                if exp
                                    res.CERAPP_Bind_exp(i,1)=train.CERAPP.model_BD.set.class_Exp(Lo_bd);
                                end
                            else
                                if exp
                                    res.CERAPP_Bind_exp(i,1)={'NA'};
                                end
                            end
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li_ag,Lo_ag] = ismember(MoleculeNames{i},train.CERAPP.model_AG.DTXSID);
                            [Li_an,Lo_an] = ismember(MoleculeNames{i},train.CERAPP.model_AN.DTXSID);
                            [Li_bd,Lo_bd] = ismember(MoleculeNames{i},train.CERAPP.model_BD.DTXSID);
                            
                            if Li_ag
                                if Lo_ag>size(train.CERAPP.model_AG.DTXSID,1)
                                    Lo_ag=mod(Lo_ag,size(train.CERAPP.model_AG.DTXSID,1));
                                end
                                res.CERAPP_Ago_pred(i,1)=train. CERAPP.model_AG.set.class(Lo_ag)-1;
                                res.AD_CERAPP_Ago(i,1)=1;
                                res.AD_index_CERAPP_Ago(i,1)=1;
                                if exp
                                    res.CERAPP_Ago_exp(i,1)=train.CERAPP.model_AG.set.class_Exp(Lo_ag);
                                end
                            else
                                if exp
                                    res.CERAPP_Ago_exp(i,1)={'NA'};
                                end
                            end
                            if Li_an
                                if Lo_an>size(train.CERAPP.model_AN.DTXSID,1)
                                    Lo_an=mod(Lo_an,size(train.CERAPP.model_AN.DTXSID,1));
                                end
                                res.CERAPP_Anta_pred(i,1)=train.CERAPP.model_AN.set.class(Lo_an)-1;
                                res.AD_CERAPP_Anta(i,1)=1;
                                res.AD_index_CERAPP_Anta(i,1)=1;
                                if exp
                                    res.CERAPP_Anta_exp(i,1)=train.CERAPP.model_AN.set.class_Exp(Lo_an);
                                end
                            else
                                if exp
                                    res.CERAPP_Anta_exp(i,1)={'NA'};
                                end
                            end
                            if Li_bd
                                if Lo_bd>size(train.CERAPP.model_BD.DTXSID,1)
                                    Lo_bd=mod(Lo_bd,size(train.CERAPP.model_BD.DTXSID,1));
                                end
                                res.CERAPP_Bind_pred(i,1)=train.CERAPP.model_BD.set.class(Lo_bd)-1;
                                res.AD_CERAPP_Bind(i,1)=1;
                                res.AD_index_CERAPP_Bind(i,1)=1;
                                if exp
                                    res.CERAPP_Bind_exp(i,1)=train.CERAPP.model_BD.set.class_Exp(Lo_bd);
                                end
                            else
                                if exp
                                    res.CERAPP_Bind_exp(i,1)={'NA'};
                                end
                            end
                        else
                            if exp
                                res.CERAPP_Ago_exp(i,1)={'NA'};
                                res.CERAPP_Anta_exp(i,1)={'NA'};
                                res.CERAPP_Bind_exp(i,1)={'NA'};
                            end
                        end
                    end
                    if  predAG.dc(i,1)==0 && predAG.w(i,1)==1
                        res.AD_CERAPP_Ago(i,1)=1;
                        res.AD_index_CERAPP_Ago(i,1)=1;
                    end
                    res.Conf_index_CERAPP_Ago(i,1)=(train.CERAPP.model_AG.conc_AG(predAG.neighbors(i,:),1)'*predAG.w(i,:)'+res.AD_index_CERAPP_Ago(i))/2;
                    if res.AD_index_CERAPP_Ago(i)==0
                        res.Conf_index_CERAPP_Ago(i,1)=0;
                    end
                    if res.AD_index_CERAPP_Ago(i,1)>=0.6 && res.Conf_index_CERAPP_Ago(i,1)>=0.5
                        res.AD_CERAPP_Ago(i,1)=1;
                    elseif res.AD_index_CERAPP_Ago(i,1)<0.2 && res.Conf_index_CERAPP_Ago(i,1)<0.5
                        res.AD_CERAPP_Ago(i,1)=0;
                    end
                    if isnan(res.AD_CERAPP_Ago(i,1))
                        res.AD_CERAPP_Ago(i,1)=0;
                    end
                    
                    
                    if  predAN.dc(i,1)==0 && predAN.w(i,1)==1
                        res.AD_CERAPP_Anta(i,1)=1;
                        res.AD_index_CERAPP_Anta(i,1)=1;
                    end
                    res.Conf_index_CERAPP_Anta(i,1)=(train.CERAPP.model_AN.conc_AN(predAN.neighbors(i,:),1)'*predAN.w(i,:)'+res.AD_index_CERAPP_Anta(i))/2;
                    if res.AD_index_CERAPP_Anta(i)==0
                        res.Conf_index_CERAPP_Anta(i,1)=0;
                    end
                    if res.AD_index_CERAPP_Anta(i,1)>=0.6 && res.Conf_index_CERAPP_Anta(i,1)>=0.5
                        res.AD_CERAPP_Anta(i,1)=1;
                    elseif res.AD_index_CERAPP_Anta(i,1)<0.2 && res.Conf_index_CERAPP_Anta(i,1)<0.5
                        res.AD_CERAPP_Anta(i,1)=0;
                    end
                    if isnan(res.AD_CERAPP_Anta(i,1))
                        res.AD_CERAPP_Anta(i,1)=0;
                    end
                    
                    
                    if  predBD.dc(i,1)==0 && predBD.w(i,1)==1
                        res.AD_CERAPP_Bind(i,1)=1;
                        res.AD_index_CERAPP_Bind(i,1)=1;
                    end
                    res.Conf_index_CERAPP_Bind(i,1)=(train.CERAPP.model_BD.conc_BD(predBD.neighbors(i,:),1)'*predBD.w(i,:)'+res.AD_index_CERAPP_Bind(i))/2;
                    if res.AD_index_CERAPP_Bind(i)==0
                        res.Conf_index_CERAPP_Bind(i,1)=0;
                    end
                    if res.AD_index_CERAPP_Bind(i,1)>=0.6 && res.Conf_index_CERAPP_Bind(i,1)>=0.5
                        res.AD_CERAPP_Bind(i,1)=1;
                    elseif res.AD_index_CERAPP_Bind(i,1)<0.2 && res.Conf_index_CERAPP_Bind(i,1)<0.5
                        res.AD_CERAPP_Bind(i,1)=0;
                    end
                    if isnan(res.AD_CERAPP_Bind(i,1))
                        res.AD_CERAPP_Bind(i,1)=0;
                    end
                    
                    
                    if isempty(find(~isnan(predAG.dc(i,:)), 1)) || isnan(res.CERAPP_Ago_pred(i,1))
                        res.CERAPP_Ago_pred(i,1)=NaN;
                        res.AD_CERAPP_Ago(i)=0;
                        res.AD_index_CERAPP_Ago(i)=0;
                        res.Conf_index_CERAPP_Ago(i,1)=0;
                    end
                    if isempty(find(~isnan(predAN.dc(i,:)), 1)) || isnan(res.CERAPP_Anta_pred(i,1))
                        res.CERAPP_Anta_pred(i,1)=NaN;
                        res.AD_CERAPP_Anta(i)=0;
                        res.AD_index_CERAPP_Anta(i)=0;
                        res.Conf_index_CERAPP_Anta(i,1)=0;
                    end
                    if isempty(find(~isnan(predBD.dc(i,:)), 1)) || isnan(res.CERAPP_Bind_pred(i,1))
                        res.CERAPP_Bind_pred(i,1)=NaN;
                        res.AD_CERAPP_Bind(i)=0;
                        res.AD_index_CERAPP_Bind(i)=0;
                        res.Conf_index_CERAPP_Bind(i,1)=0;
                    end
                    
                    if Xin(i,12)==0
                        res.AD_CERAPP_Ago(i)=0;
                        res.AD_index_CERAPP_Ago(i)=res.AD_index_CERAPP_Ago(i)/2;
                        res.Conf_index_CERAPP_Ago(i,1)=res.Conf_index_CERAPP_Ago(i,1)/2;
                        res.AD_CERAPP_Anta(i)=0;
                        res.AD_index_CERAPP_Anta(i)=res.AD_index_CERAPP_Anta(i)/2;
                        res.Conf_index_CERAPP_Anta(i,1)=res.Conf_index_CERAPP_Anta(i,1)/2;
                        res.AD_CERAPP_Bind(i)=0;
                        res.AD_index_CERAPP_Bind(i)=res.AD_index_CERAPP_Bind(i)/2;
                        res.Conf_index_CERAPP_Bind(i,1)=res.Conf_index_CERAPP_Bind(i,1)/2;
                    end

                    res.AD_index_CERAPP_Ago(i,1)=round(res.AD_index_CERAPP_Ago(i,1),2);
                    res.Conf_index_CERAPP_Ago(i,1)=round(res.Conf_index_CERAPP_Ago(i,1),2);
                    res.AD_index_CERAPP_Anta(i,1)=round(res.AD_index_CERAPP_Anta(i,1),2);
                    res.Conf_index_CERAPP_Anta(i,1)=round(res.Conf_index_CERAPP_Anta(i,1),2);
                    res.AD_index_CERAPP_Bind(i,1)=round(res.AD_index_CERAPP_Bind(i,1),2);
                    res.Conf_index_CERAPP_Bind(i,1)=round(res.Conf_index_CERAPP_Bind(i,1),2);

                    if neighbors==1
                        %                         train.CERAPP.model_AG.CAS=strrep(strrep(join(train.CERAPP.model_AG.CAS,'|',2),'|||',''),'||','');
                        %                         train.CERAPP.model_AG.DTXSID=strrep(strrep(join(train.CERAPP.model_AG.DTXSID,'|',2),'|||',''),'||','');
                        %                         train.CERAPP.model_AN.CAS=strrep(strrep(join(train.CERAPP.model_AN.CAS,'|',2),'|||',''),'||','');
                        %                         train.CERAPP.model_AN.DTXSID=strrep(strrep(join(train.CERAPP.model_AN.DTXSID,'|',2),'|||',''),'||','');
                        %                         train.CERAPP.model_BD.CAS=strrep(strrep(join(train.CERAPP.model_BD.CAS,'|',2),'|||',''),'||','');
                        %                         train.CERAPP.model_BD.DTXSID=strrep(strrep(join(train.CERAPP.model_BD.DTXSID,'|',2),'|||',''),'||','');
                        if res.AD_index_CERAPP_Ago(i)~=0
                            res.CERAPP_Ago_CAS_neighbor(i,:)=AG_CAS(predAG.neighbors(i,:));
                            res.CERAPP_Ago_InChiKey_neighbor(i,:)=train.CERAPP.model_AG.InChiKey(predAG.neighbors(i,:));
                            res.CERAPP_Ago_DTXSID_neighbor(i,:)=AG_DTXSID(predAG.neighbors(i,:));
                            %res.CERAPP_Ago_DSSTOXMPID_neighbor(i,:)=train.CERAPP.model_AG.DSSTOXMPID(pred.neighbors(i,:));
                            res.CERAPP_Ago_Exp_neighbor(i,:)=train.CERAPP.model_AG.set.class_Exp(predAG.neighbors(i,:));
                            res.CERAPP_Ago_pred_neighbor(i,:)=train.CERAPP.model_AG.set.class_S(predAG.neighbors(i,:));
                        else
                            res.CERAPP_Ago_CAS_neighbor(i,:)=cell(1,5);
                            res.CERAPP_Ago_InChiKey_neighbor(i,:)=cell(1,5);
                            res.CERAPP_Ago_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.CERAPP_Ago_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.CERAPP_Ago_Exp_neighbor(i,:)=cell(1,5);
                            res.CERAPP_Ago_pred_neighbor(i,:)=cell(1,5);
                        end
                        if res.AD_index_CERAPP_Anta(i) ~=0
                            res.CERAPP_Anta_CAS_neighbor(i,:)=AN_CAS(predAN.neighbors(i,:));
                            res.CERAPP_Anta_InChiKey_neighbor(i,:)=train.CERAPP.model_AN.InChiKey(predAN.neighbors(i,:));
                            res.CERAPP_Anta_DTXSID_neighbor(i,:)=AN_DTXSID(predAN.neighbors(i,:));
                            %res.CERAPP_Anta_DSSTOXMPID_neighbor(i,:)=train.CERAPP.model_AN.DSSTOXMPID(pred.neighbors(i,:));
                            res.CERAPP_Anta_Exp_neighbor(i,:)=train.CERAPP.model_AN.set.class_Exp(predAN.neighbors(i,:));
                            res.CERAPP_Anta_pred_neighbor(i,:)=train.CERAPP.model_AN.set.class_S(predAN.neighbors(i,:));
                        else
                            res.CERAPP_Anta_CAS_neighbor(i,:)=cell(1,5);
                            res.CERAPP_Anta_InChiKey_neighbor(i,:)=cell(1,5);
                            res.CERAPP_Anta_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.CERAPP_Anta_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.CERAPP_Anta_Exp_neighbor(i,:)=cell(1,5);
                            res.CERAPP_Anta_pred_neighbor(i,:)=cell(1,5);
                        end
                        if res.AD_index_CERAPP_Bind(i) ~=0
                            res.CERAPP_Bind_CAS_neighbor(i,:)=BD_CAS(predBD.neighbors(i,:));
                            res.CERAPP_Bind_InChiKey_neighbor(i,:)=train.CERAPP.model_BD.InChiKey(predBD.neighbors(i,:));
                            res.CERAPP_Bind_DTXSID_neighbor(i,:)=BD_DTXSID(predBD.neighbors(i,:));
                            %res.CERAPP_Bind_DSSTOXMPID_neighbor(i,:)=train.CERAPP.model_BD.DSSTOXMPID(predBD.neighbors(i,:));
                            res.CERAPP_Bind_Exp_neighbor(i,:)=train.CERAPP.model_BD.set.class_Exp(predBD.neighbors(i,:));
                            res.CERAPP_Bind_pred_neighbor(i,:)=train.CERAPP.model_BD.set.class_S(predBD.neighbors(i,:));
                        else
                            res.CERAPP_Bind_CAS_neighbor(i,:)=cell(1,5);
                            res.CERAPP_Bind_InChiKey_neighbor(i,:)=cell(1,5);
                            res.CERAPP_Bind_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.CERAPP_Bind_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.CERAPP_Bind_Exp_neighbor(i,:)=cell(1,5);
                            res.CERAPP_Bind_pred_neighbor(i,:)=cell(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'AG experimental= %s, AN experimental= %s, BD experimental= %s\n', res.CERAPP_Ago_exp{i},res.CERAPP_Anta_exp{i},res.CERAPP_Bind_exp{i});
                        end
                        fprintf(output(Locb(find(Locb))),'AG predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CERAPP_Ago_pred(i),res.AD_CERAPP_Ago(i),res.AD_index_CERAPP_Ago(i),res.Conf_index_CERAPP_Ago(i));
                        fprintf(output(Locb(find(Locb))),'AN predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CERAPP_Anta_pred(i),res.AD_CERAPP_Anta(i),res.AD_index_CERAPP_Anta(i),res.Conf_index_CERAPP_Anta(i));
                        fprintf(output(Locb(find(Locb))),'BD category predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CERAPP_Bind_pred(i),res.AD_CERAPP_Bind(i),res.AD_index_CERAPP_Bind(i),res.Conf_index_CERAPP_Bind(i));
                        
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors (Agonist):%15s,%15s,%15s,%15s,%15s\n',train.CERAPP.model_AG.set.K, res.CERAPP_Ago_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors (Antagonist):%15s,%15s,%15s,%15s,%15s\n',train.CERAPP.model_AN.set.K, res.CERAPP_Anta_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors (Binding):%15s,%15s,%15s,%15s,%15s\n',train.CERAPP.model_BD.set.K, res.CERAPP_Bind_CAS_neighbor{i,1:5});
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'AG experimental= %s, AN experimental= %s, BD experimental= %s\n', res.CERAPP_Ago_exp{i},res.CERAPP_Anta_exp{i},res.CERAPP_Bind_exp{i});
                        end
                        fprintf(output,'AG predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CERAPP_Ago_pred(i),res.AD_CERAPP_Ago(i),res.AD_index_CERAPP_Ago(i),res.Conf_index_CERAPP_Ago(i));
                        fprintf(output,'AN predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CERAPP_Anta_pred(i),res.AD_CERAPP_Anta(i),res.AD_index_CERAPP_Anta(i),res.Conf_index_CERAPP_Anta(i));
                        fprintf(output,'BD category predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CERAPP_Bind_pred(i),res.AD_CERAPP_Bind(i),res.AD_index_CERAPP_Bind(i),res.Conf_index_CERAPP_Bind(i));
                        
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors (Agonist):%15s,%15s,%15s,%15s,%15s\n',train.CERAPP.model_AG.set.K, res.CERAPP_Ago_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors (Antagonist):%15s,%15s,%15s,%15s,%15s\n',train.CERAPP.model_AN.set.K, res.CERAPP_Anta_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors (Binding):%15s,%15s,%15s,%15s,%15s\n',train.CERAPP.model_BD.set.K, res.CERAPP_Bind_CAS_neighbor{i,1:5});
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         if exp
                        %                             T{end+1:end+nf,2:5}=nan(nf,4);
                        %                         else
                        %                             T{end+1:end+nf,1:4}=nan(nf,4);
                        %                         end
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        %Xtest=[XtestAG; XtestAN; XtestBD; XtestGHS; XtestLD50];
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    %Xtest=[XtestAG; XtestAN; XtestBD; XtestGHS; XtestLD50];
                    Xtest(:,ismember(Desc,DescNames))=[];
                    Desc(ismember(Desc,DescNames))=[];
                    DescNames=[DescNames Desc];
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.CERAPP=res;
                    clear('res');
                    
                end
                % Clean memory
                clear('XinCDK_CERAPP');
                clear('Xtest');
                clear('XtestAG');
                clear('XtestAN');
                clear('XtestBD');
                clear('predAG');
                clear('predAN');
                clear('predBD')
                clear('AD');
                train = rmfield(train, 'CERAPP');
                clear('AG_CAS');
                clear('AN_CAS');
                clear('BD_CAS');
                clear('AG_DTXSID');
                clear('AN_DTXSID');
                clear('BD_DTXSID');
                %end clean memory
            end
            
            %--------------------------------------------
            
            %Predict CoMPARA endpoints
            %case {'CoMPARA','ER'}
            [Lia,Locb] =ismember({'compara','ar'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting Androgen Receptor Activity (CoMPARA)';
                Desc=train.COMPARA.DescIn;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting CoMPARA endpoints... \n\n			==============================================================  \n\n');
                end
                
                %XlabelsCDK
                XinCDK_CoMPARA=XinCDK(:,train.COMPARA.cdk_in);
                Xtest=[Xin(:,train.PadelVarIn(train.COMPARA.Padel_in)), XinCDK_CoMPARA];

                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some CoMPARA descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds may have failed CoMPARA calculations. Check input and repeat calculation.'];
                end
                %%%
                
                XtestAG=Xtest(:,train.COMPARA.model_AG.DescAG_i);
                XtestAN=Xtest(:,train.COMPARA.model_AN.DescAN_i);
                XtestBD=Xtest(:,train.COMPARA.model_BD.DescBD_i);
                
                predAG = knnpred2(XtestAG,train.COMPARA.model_AG.set.train,train.COMPARA.model_AG.set.class,train.COMPARA.model_AG.set.class_Exp_N,train.COMPARA.model_AG.set.K,train.COMPARA.model_AG.set.dist_type,train.COMPARA.model_AG.set.param.pret_type);
                %predAG.D=diag(predAG.D);
                predAG.D=[];
                predAN = knnpred2(XtestAN,train.COMPARA.model_AN.set.train,train.COMPARA.model_AN.set.class,train.COMPARA.model_AN.set.class_Exp_N,train.COMPARA.model_AN.set.K,train.COMPARA.model_AN.set.dist_type,train.COMPARA.model_AN.set.param.pret_type);
                %predAN.D=diag(predAN.D);
                predAN.D=[];
                predBD = knnpred2(XtestBD,train.COMPARA.model_BD.set.train,train.COMPARA.model_BD.set.class,train.COMPARA.model_BD.set.class_Exp_N,train.COMPARA.model_BD.set.K,train.COMPARA.model_BD.set.dist_type,train.COMPARA.model_BD.set.param.pret_type);
                %predBD.D=diag(predBD.D);
                predBD.D=[];
                
                res.MoleculeID=MoleculeNames;
                if exp
                    res.CoMPARA_Ago_exp=cell(size(Xtest,1),1);
                end
                res.CoMPARA_Ago_pred(:,1)=predAG.class_pred-1;
                AD=classical_leverage(train.COMPARA.model_AG.set.train,XtestAG,'auto');
                res.AD_CoMPARA_Ago=abs(AD.inorout-1)';
                res.AD_index_CoMPARA_Ago=zeros(size(XtestAG,1),1);
                %                 res.AD_index_CoMPARA_Ago=1-test_pretreatment(predAG.dc(:,1),train.COMPARA.model_AG.set.dc_param);
                %                 res.AD_index_CoMPARA_Ago(find(res.AD_index_CoMPARA_Ago<0),1)=1./(1+predAG.dc(find(res.AD_index_CoMPARA_Ago<0),1));
                res.CoMPARA_Ago_pred(find(isnan(predAG.dc(:,1))))=NaN;
                res.AD_CoMPARA_Ago(find(isnan(predAG.dc(:,1))))=0;
                %                 res.AD_index_CoMPARA_Ago(find(isnan(predAG.dc(:,1))))=0;
                %                 res.AD_CoMPARA_Ago(find(res.AD_index_CoMPARA_Ago>0.5))=1;
                res.Conf_index_CoMPARA_Ago=zeros(size(XtestAG,1),1);
                if exp
                    res.CoMPARA_Anta_exp=cell(size(Xtest,1),1);
                end
                res.CoMPARA_Anta_pred(:,1)=predAN.class_pred-1;
                AD=classical_leverage(train.COMPARA.model_AN.set.train,XtestAN,'auto');
                res.AD_CoMPARA_Anta=abs(AD.inorout-1)';
                res.AD_index_CoMPARA_Anta=zeros(size(XtestAN,1),1);
                %                 res.AD_index_CoMPARA_Anta=1-test_pretreatment(predAN.dc(:,1),train.COMPARA.model_AN.set.dc_param);
                %                 res.AD_index_CoMPARA_Anta(find(res.AD_index_CoMPARA_Anta<0),1)=1./(1+predAN.dc(find(res.AD_index_CoMPARA_Anta<0),1));
                res.CoMPARA_Anta_pred(find(isnan(predAN.dc(:,1))))=NaN;
                res.AD_CoMPARA_Anta(find(isnan(predAN.dc(:,1))))=0;
                %                 res.AD_index_CoMPARA_Anta(find(isnan(predAN.dc(:,1))))=0;
                %                 res.AD_CoMPARA_Anta(find(res.AD_index_CoMPARA_Anta>0.5))=1;
                res.Conf_index_CoMPARA_Anta=zeros(size(XtestAN,1),1);
                if exp
                    res.CoMPARA_Bind_exp=cell(size(Xtest,1),1);
                end
                res.CoMPARA_Bind_pred(:,1)=predBD.class_pred-1;
                AD=classical_leverage(train.COMPARA.model_BD.set.train,XtestBD,'auto');
                res.AD_CoMPARA_Bind=abs(AD.inorout-1)';
                res.AD_index_CoMPARA_Bind=zeros(size(XtestBD,1),1);
                %                 res.AD_index_CoMPARA_Bind=1-test_pretreatment(predBD.dc(:,1),train.COMPARA.model_BD.set.dc_param);
                %                 res.AD_index_CoMPARA_Bind(find(res.AD_index_CoMPARA_Bind<0),1)=1./(1+predBD.dc(find(res.AD_index_CoMPARA_Bind<0),1));
                res.CoMPARA_Bind_pred(find(isnan(predBD.dc(:,1))))=NaN;
                res.AD_CoMPARA_Bind(find(isnan(predBD.dc(:,1))))=0;
                %                 res.AD_index_CoMPARA_Bind(find(isnan(predBD.dc(:,1))))=0;
                %                 res.AD_CoMPARA_Bind(find(res.AD_index_CoMPARA_Bind>0.5))=1;
                res.Conf_index_CoMPARA_Bind=zeros(size(XtestBD,1),1);
                res.CoMPARA_Bind_pred(find(res.CoMPARA_Ago_pred(:,1)))=1;
                res.CoMPARA_Bind_pred(find(res.CoMPARA_Anta_pred(:,1)))=1;
                
                AG_CAS=strrep(strrep(join(train.COMPARA.model_AG.CAS,'|',2),'|||',''),'||','');
                AG_DTXSID=strrep(strrep(join(train.COMPARA.model_AG.DTXSID,'|',2),'|||',''),'||','');
                AN_CAS=strrep(strrep(join(train.COMPARA.model_AN.CAS,'|',2),'|||',''),'||','');
                AN_DTXSID=strrep(strrep(join(train.COMPARA.model_AN.DTXSID,'|',2),'|||',''),'||','');
                BD_CAS=strrep(strrep(join(train.COMPARA.model_BD.CAS,'|',2),'|||',''),'||','');
                BD_DTXSID=strrep(strrep(join(train.COMPARA.model_BD.DTXSID,'|',2),'|||',''),'||','');
                for i=1:size(Xtest,1)
                    res.AD_index_CoMPARA_Ago(i,1)=1./(1+predAG.dc(i,~isnan(predAG.dc(i,:)))*predAG.w(i,~isnan(predAG.dc(i,:)))');
                    res.AD_index_CoMPARA_Ago(i,1)=0.5*res.AD_index_CoMPARA_Ago(i,1)+0.3*(~isnan(train.COMPARA.model_AG.set.class_Exp_N(predAG.neighbors(i,1),1)))+0.2*length(find(~isnan(train.COMPARA.model_AG.set.class_Exp_N(predAG.neighbors(i,:),1))))/5;
                    res.AD_index_CoMPARA_Anta(i,1)=1./(1+predAN.dc(i,~isnan(predAN.dc(i,:)))*predAN.w(i,~isnan(predAN.dc(i,:)))');
                    res.AD_index_CoMPARA_Anta(i,1)=0.5*res.AD_index_CoMPARA_Anta(i,1)+0.3*(~isnan(train.COMPARA.model_AN.set.class_Exp_N(predAN.neighbors(i,1),1)))+0.2*length(find(~isnan(train.COMPARA.model_AN.set.class_Exp_N(predAN.neighbors(i,:),1))))/5;
                    res.AD_index_CoMPARA_Bind(i,1)=1./(1+predBD.dc(i,~isnan(predBD.dc(i,:)))*predBD.w(i,~isnan(predBD.dc(i,:)))');
                    res.AD_index_CoMPARA_Bind(i,1)=0.5*res.AD_index_CoMPARA_Bind(i,1)+0.3*(~isnan(train.COMPARA.model_BD.set.class_Exp_N(predBD.neighbors(i,1),1)))+0.2*length(find(~isnan(train.COMPARA.model_BD.set.class_Exp_N(predBD.neighbors(i,:),1))))/5;
                    
                    Li_ag=0;
                    Li_an=0;
                    Li_bd=0;
                    if ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            [Li_ag,Lo_ag] = ismember(MoleculeNames(i),train.COMPARA.model_AG.CAS);
                            [Li_an,Lo_an] = ismember(MoleculeNames(i),train.COMPARA.model_AN.CAS);
                            [Li_bd,Lo_bd] = ismember(MoleculeNames(i),train.COMPARA.model_BD.CAS);
                            if Li_ag
                                if Lo_ag>size(train.COMPARA.model_AG.CAS,1)
                                    Lo_ag=mod(Lo_ag,size(train.COMPARA.model_AG.CAS,1));
                                end
                                res.CoMPARA_Ago_pred(i,1)=train.COMPARA.model_AG.set.class(Lo_ag)-1;
                                res.AD_CoMPARA_Ago(i)=1;
                                res.AD_index_CoMPARA_Ago(i)=1;
                                if exp
                                    res.CoMPARA_Ago_exp(i,1)=train.COMPARA.model_AG.set.class_Exp(Lo_ag);
                                end
                            else
                                if exp
                                    res.CoMPARA_Ago_exp(i,1)={'NA'};
                                end
                            end
                            if Li_an
                                if Lo_an>size(train.COMPARA.model_AN.CAS,1)
                                    Lo_an=mod(Lo_an,size(train.COMPARA.model_AN.CAS,1));
                                end
                                res.CoMPARA_Anta_pred(i,1)=train.COMPARA.model_AN.set.class(Lo_an)-1;
                                res.AD_CoMPARA_Anta(i)=1;
                                res.AD_index_CoMPARA_Anta(i)=1;
                                if exp
                                    res.CoMPARA_Anta_exp(i,1)=train.COMPARA.model_AN.set.class_Exp(Lo_an);
                                end
                            else
                                if exp
                                    res.CoMPARA_Anta_exp(i,1)={'NA'};
                                end
                            end
                            if Li_bd
                                if Lo_bd>size(train.COMPARA.model_BD.CAS,1)
                                    Lo_bd=mod(Lo_bd,size(train.COMPARA.model_BD.CAS,1));
                                end
                                res.CoMPARA_Bind_pred(i,1)=train.COMPARA.model_BD.set.class(Lo_bd)-1;
                                res.AD_CoMPARA_Bind(i)=1;
                                res.AD_index_CoMPARA_Bind(i)=1;
                                if exp
                                    res.CoMPARA_Bind_exp(i,1)=train.COMPARA.model_BD.set.class_Exp(Lo_bd);
                                end
                            else
                                if exp
                                    res.CoMPARA_Bind_exp(i,1)={'NA'};
                                end
                            end
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            [Li_ag,Lo_ag] = ismember(MoleculeNames{i},train.COMPARA.model_AG.DTXSID);
                            [Li_an,Lo_an] = ismember(MoleculeNames{i},train.COMPARA.model_AN.DTXSID);
                            [Li_bd,Lo_bd] = ismember(MoleculeNames{i},train.COMPARA.model_BD.DTXSID);
                            
                            if Li_ag
                                if Lo_ag>size(train.COMPARA.model_AG.DTXSID,1)
                                    Lo_ag=mod(Lo_ag,size(train.COMPARA.model_AG.DTXSID,1));
                                end
                                res.CoMPARA_Ago_pred(i,1)=train.COMPARA.model_AG.set.class(Lo_ag)-1;
                                res.AD_CoMPARA_Ago(i)=1;
                                res.AD_index_CoMPARA_Ago(i)=1;
                                if exp
                                    res.CoMPARA_Ago_exp(i,1)=train.COMPARA.model_AG.set.class_Exp(Lo_ag);
                                end
                            else
                                if exp
                                    res.CoMPARA_Ago_exp(i,1)={'NA'};
                                end
                            end
                            if Li_an
                                if Lo_an>size(train.COMPARA.model_AN.DTXSID,1)
                                    Lo_an=mod(Lo_an,size(train.COMPARA.model_AN.DTXSID,1));
                                end
                                res.CoMPARA_Anta_pred(i,1)=train.COMPARA.model_AN.set.class(Lo_an)-1;
                                res.AD_CoMPARA_Anta(i)=1;
                                res.AD_index_CoMPARA_Anta(i)=1;
                                if exp
                                    res.CoMPARA_Anta_exp(i,1)=train.COMPARA.model_AN.set.class_Exp(Lo_an);
                                end
                            else
                                if exp
                                    res.CoMPARA_Anta_exp(i,1)={'NA'};
                                end
                            end
                            if Li_bd
                                if Lo_bd>size(train.COMPARA.model_BD.DTXSID,1)
                                    Lo_bd=mod(Lo_bd,size(train.COMPARA.model_BD.DTXSID,1));
                                end
                                res.CoMPARA_Bind_pred(i,1)=train.COMPARA.model_BD.set.class(Lo_bd)-1;
                                res.AD_CoMPARA_Bind(i)=1;
                                res.AD_index_CoMPARA_Bind(i)=1;
                                if exp
                                    res.CoMPARA_Bind_exp(i,1)=train.COMPARA.model_BD.set.class_Exp(Lo_bd);
                                end
                            else
                                if exp
                                    res.CoMPARA_Bind_exp(i,1)={'NA'};
                                end
                            end
                        else
                            if exp
                                res.CoMPARA_Ago_exp(i,1)={'NA'};
                                res.CoMPARA_Anta_exp(i,1)={'NA'};
                                res.CoMPARA_Bind_exp(i,1)={'NA'};
                            end
                        end
                    end
                    if  predAG.dc(i,1)==0 && predAG.w(i,1)==1
                        res.AD_CoMPARA_Ago(i,1)=1;
                        res.AD_index_CoMPARA_Ago(i,1)=1;
                    end
                    res.Conf_index_CoMPARA_Ago(i,1)=(train.COMPARA.model_AG.conc_AG(predAG.neighbors(i,:),1)'*predAG.w(i,:)'+res.AD_index_CoMPARA_Ago(i))/2;
                    if res.AD_index_CoMPARA_Ago(i)==0
                        res.Conf_index_CoMPARA_Ago(i,1)=0;
                    end
                    if res.AD_index_CoMPARA_Ago(i,1)>=0.6 && res.Conf_index_CoMPARA_Ago(i,1)>=0.5
                        res.AD_CoMPARA_Ago(i,1)=1;
                    elseif res.AD_index_CoMPARA_Ago(i,1)<0.2 && res.Conf_index_CoMPARA_Ago(i,1)<0.5
                        res.AD_CoMPARA_Ago(i,1)=0;
                    end
                    if isnan(res.AD_CoMPARA_Ago(i,1))
                        res.AD_CoMPARA_Ago(i,1)=0;
                    end
                    
                    
                    if  predAN.dc(i,1)==0 && predAN.w(i,1)==1
                        res.AD_CoMPARA_Anta(i,1)=1;
                        res.AD_index_CoMPARA_Anta(i,1)=1;
                    end
                    res.Conf_index_CoMPARA_Anta(i,1)=(train.COMPARA.model_AN.conc_AN(predAN.neighbors(i,:),1)'*predAN.w(i,:)'+res.AD_index_CoMPARA_Anta(i))/2;
                    if res.AD_index_CoMPARA_Anta(i)==0
                        res.Conf_index_CoMPARA_Anta(i,1)=0;
                    end
                    if res.AD_index_CoMPARA_Anta(i,1)>=0.6 && res.Conf_index_CoMPARA_Anta(i,1)>=0.5
                        res.AD_CoMPARA_Anta(i,1)=1;
                    elseif res.AD_index_CoMPARA_Anta(i,1)<0.2 && res.Conf_index_CoMPARA_Anta(i,1)<0.5
                        res.AD_CoMPARA_Anta(i,1)=0;
                    end
                    if isnan(res.AD_CoMPARA_Anta(i,1))
                        res.AD_CoMPARA_Anta(i,1)=0;
                    end
                    
                    
                    if  predBD.dc(i,1)==0 && predBD.w(i,1)==1
                        res.AD_CoMPARA_Bind(i,1)=1;
                        res.AD_index_CoMPARA_Bind(i,1)=1;
                    end
                    res.Conf_index_CoMPARA_Bind(i,1)=(train.COMPARA.model_BD.conc_BD(predBD.neighbors(i,:),1)'*predBD.w(i,:)'+res.AD_index_CoMPARA_Bind(i))/2;
                    if res.AD_index_CoMPARA_Bind(i)==0
                        res.Conf_index_CoMPARA_Bind(i,1)=0;
                    end
                    if res.AD_index_CoMPARA_Bind(i,1)>=0.6 && res.Conf_index_CoMPARA_Bind(i,1)>=0.5
                        res.AD_CoMPARA_Bind(i,1)=1;
                    elseif res.AD_index_CoMPARA_Bind(i,1)<0.2 && res.Conf_index_CoMPARA_Bind(i,1)<0.5
                        res.AD_CoMPARA_Bind(i,1)=0;
                    end
                    if isnan(res.AD_CoMPARA_Bind(i,1))
                        res.AD_CoMPARA_Bind(i,1)=0;
                    end
                    

                    if isempty(find(~isnan(predAG.dc(i,:)), 1)) || isnan(res.CoMPARA_Ago_pred(i,1))
                        res.CoMPARA_Ago_pred(i,1)=NaN;
                        res.AD_CoMPARA_Ago(i)=0;
                        res.AD_index_CoMPARA_Ago(i)=0;
                        res.Conf_index_CoMPARA_Ago(i,1)=0;
                    end
                    if isempty(find(~isnan(predAN.dc(i,:)), 1)) || isnan(res.CoMPARA_Anta_pred(i,1))
                        res.CoMPARA_Anta_pred(i,1)=NaN;
                        res.AD_CoMPARA_Anta(i)=0;
                        res.AD_index_CoMPARA_Anta(i)=0;
                        res.Conf_index_CoMPARA_Anta(i,1)=0;
                    end
                    if isempty(find(~isnan(predBD.dc(i,:)), 1)) || isnan(res.CoMPARA_Bind_pred(i,1))
                        res.CoMPARA_Bind_pred(i,1)=NaN;
                        res.AD_CoMPARA_Bind(i)=0;
                        res.AD_index_CoMPARA_Bind(i)=0;
                        res.Conf_index_CoMPARA_Bind(i,1)=0;
                    end


                    if Xin(i,12)==0
                        res.AD_CoMPARA_Ago(i)=0;
                        res.AD_index_CoMPARA_Ago(i)=res.AD_index_CoMPARA_Ago(i)/2;
                        res.Conf_index_CoMPARA_Ago(i,1)=res.Conf_index_CoMPARA_Ago(i,1)/2;
                        res.AD_CoMPARA_Anta(i)=0;
                        res.AD_index_CoMPARA_Anta(i)=res.AD_index_CoMPARA_Anta(i)/2;
                        res.Conf_index_CoMPARA_Anta(i,1)=res.Conf_index_CoMPARA_Anta(i,1)/2;
                        res.AD_CoMPARA_Bind(i)=0;
                        res.AD_index_CoMPARA_Bind(i)=res.AD_index_CoMPARA_Bind(i)/2;
                        res.Conf_index_CoMPARA_Bind(i,1)=res.Conf_index_CoMPARA_Bind(i,1)/2;
                    end

                    res.AD_index_CoMPARA_Ago(i,1)=round(res.AD_index_CoMPARA_Ago(i,1),2);
                    res.Conf_index_CoMPARA_Ago(i,1)=round(res.Conf_index_CoMPARA_Ago(i,1),2);
                    res.AD_index_CoMPARA_Anta(i,1)=round(res.AD_index_CoMPARA_Anta(i,1),2);
                    res.Conf_index_CoMPARA_Anta(i,1)=round(res.Conf_index_CoMPARA_Anta(i,1),2);
                    res.AD_index_CoMPARA_Bind(i,1)=round(res.AD_index_CoMPARA_Bind(i,1),2);
                    res.Conf_index_CoMPARA_Bind(i,1)=round(res.Conf_index_CoMPARA_Bind(i,1),2);

                    if neighbors==1
                        %                         train.COMPARA.model_AG.CAS=strrep(strrep(join(train.COMPARA.model_AG.CAS,'|',2),'|||',''),'||','');
                        %                         train.COMPARA.model_AG.DTXSID=strrep(strrep(join(train.COMPARA.model_AG.DTXSID,'|',2),'|||',''),'||','');
                        %                         train.COMPARA.model_AN.CAS=strrep(strrep(join(train.COMPARA.model_AN.CAS,'|',2),'|||',''),'||','');
                        %                         train.COMPARA.model_AN.DTXSID=strrep(strrep(join(train.COMPARA.model_AN.DTXSID,'|',2),'|||',''),'||','');
                        %                         train.COMPARA.model_BD.CAS=strrep(strrep(join(train.COMPARA.model_BD.CAS,'|',2),'|||',''),'||','');
                        %                         train.COMPARA.model_BD.DTXSID=strrep(strrep(join(train.COMPARA.model_BD.DTXSID,'|',2),'|||',''),'||','');
                        if res.AD_index_CoMPARA_Ago(i)~=0
                            res.CoMPARA_Ago_CAS_neighbor(i,:)=AG_CAS(predAG.neighbors(i,:));
                            res.CoMPARA_Ago_InChiKey_neighbor(i,:)=train.COMPARA.model_AG.InChiKey(predAG.neighbors(i,:));
                            res.CoMPARA_Ago_DTXSID_neighbor(i,:)=AG_DTXSID(predAG.neighbors(i,:));
                            %res.CoMPARA_Ago_DSSTOXMPID_neighbor(i,:)=train.COMPARA.model_AG.DSSTOXMPID(pred.neighbors(i,:));
                            res.CoMPARA_Ago_Exp_neighbor(i,:)=train.COMPARA.model_AG.set.class_Exp(predAG.neighbors(i,:));
                            res.CoMPARA_Ago_pred_neighbor(i,:)=train.COMPARA.model_AG.set.class_S(predAG.neighbors(i,:));
                        else
                            res.CoMPARA_Ago_CAS_neighbor(i,:)=cell(1,5);
                            res.CoMPARA_Ago_InChiKey_neighbor(i,:)=cell(1,5);
                            res.CoMPARA_Ago_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.CoMPARA_Ago_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.CoMPARA_Ago_Exp_neighbor(i,:)=cell(1,5);
                            res.CoMPARA_Ago_pred_neighbor(i,:)=cell(1,5);
                        end
                        if res.AD_index_CoMPARA_Anta(i)~=0
                            res.CoMPARA_Anta_CAS_neighbor(i,:)=AN_CAS(predAN.neighbors(i,:));
                            res.CoMPARA_Anta_InChiKey_neighbor(i,:)=train.COMPARA.model_AN.InChiKey(predAN.neighbors(i,:));
                            res.CoMPARA_Anta_DTXSID_neighbor(i,:)=AN_DTXSID(predAN.neighbors(i,:));
                            %res.CoMPARA_Anta_DSSTOXMPID_neighbor(i,:)=train.COMPARA.model_AN.DSSTOXMPID(pred.neighbors(i,:));
                            res.CoMPARA_Anta_Exp_neighbor(i,:)=train.COMPARA.model_AN.set.class_Exp(predAN.neighbors(i,:));
                            res.CoMPARA_Anta_pred_neighbor(i,:)=train.COMPARA.model_AN.set.class_S(predAN.neighbors(i,:));
                        else
                            res.CoMPARA_Anta_CAS_neighbor(i,:)=cell(1,5);
                            res.CoMPARA_Anta_InChiKey_neighbor(i,:)=cell(1,5);
                            res.CoMPARA_Anta_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.CoMPARA_Anta_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.CoMPARA_Anta_Exp_neighbor(i,:)=cell(1,5);
                            res.CoMPARA_Anta_pred_neighbor(i,:)=cell(1,5);
                        end
                        if res.AD_index_CoMPARA_Bind(i)~=0
                            res.CoMPARA_Bind_CAS_neighbor(i,:)=BD_CAS(predBD.neighbors(i,:));
                            res.CoMPARA_Bind_InChiKey_neighbor(i,:)=train.COMPARA.model_BD.InChiKey(predBD.neighbors(i,:));
                            res.CoMPARA_Bind_DTXSID_neighbor(i,:)=BD_DTXSID(predBD.neighbors(i,:));
                            %res.CoMPARA_Bind_DSSTOXMPID_neighbor(i,:)=train.COMPARA.model_BD.DSSTOXMPID(predBD.neighbors(i,:));
                            res.CoMPARA_Bind_Exp_neighbor(i,:)=train.COMPARA.model_BD.set.class_Exp(predBD.neighbors(i,:));
                            res.CoMPARA_Bind_pred_neighbor(i,:)=train.COMPARA.model_BD.set.class_S(predBD.neighbors(i,:));
                        else
                            res.CoMPARA_Bind_CAS_neighbor(i,:)=cell(1,5);
                            res.CoMPARA_Bind_InChiKey_neighbor(i,:)=cell(1,5);
                            res.CoMPARA_Bind_DTXSID_neighbor(i,:)=cell(1,5);
                            %res.CoMPARA_Bind_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.CoMPARA_Bind_Exp_neighbor(i,:)=cell(1,5);
                            res.CoMPARA_Bind_pred_neighbor(i,:)=cell(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output(Locb(find(Locb))),'AG experimental= %s, AN experimental= %s, BD experimental= %s\n', res.CoMPARA_Ago_exp{i},res.CoMPARA_Anta_exp{i},res.CoMPARA_Bind_exp{i});
                        end
                        fprintf(output(Locb(find(Locb))),'AG predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CoMPARA_Ago_pred(i),res.AD_CoMPARA_Ago(i),res.AD_index_CoMPARA_Ago(i),res.Conf_index_CoMPARA_Ago(i));
                        fprintf(output(Locb(find(Locb))),'AN predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CoMPARA_Anta_pred(i),res.AD_CoMPARA_Anta(i),res.AD_index_CoMPARA_Anta(i),res.Conf_index_CoMPARA_Anta(i));
                        fprintf(output(Locb(find(Locb))),'BD category predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CoMPARA_Bind_pred(i),res.AD_CoMPARA_Bind(i),res.AD_index_CoMPARA_Bind(i),res.Conf_index_CoMPARA_Bind(i));
                        
                        if neighbors==1
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors (Agonist):%15s,%15s,%15s,%15s,%15s\n',train.COMPARA.model_AG.set.K, res.CoMPARA_Ago_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors (Antagonist):%15s,%15s,%15s,%15s,%15s\n',train.COMPARA.model_AN.set.K, res.CoMPARA_Anta_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors (Binding):%15s,%15s,%15s,%15s,%15s\n',train.COMPARA.model_BD.set.K, res.CoMPARA_Bind_CAS_neighbor{i,1:5});
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'AG experimental= %s, AN experimental= %s, BD experimental= %s\n', res.CoMPARA_Ago_exp{i},res.CoMPARA_Anta_exp{i},res.CoMPARA_Bind_exp{i});
                        end
                        fprintf(output,'AG predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CoMPARA_Ago_pred(i),res.AD_CoMPARA_Ago(i),res.AD_index_CoMPARA_Ago(i),res.Conf_index_CoMPARA_Ago(i));
                        fprintf(output,'AN predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CoMPARA_Anta_pred(i),res.AD_CoMPARA_Anta(i),res.AD_index_CoMPARA_Anta(i),res.Conf_index_CoMPARA_Anta(i));
                        fprintf(output,'BD category predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CoMPARA_Bind_pred(i),res.AD_CoMPARA_Bind(i),res.AD_index_CoMPARA_Bind(i),res.Conf_index_CoMPARA_Bind(i));
                        
                        if neighbors==1
                            fprintf(output,'CAS of the %i nearest neighbors (Agonist):%15s,%15s,%15s,%15s,%15s\n',train.COMPARA.model_AG.set.K, res.CoMPARA_Ago_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors (Antagonist):%15s,%15s,%15s,%15s,%15s\n',train.COMPARA.model_AN.set.K, res.CoMPARA_Anta_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors (Binding):%15s,%15s,%15s,%15s,%15s\n',train.COMPARA.model_BD.set.K, res.CoMPARA_Bind_CAS_neighbor{i,1:5});
                        end
                        
                    end
                end
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
                        %                         if exp
                        %                             T{end+1:end+nf,2:5}=nan(nf,4);
                        %                         else
                        %                             T{end+1:end+nf,1:4}=nan(nf,4);
                        %                         end
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        %Xtest=[XtestAG; XtestAN; XtestBD; XtestGHS; XtestLD50];
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    %Xtest=[XtestAG; XtestAN; XtestBD; XtestGHS; XtestLD50];
                    Xtest(:,ismember(Desc,DescNames))=[];
                    Desc(ismember(Desc,DescNames))=[];
                    DescNames=[DescNames Desc];
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.CoMPARA=res;
                    clear('res');
                    
                end
                % Clean memory
                clear('XinCDK_CoMPARA');
                clear('Xtest');
                clear('XtestAG');
                clear('XtestAN');
                clear('XtestBD');
                clear('predAG');
                clear('predAN');
                clear('predBD')
                clear('AD');
                train = rmfield(train, 'COMPARA');
                clear('AG_CAS');
                clear('AN_CAS');
                clear('BD_CAS');
                clear('AG_DTXSID');
                clear('AN_DTXSID');
                clear('BD_DTXSID');
                %end clean memory
            end
            
            %--------------------------------------------
            %Predict CATMoS endpoints
            %case {'CATMoS','AcuteTox'}
            [Lia,Locb] =ismember({'catmos','acutetox'},lower(prop));
            if find(Lia)
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Predicting Acute Oral Tox. endpoints (CATMoS)';
                Desc=train.CATMOS.DescIn;
                
                if strcmpi(ext,'.txt') && sep==0
                    fprintf(output,'\n\n\t\t\t\t\t Predicting CATMoS endpoints... \n\n			==============================================================  \n\n');
                end
                
                
                XinCDK_CATMoS=XinCDK(:,train.CATMOS.cdk_in);
                Xtest=[Xin(:,train.PadelVarIn(train.CATMOS.Padel_in)), XinCDK_CATMoS];

                %%% Check for NaN
                idx = all(isnan(Xtest), 1);
                if any(idx)
                    msgbox('Some CATMoS descriptors failed. Check input and repeat calculation.', 'Error','error');
                    return;
                end
                if any(any(isnan(Xtest)))
                    app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'WARNING: Some compounds failed CATMoS calculations. Check input and repeat calculation.'];
                end
                %%%
                
                XtestVT=Xtest(:,train.CATMOS.model_VT.DescVT_i);
                XtestNT=Xtest(:,train.CATMOS.model_NT.DescNT_i);
                XtestEPA=Xtest(:,train.CATMOS.model_EPA.DescEPA_i);
                XtestGHS=Xtest(:,train.CATMOS.model_GHS.DescGHS_i);
                XtestLD50=Xtest(:,train.CATMOS.model_LD50.DescLD50_i);

                if salt ==0
                    SaltIndex=zeros(size(Xtest,1),1);
                    
                    La=zeros(length(MoleculeNames),1);
                    Lb=zeros(length(MoleculeNames),1);
                    for i=1:length(MoleculeNames)
                        if ~contains(MoleculeNames(i),'AUTOGEN_')
                            if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,2});
                            elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,3});
                            elseif regexp(MoleculeNames{i},'DTXCID[0-9]+')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,4});
                            elseif regexp(MoleculeNames{i},'[A-Z]+-[A-Z]+-[A-Z]')
                                [La(i),Lb(i)] = ismember(MoleculeNames{i},train.DSSToxQSARr{:,5});
                            end
                            if La(i)
                                %salt=1;
                                SaltIndex(i,1)=train.DSSToxQSARr.SaltInfo(Lb(i));
                            end
                        end
                    end
                    if any(La)
                        salt=1;
                    end
                    if salt==1
                        app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'Salt info. was retrieved using the provided IDs'];
                        if any(~La)
                           app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'WARNING: Some IDs were not found!'];
                           app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; '(May affect predictions. Use Standardize)'];
                        end 
                    else
                        app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value; 'WARNING: No salt info found. May affect predictions. Use standardize or provide IDs'];
                    end
                end

                Xtest=[Xtest SaltIndex];
                Desc=[Desc,'SaltIndex'];
                
                predVT = knnpred2([XtestVT SaltIndex],train.CATMOS.model_VT.set.train,train.CATMOS.model_VT.set.class,train.CATMOS.model_VT.set.class_Exp+1,train.CATMOS.model_VT.set.K,train.CATMOS.model_VT.set.dist_type,train.CATMOS.model_VT.set.param.pret_type);
                %predVT.D=diag(predVT.D);
                predVT.D=single(predVT.D);
                predNT = knnpred2([XtestNT SaltIndex],train.CATMOS.model_NT.set.train,train.CATMOS.model_NT.set.class,train.CATMOS.model_NT.set.class_Exp+1,train.CATMOS.model_NT.set.K,train.CATMOS.model_NT.set.dist_type,train.CATMOS.model_NT.set.param.pret_type);
                %predNT.D=diag(predNT.D);
                predNT.D=single(predNT.D);
                predEPA = knnpred2([XtestEPA SaltIndex],train.CATMOS.model_EPA.set.train,train.CATMOS.model_EPA.set.class,train.CATMOS.model_EPA.set.class_Exp,train.CATMOS.model_EPA.set.K,train.CATMOS.model_EPA.set.dist_type,train.CATMOS.model_EPA.set.param.pret_type);
                %predEPA.D=diag(predEPA.D);
                predEPA.D=single(predEPA.D);
                predGHS = knnpred2([XtestGHS SaltIndex],train.CATMOS.model_GHS.set.train,train.CATMOS.model_GHS.set.class,train.CATMOS.model_GHS.set.class_Exp,train.CATMOS.model_GHS.set.K,train.CATMOS.model_GHS.set.dist_type,train.CATMOS.model_GHS.set.param.pret_type);
                %predGHS.D=diag(predGHS.D);
                predGHS.D=single(predGHS.D);
                predLD50 = nnrpred2([XtestLD50 SaltIndex],train.CATMOS.model_LD50.set.train,train.CATMOS.model_LD50.set.y,train.CATMOS.model_LD50.set.y_Exp_nAll,train.CATMOS.model_LD50.set.K,train.CATMOS.model_LD50.set.dist_type,train.CATMOS.model_LD50.set.param.pret_type);
                %predLD50.D=diag(predLD50.D);
                predLD50.D=single(predLD50.D);
                
                res.MoleculeID=MoleculeNames;
                %         if exp
                %             res.CATMoS_VT_exp=NaN(size(Xtest,1),1);
                %         end
                res.CATMoS_VT_pred(:,1)=predVT.class_pred-1;
                AD=classical_leverage(train.CATMOS.model_VT.set.train(:,1:end-1),XtestVT,'auto');
                res.AD_VT=abs(AD.inorout-1)';
                res.AD_index_VT=zeros(size(XtestVT,1),1);
                %                 res.AD_index_VT=1-test_pretreatment(predVT.dc(:,1),train.CATMOS.model_VT.set.dc_param);
                %                 res.AD_index_VT(find(res.AD_index_VT<0),1)=1./(1+predVT.dc(find(res.AD_index_VT<0),1));
                res.CATMoS_VT_pred(find(isnan(predVT.dc(:,1))))=NaN;
                res.AD_VT(find(isnan(predVT.dc(:,1))))=0;
                %                 res.AD_index_VT(find(isnan(predVT.dc(:,1))))=0;
                %                 res.AD_index_VT(find(res.AD_index_VT>0.9999),1)=1;
                %                 res.AD_VT(find(res.AD_index_VT>0.5))=1;
                res.Conf_index_VT=zeros(size(XtestVT,1),1);
                %         if exp
                %             res.CATMoS_NT_exp=NaN(size(Xtest,1),1);
                %         end
                res.CATMoS_NT_pred(:,1)=predNT.class_pred-1;
                AD=classical_leverage(train.CATMOS.model_NT.set.train(:,1:end-1),XtestNT,'auto');
                res.AD_NT=abs(AD.inorout-1)';
                res.AD_index_NT=zeros(size(XtestNT,1),1);
                %                 res.AD_index_NT=1-test_pretreatment(predNT.dc(:,1),train.CATMOS.model_NT.set.dc_param);
                %                 res.AD_index_NT(find(res.AD_index_NT<0),1)=1./(1+predNT.dc(find(res.AD_index_NT<0),1));
                res.CATMoS_NT_pred(find(isnan(predNT.dc(:,1))))=NaN;
                res.AD_NT(find(isnan(predNT.dc(:,1))))=0;
                %                 res.AD_index_NT(find(isnan(predNT.dc(:,1))))=0;
                %                 res.AD_index_NT(find(res.AD_index_NT>0.9999),1)=1;
                %                 res.AD_NT(find(res.AD_index_NT>0.5))=1;
                res.Conf_index_NT=zeros(size(XtestNT,1),1);
                %         if exp
                %             res.CATMoS_EPA_exp=NaN(size(Xtest,1),1);
                %         end
                res.CATMoS_EPA_pred(:,1)=predEPA.class_pred;
                AD=classical_leverage(train.CATMOS.model_EPA.set.train(:,1:end-1),XtestEPA,'auto');
                res.AD_EPA=abs(AD.inorout-1)';
                res.AD_index_EPA=zeros(size(XtestEPA,1),1);
                %                 res.AD_index_EPA=1-test_pretreatment(predEPA.dc(:,1),train.CATMOS.model_EPA.set.dc_param);
                %                 res.AD_index_EPA(find(res.AD_index_EPA<0),1)=1./(1+predEPA.dc(find(res.AD_index_EPA<0),1));
                res.CATMoS_EPA_pred(find(isnan(predEPA.dc(:,1))))=NaN;
                res.AD_EPA(find(isnan(predEPA.dc(:,1))))=0;
                %                 res.AD_index_EPA(find(isnan(predEPA.dc(:,1))))=0;
                %                 res.AD_index_EPA(find(res.AD_index_EPA>0.9999),1)=1;
                %                 res.AD_EPA(find(res.AD_index_EPA>0.5))=1;
                res.Conf_index_EPA=zeros(size(XtestEPA,1),1);
                %         if exp
                %             res.CATMoS_GHS_exp=NaN(size(Xtest,1),1);
                %         end
                res.CATMoS_GHS_pred(:,1)=predGHS.class_pred;
                AD=classical_leverage(train.CATMOS.model_GHS.set.train(:,1:end-1),XtestGHS,'auto');
                res.AD_GHS=abs(AD.inorout-1)';
                res.AD_index_GHS=zeros(size(XtestGHS,1),1);
                %                 res.AD_index_GHS=1-test_pretreatment(predGHS.dc(:,1),train.CATMOS.model_GHS.set.dc_param);
                %                 res.AD_index_GHS(find(res.AD_index_GHS<0),1)=1./(1+predGHS.dc(find(res.AD_index_GHS<0),1));
                res.CATMoS_GHS_pred(find(isnan(predGHS.dc(:,1))))=NaN;
                res.AD_GHS(find(isnan(predGHS.dc(:,1))))=0;
                %                 res.AD_index_GHS(find(isnan(predGHS.dc(:,1))))=0;
                %                 res.AD_index_GHS(find(res.AD_index_GHS>0.9999),1)=1;
                %                 res.AD_GHS(find(res.AD_index_GHS>0.5))=1;
                res.Conf_index_GHS=zeros(size(XtestGHS,1),1);
                if exp
                    %res.CATMoS_LD50_exp=NaN(size(Xtest,1),1);
                    res.CATMoS_LD50_exp=cell(size(Xtest,1),1);
                end
                res.CATMoS_LD50_pred(:,1)=predLD50.y_pred_weighted;
                res.CATMoS_LD50_predRange=cell(size(Xtest,1),1);
                AD=classical_leverage(train.CATMOS.model_LD50.set.train(:,1:end-1),XtestLD50,'auto');
                res.AD_LD50=abs(AD.inorout-1)';
                res.AD_index_LD50=zeros(size(XtestLD50,1),1);
                %                 res.AD_index_LD50=1-test_pretreatment(predLD50.dc(:,1),train.CATMOS.model_LD50.set.dc_param);
                %                 res.AD_index_LD50(find(res.AD_index_LD50<0),1)=1./(1+predLD50.dc(find(res.AD_index_LD50<0),1));
                res.CATMoS_LD50_pred(find(isnan(predLD50.dc(:,1))))=NaN;
                res.AD_LD50(find(isnan(predLD50.dc(:,1))))=0;
                %                 res.AD_index_LD50(find(isnan(predLD50.dc(:,1))))=0;
                %                 res.AD_index_LD50(find(res.AD_index_LD50>0.9999),1)=1;
                %                 res.AD_LD50(find(res.AD_index_LD50>0.5))=1;
                res.Conf_index_LD50=zeros(size(XtestLD50,1),1);
                
                for i=1:size(Xtest,1)
                    res.AD_index_VT(i,1)=1./(1+predVT.dc(i,~isnan(predVT.dc(i,:)))*predVT.w(i,~isnan(predVT.dc(i,:)))');
                    res.AD_index_VT(i,1)=0.5*res.AD_index_VT(i,1)+0.3*(~isnan(train.CATMOS.model_VT.set.class_Exp(predVT.neighbors(i,1),1)))+0.2*length(find(~isnan(train.CATMOS.model_VT.set.class_Exp(predVT.neighbors(i,:),1))))/5;
                    res.AD_index_NT(i,1)=1./(1+predNT.dc(i,~isnan(predNT.dc(i,:)))*predNT.w(i,~isnan(predNT.dc(i,:)))');
                    res.AD_index_NT(i,1)=0.5*res.AD_index_NT(i,1)+0.3*(~isnan(train.CATMOS.model_NT.set.class_Exp(predNT.neighbors(i,1),1)))+0.2*length(find(~isnan(train.CATMOS.model_NT.set.class_Exp(predNT.neighbors(i,:),1))))/5;
                    res.AD_index_EPA(i,1)=1./(1+predEPA.dc(i,~isnan(predEPA.dc(i,:)))*predEPA.w(i,~isnan(predEPA.dc(i,:)))');
                    res.AD_index_EPA(i,1)=0.5*res.AD_index_EPA(i,1)+0.3*(~isnan(train.CATMOS.model_EPA.set.class_Exp(predEPA.neighbors(i,1),1)))+0.2*length(find(~isnan(train.CATMOS.model_EPA.set.class_Exp(predEPA.neighbors(i,:),1))))/5;
                    res.AD_index_GHS(i,1)=1./(1+predGHS.dc(i,~isnan(predGHS.dc(i,:)))*predGHS.w(i,~isnan(predGHS.dc(i,:)))');
                    res.AD_index_GHS(i,1)=0.5*res.AD_index_GHS(i,1)+0.3*(~isnan(train.CATMOS.model_GHS.set.class_Exp(predGHS.neighbors(i,1),1)))+0.2*length(find(~isnan(train.CATMOS.model_GHS.set.class_Exp(predGHS.neighbors(i,:),1))))/5;
                    res.AD_index_LD50(i,1)=1./(1+predLD50.dc(i,~isnan(predLD50.dc(i,:)))*predLD50.w(i,~isnan(predLD50.dc(i,:)))');
                    res.AD_index_LD50(i,1)=0.5*res.AD_index_LD50(i,1)+0.3*(~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,1),1)))+0.2*length(find(~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,:),1))))/5;
                    
                    %             Li_vt=0;
                    %             Li_nt=0;
                    %             Li_epa=0;
                    %             Li_ghs=0;
                    Li_ld50=0;
                    Lo_ld50=0;
                    if exp 
                        res.CATMoS_LD50_exp(i,1)={'NA'};
                        if ~contains(MoleculeNames(i),'AUTOGEN_')
                        if regexp(MoleculeNames{i},'[0-9]+-[0-9]+-[0-9]')
                            %                     [Li_vt,Lo_vt] = ismember(MoleculeNames(i),train.CATMOS.model_VT.CAS);
                            %                     [Li_nt,Lo_nt] = ismember(MoleculeNames(i),train.CATMOS.model_NT.CAS);
                            %                     [Li_epa,Lo_epa] = ismember(MoleculeNames(i),train.CATMOS.model_EPA.CAS);
                            %                     [Li_ghs,Lo_ghs] = ismember(MoleculeNames(i),train.CATMOS.model_GHS.CAS);
                            [Li_ld50,Lo_ld50] = ismember(MoleculeNames(i),train.CATMOS.model_LD50.CAS);
                        elseif regexp(MoleculeNames{i},'DTXSID[0-9]+')
                            %                     [Li_vt,Lo_vt] = ismember(MoleculeNames{i},train.CATMOS.model_VT.DTXSID);
                            %                     [Li_nt,Lo_nt] = ismember(MoleculeNames{i},train.CATMOS.model_NT.DTXSID);
                            %                     [Li_epa,Lo_epa] = ismember(MoleculeNames{i},train.CATMOS.model_EPA.DTXSID);
                            %                     [Li_ghs,Lo_ghs] = ismember(MoleculeNames{i},train.CATMOS.model_GHS.DTXSID);
                            [Li_ld50,Lo_ld50] = ismember(MoleculeNames{i},train.CATMOS.model_LD50.DTXSID);
                        end
                        %                 if Li_vt
                        %                     res.CATMoS_VT_exp(i,1)=train.CATMOS.model_VT.set.class_Exp(Lo_vt);
                        %                 end
                        %                 if Li_nt
                        %                     res.CATMoS_NT_exp(i,1)=train.CATMOS.model_NT.set.class_Exp(Lo_nt);
                        %                 end
                        %                 if Li_epa
                        %                     res.CATMoS_EPA_exp(i,1)=train.CATMOS.model_EPA.set.class_Exp(Lo_epa);
                        %                 end
                        %                 if Li_ghs
                        %                     res.CATMoS_GHS_exp(i,1)=train.CATMOS.model_GHS.set.class_Exp(Lo_ghs);
                        %                 end
                        if Li_ld50
                            % res.AD_VT(i)=1;
                            % res.AD_index_VT(i,1)=1;
                            % %res.CATMoS_VT_pred(i,1)=train.CATMOS.model_VT.set.class(Lo_ld50)-1;
                            % res.AD_NT(i)=1;
                            % res.AD_index_NT(i,1)=1;
                            % %res.CATMoS_NT_pred(i,1)=train.CATMOS.model_NT.set.class(Lo_ld50)-1;
                            % res.AD_EPA(i)=1;
                            % res.AD_index_EPA(i,1)=1;
                            % %res.CATMoS_EPA_pred(i,1)=train.CATMOS.model_EPA.set.class(Lo_ld50);
                            % res.AD_GHS(i)=1;
                            % res.AD_index_GHS(i,1)=1;
                            % %res.CATMoS_GHS_pred(i,1)=train.CATMOS.model_GHS.set.class(Lo_ld50);
                            % res.AD_LD50(i)=1;
                            % res.AD_index_LD50(i,1)=1;
                            % %res.CATMoS_LD50_pred(i,1)=train.CATMOS.model_LD50.set.y(Lo_ld50);
                            %if isnan(CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,1),1))
                                %res.CATMoS_LD50_pred(i,1)=CATMOS.model_LD50.set.y_Exp_nAll(Lo_ld50);
                            %end
                            %if exp
                                res.CATMoS_LD50_exp(i,1)=train.CATMOS.model_LD50.set.y_Exp(Lo_ld50);
                            %end
                        % else
                        %     %if exp
                        %         res.CATMoS_LD50_exp(i,1)={'NA'};
                        %     %end
                        end
                        end
                    end
                    %res.AD_index_VT(i,1)=1./(1+predVT.dc(i,1)*predVT.w(i,~isnan(predVT.dc(i,1)))');
                    
                    res.Conf_index_VT(i,1)=(train.CATMOS.model_VT.conc_VT(predVT.neighbors(i,:),1)'*predVT.w(i,:)'+res.AD_index_VT(i))/2;
                    if res.AD_index_VT(i)==0
                        res.Conf_index_VT(i,1)=0;
                    end
                    res.Conf_index_NT(i,1)=(train.CATMOS.model_NT.conc_NT(predNT.neighbors(i,:),1)'*predNT.w(i,:)'+res.AD_index_NT(i))/2;
                    if res.AD_index_NT(i)==0
                        res.Conf_index_NT(i,1)=0;
                    end
                    res.Conf_index_EPA(i,1)=(train.CATMOS.model_EPA.conc_EPA(predEPA.neighbors(i,:),1)'*predEPA.w(i,:)'+res.AD_index_EPA(i))/2;
                    if res.AD_index_EPA(i)==0
                        res.Conf_index_EPA(i,1)=0;
                    end
                    res.Conf_index_GHS(i,1)=(train.CATMOS.model_GHS.conc_GHS(predGHS.neighbors(i,:),1)'*predGHS.w(i,:)'+res.AD_index_GHS(i))/2;
                    if res.AD_index_GHS(i)==0
                        res.Conf_index_GHS(i,1)=0;
                    end
                    res.Conf_index_LD50(i,1)=(train.CATMOS.model_LD50.conc_LD50(predLD50.neighbors(i,:),1)'*predLD50.w(i,:)'+res.AD_index_LD50(i))/2;
                    if res.AD_index_LD50(i)==0
                        res.Conf_index_LD50(i,1)=0;
                    end
                    
                    %----Pre-WoE endpoint confidence coverage adjustment----
                    % [res, endpointConfCovDiag] = conf_index_adjust(res, i, CATMOS, predVT, predNT, predEPA, predGHS, predLD50, ...
                    %     'BonusThreshold', 3, 'BonusMax', 0.1, 'PenaltyMax', 0.1, 'EffectPower', 1.0);
                    [res, endpointConfCovDiag] = conf_index_adjustv2(res, i, train.CATMOS, predVT, predNT, predEPA, predGHS, predLD50, ...
                        'EffectMode', 'blend','BlendStrength', 0.25,'BonusThreshold', 3, 'EffectPower', 1.0);


                    %WOE corrections
                    %res.CATMoS_LD50_pred_i(i,1)=10^res.CATMoS_LD50_pred(i);
                    %res.CATMoS_LD50_range{i,1}='';
                    res=woe_corrV76(res,i);
                    %------------

                    %----Identify nearest neighbors----
                    neighborsIDs = [];
                    neighborsW  = [];
                    neighborsSrc = {};

                    winModels = res.CATMoS_winning_model_names{i,1};

                    if ismember('LD50',winModels)

                        neighborsIDs = predLD50.neighbors(i,:);
                        neighborsW  = predLD50.w(i,:);
                        neighborsSrc = repmat({'LD50'},1,numel(neighborsIDs));
                        neighborsScore = neighborsW;
                        neighborsDC = predLD50.dc(i,:);

                    else
                        [neighborsIDs, neighborsSrc, neighborsScore, neighborsDC, neighborsW] = catmos_consensus_neighbors_with_weights(res,i,predVT,predNT,predEPA,predGHS,predLD50);
                    end

                   %res.CATMoS_consensus_neighbors_source{i,1} = neighborsSrc;

                    res=rmfield(res,{'CATMoS_winning_model_names','CATMoS_winning_model_idx'});

                    %-----------------------
                     % --- Safe neighbor output guard ---
                    nN = 5;
                    neighborsIDs = neighborsIDs(:)';

                    badNeighbors = isempty(neighborsIDs) || ...
                        numel(neighborsIDs) < nN || ...
                        any(~isfinite(neighborsIDs)) || ...
                        any(neighborsIDs < 1) || ...
                        any(neighborsIDs ~= floor(neighborsIDs)) || ...
                        any(neighborsIDs > numel(train.CATMOS.model_LD50.CAS));

                    %------------


                    %res.CATMoS_LD50_predRange{i,1}=strcat('[',num2str(floor(10^(res.CATMoS_LD50_pred(i)-0.3))),'-',num2str(ceil(10^(res.CATMoS_LD50_pred(i)+0.3))),']');
                    if  res.CATMoS_LD50_pred(i)<=2.3
                        res.CATMoS_LD50_predRange{i,1}=strcat('[',num2str(round(10^(res.CATMoS_LD50_pred(i)-0.25),1,'significant')),':',num2str(round(10^(res.CATMoS_LD50_pred(i)+0.25),2,'significant')),']');
                    else
                        res.CATMoS_LD50_predRange{i,1}=strcat('[',num2str(round(10^(res.CATMoS_LD50_pred(i)-0.25),2,'significant')),':',num2str(round(10^(res.CATMoS_LD50_pred(i)+0.25),2,'significant')),']');
                    end

                    if 10^(res.CATMoS_LD50_pred(i))>=5
                        res.CATMoS_LD50_pred(i)=round(10^res.CATMoS_LD50_pred(i));
                    else
                        res.CATMoS_LD50_pred(i)=round(10^res.CATMoS_LD50_pred(i),1);
                    end
                    %%%%%%%%%%Alter AD index and AD%%%%%%%%%
                    % if Li_ld50 || (predLD50.dc(i,1)==0 && predLD50.w(i,1)==1)
                    %     res.AD_CATMoS(i,1)=1;
                    %     res.AD_index_CATMoS(i,1)=1;
                    % end
                    

                    %%%%%%%%%%---Alter Conf_index
                    %old formula V2.9.2
%                     if predLD50.w(i,1)==1 && ~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,1)))
%                         Std_index_CATMoS=1;
%                     elseif isnan(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,1))) && length(find(~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,:))))) <= 1
%                         Std_index_CATMoS=0;
%                     elseif ~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,1))) && length(find(~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,:))))) <= 1
%                         Std_index_CATMoS=1-(std([log10(res.CATMoS_LD50_pred(i)), train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,1))],[1,predLD50.w(i,1)]))/std(train.CATMOS.model_LD50.set.y_Exp_nAll(find(~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll))));
%                     elseif sum(predLD50.w(i,find(~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,:))))))==0
%                         Std_index_CATMoS=(1-(std(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,find(~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,:)))))))/std(train.CATMOS.model_LD50.set.y_Exp_nAll(find(~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll))))));
%                     else
%                         Std_index_CATMoS=(1-(std(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,find(~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,:)))))),predLD50.w(i,find(~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll(predLD50.neighbors(i,:))))))/std(train.CATMOS.model_LD50.set.y_Exp_nAll(find(~isnan(train.CATMOS.model_LD50.set.y_Exp_nAll))))));
%                     end
% %                     if res.AD_index_CATMoS(i,1)==1
% %                         res.Conf_index_CATMoS(i,1)=max(0.6*res.Conf_index_CATMoS(i,1)+0.4*(1-(std(CATMOS.model_LD50.set.y(predLD50.neighbors(i,:)),predLD50.w(i,:))/std(CATMOS.model_LD50.set.y))),0.1);
% %                     else
%                          res.Conf_index_CATMoS(i,1)=max(0.5*res.Conf_index_CATMoS(i,1)+0.3*Std_index_CATMoS+0.2*(1-(std(train.CATMOS.model_LD50.set.y(predLD50.neighbors(i,:)),predLD50.w(i,:))/std(train.CATMOS.model_LD50.set.y))),0.1);
% %                     end

                    [res, nnConfDiag] = catmos_conf_index_adjuster_v10(res, i, train.CATMOS, neighborsIDs, neighborsW, 'NeighborScore', neighborsScore, 'WeightMode', 'auto', 'UsePredExpAgreement', true,'PredExpMode', 'bin_overlap');
                    [res, adDiag] = catmos_ad_index_adjuster(res, i,predVT, predNT, predEPA, predGHS, predLD50, neighborsIDs, neighborsSrc,'NeighborScore', neighborsScore,'NeighborsDC', neighborsDC);


                    if res.AD_index_CATMoS(i,1)>=0.6 && res.Conf_index_CATMoS(i,1)>=0.5
                        res.AD_CATMoS(i,1)=1;
                    elseif res.AD_index_CATMoS(i,1)<0.2 && res.Conf_index_CATMoS(i,1)<0.5
                        res.AD_CATMoS(i,1)=0;
                    end
                    if isnan(res.AD_CATMoS(i,1))
                        res.AD_CATMoS(i,1)=0;
                    end
                    

                    if salt==1 && isempty(FileSalt)
                        if La(i)==0
                            res.AD_index_CATMoS(i)=0.75*res.AD_index_CATMoS(i);
                            res.Conf_index_CATMoS(i,1)=0.75*res.Conf_index_CATMoS(i,1);
                        end
                    end

                    if Xin(i,12)==0
                        res.AD_CATMoS(i)=0;
                        res.AD_index_CATMoS(i)=res.AD_index_CATMoS(i)/2;
                        res.Conf_index_CATMoS(i,1)=res.Conf_index_CATMoS(i,1)/2;
                    end

                    res.AD_index_CATMoS(i,1)=round(res.AD_index_CATMoS(i,1),2);
                    res.Conf_index_CATMoS(i,1)=round(res.Conf_index_CATMoS(i,1),2);

                    if  isnan(res.CATMoS_LD50_pred(i)) || isempty(find(~isnan(predVT.dc(i,:)), 1)) || isempty(find(~isnan(predNT.dc(i,:)), 1)) || isempty(find(~isnan(predEPA.dc(i,:)), 1)) || isempty(find(~isnan(predGHS.dc(i,:)), 1)) || isempty(find(~isnan(predLD50.dc(i,:)), 1))
                        res.CATMoS_VT_pred(i)=NaN;
                        res.CATMoS_NT_pred(i)=NaN;
                        res.CATMoS_GHS_pred(i)=NaN;
                        res.CATMoS_EPA_pred(i)=NaN;
                        res.CATMoS_LD50_pred(i)=NaN;
                        res.AD_CATMoS(i,1)=0;
                        res.AD_index_CATMoS(i)=0;
                        res.Conf_index_CATMoS(i,1)=0;
                        res.CATMoS_LD50_predRange{i,1}='NA';
                    end
                    
                    %neighbors
                    if neighbors==1
                        %                 if res.AD_index_VT(i,:)~=0
                        %                     %res.VT_CATMoS_ID_neighbor(i,:)=train.CATMOS.model_VT.ChemID(predVT.neighbors(i,:));
                        %                     res.VT_CAS_neighbor(i,:)=train.CATMOS.model_VT.CAS(predVT.neighbors(i,:));
                        %                     res.VT_InChiKey_neighbor(i,:)=train.CATMOS.model_VT.InChiKey(predVT.neighbors(i,:));
                        %                     res.VT_DTXSID_neighbor(i,:)=train.CATMOS.model_VT.DTXSID(predVT.neighbors(i,:));
                        %                     %res.VT_DSSTOXMPID_neighbor(i,:)=train.CATMOS.model_VT.DSSTOXMPID(pred.neighbors(i,:));
                        %                     res.VT_Exp_neighbor(i,:)=train.CATMOS.model_VT.set.class_Exp(predVT.neighbors(i,:));
                        %                     res.VT_pred_neighbor(i,:)=train.CATMOS.model_VT.set.class(predVT.neighbors(i,:))-1;
                        %                 else
                        %                     res.VT_CAS_neighbor(i,:)=cell(1,5);
                        %                     res.VT_InChiKey_neighbor(i,:)=cell(1,5);
                        %                     res.VT_DTXSID_neighbor(i,:)=cell(1,5);
                        %                     %res.VT_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                        %                     res.VT_Exp_neighbor(i,:)=nan(1,5);
                        %                     res.VT_pred_neighbor(i,:)=nan(1,5);
                        %                 end
                        %                 if res.AD_index_NT(i,:)~=0
                        %                     %res.NT_CATMoS_ID_neighbor(i,:)=train.CATMOS.model_NT.ChemID(predNT.neighbors(i,:));
                        %                     res.NT_CAS_neighbor(i,:)=train.CATMOS.model_NT.CAS(predNT.neighbors(i,:));
                        %                     res.NT_InChiKey_neighbor(i,:)=train.CATMOS.model_NT.InChiKey(predNT.neighbors(i,:));
                        %                     res.NT_DTXSID_neighbor(i,:)=train.CATMOS.model_NT.DTXSID(predNT.neighbors(i,:));
                        %                     %res.NT_DSSTOXMPID_neighbor(i,:)=train.CATMOS.model_NT.DSSTOXMPID(pred.neighbors(i,:));
                        %                     res.NT_Exp_neighbor(i,:)=train.CATMOS.model_NT.set.class_Exp(predNT.neighbors(i,:));
                        %                     res.NT_pred_neighbor(i,:)=train.CATMOS.model_NT.set.class(predNT.neighbors(i,:))-1;
                        %                 else
                        %                     res.NT_CAS_neighbor(i,:)=cell(1,5);
                        %                     res.NT_InChiKey_neighbor(i,:)=cell(1,5);
                        %                     res.NT_DTXSID_neighbor(i,:)=cell(1,5);
                        %                     %res.NT_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                        %                     res.NT_Exp_neighbor(i,:)=nan(1,5);
                        %                     res.NT_pred_neighbor(i,:)=nan(1,5);
                        %                 end
                        %                 if res.AD_index_EPA(i,:)~=0
                        %                     %res.EPA_CATMoS_ID_neighbor(i,:)=train.CATMOS.model_EPA.ChemID(predEPA.neighbors(i,:));
                        %                     res.EPA_CAS_neighbor(i,:)=train.CATMOS.model_EPA.CAS(predEPA.neighbors(i,:));
                        %                     res.EPA_InChiKey_neighbor(i,:)=train.CATMOS.model_EPA.InChiKey(predEPA.neighbors(i,:));
                        %                     res.EPA_DTXSID_neighbor(i,:)=train.CATMOS.model_EPA.DTXSID(predEPA.neighbors(i,:));
                        %                     %res.EPA_DSSTOXMPID_neighbor(i,:)=train.CATMOS.model_EPA.DSSTOXMPID(predEPA.neighbors(i,:));
                        %                     res.EPA_Exp_neighbor(i,:)=train.CATMOS.model_EPA.set.class_Exp(predEPA.neighbors(i,:));
                        %                     res.EPA_pred_neighbor(i,:)=train.CATMOS.model_EPA.set.class(predEPA.neighbors(i,:));
                        %                 else
                        %                     res.EPA_CAS_neighbor(i,:)=cell(1,5);
                        %                     res.EPA_InChiKey_neighbor(i,:)=cell(1,5);
                        %                     res.EPA_DTXSID_neighbor(i,:)=cell(1,5);
                        %                     %res.EPA_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                        %                     res.EPA_Exp_neighbor(i,:)=nan(1,5);
                        %                     res.EPA_pred_neighbor(i,:)=nan(1,5);
                        %                 end
                        %                 if res.AD_index_GHS(i,:)~=0
                        %                     %res.GHS_CATMoS_ID_neighbor(i,:)=train.CATMOS.model_GHS.ChemID(predGHS.neighbors(i,:));
                        %                     res.GHS_CAS_neighbor(i,:)=train.CATMOS.model_GHS.CAS(predGHS.neighbors(i,:));
                        %                     res.GHS_InChiKey_neighbor(i,:)=train.CATMOS.model_GHS.InChiKey(predGHS.neighbors(i,:));
                        %                     res.GHS_DTXSID_neighbor(i,:)=train.CATMOS.model_GHS.DTXSID(predGHS.neighbors(i,:));
                        %                     %res.GHS_DSSTOXMPID_neighbor(i,:)=train.CATMOS.model_GHS.DSSTOXMPID(pred.neighbors(i,:));
                        %                     res.GHS_Exp_neighbor(i,:)=train.CATMOS.model_GHS.set.class_Exp(predGHS.neighbors(i,:));
                        %                     res.GHS_pred_neighbor(i,:)=train.CATMOS.model_GHS.set.class(predGHS.neighbors(i,:));
                        %                 else
                        %                     res.GHS_CAS_neighbor(i,:)=cell(1,5);
                        %                     res.GHS_InChiKey_neighbor(i,:)=cell(1,5);
                        %                     res.GHS_DTXSID_neighbor(i,:)=cell(1,5);
                        %                     %res.GHS_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                        %                     res.GHS_Exp_neighbor(i,:)=nan(1,5);
                        %                     res.GHS_pred_neighbor(i,:)=nan(1,5);
                        %                 end
                        %                 CATMOS.model_LD50.CAS=strrep(strrep(join(CATMOS.model_LD50.CAS,'|',2),'|||',''),'||','');
                        %                 CATMOS.model_LD50.DTXSID=strrep(strrep(join(CATMOS.model_LD50.DTXSID,'|',2),'|||',''),'||','');
                        if res.AD_index_LD50(i,:)~=0 && ~badNeighbors
                            %res.LD50_CATMoS_ID_neighbor(i,:)=train.CATMOS.model_LD50.ChemID(predLD50.neighbors(i,:));
                            res.CAS_neighbor(i,:)=train.CATMOS.model_LD50.CAS(neighborsIDs);
                            res.InChiKey_neighbor(i,:)=train.CATMOS.model_LD50.InChiKey(neighborsIDs);
                            res.DTXSID_neighbor(i,:)=train.CATMOS.model_LD50.DTXSID(neighborsIDs);
                            %res.LD50_DSSTOXMPID_neighbor(i,:)=train.CATMOS.model_LD50.DSSTOXMPID(pred.neighbors(i,:));
                            res.LD50_Exp_neighbor(i,:)=train.CATMOS.model_LD50.set.y_Exp(neighborsIDs);
                            res.LD50_pred_neighbor(i,:)=round(10.^(train.CATMOS.model_LD50.set.y(neighborsIDs)),2);
                        else
                            res.CAS_neighbor(i,:)=cell(1,5);
                            res.InChiKey_neighbor(i,:)=cell(1,5);
                            res.DTXSID_neighbor(i,:)=cell(1,5);
                            %res.LD50_DSSTOXMPID_neighbor(i,:)=cell(1,5);
                            res.LD50_Exp_neighbor(i,:)=cell(1,5);
                            res.LD50_pred_neighbor(i,:)=nan(1,5);
                        end
                    end
                    
                    if strcmpi(ext,'.txt') && sep==1
                        %res.Xtest=Xtest;
                        fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            %fprintf(output(Locb(find(Locb))),'LD50 experimental= %.3f\n', res.CATMoS_LD50_exp(i));
                            fprintf(output(Locb(find(Locb))),'LD50 experimental= %s\n', res.CATMoS_LD50_exp{i,1});
                        end
                        %                 fprintf(output(Locb(find(Locb))),'VT predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_VT_pred(i),res.AD_VT(i),res.AD_index_VT(i),res.Conf_index_VT(i));
                        %                 fprintf(output(Locb(find(Locb))),'NT predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_NT_pred(i),res.AD_NT(i),res.AD_index_NT(i),res.Conf_index_NT(i));
                        %                 fprintf(output(Locb(find(Locb))),'EPA category predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_EPA_pred(i),res.AD_EPA(i),res.AD_index_EPA(i),res.Conf_index_EPA(i));
                        %                 fprintf(output(Locb(find(Locb))),'GHS category predicted= %i, AD: %i,AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_GHS_pred(i),res.AD_GHS(i),res.AD_index_GHS(i),res.Conf_index_GHS(i));
                        %                 fprintf(output(Locb(find(Locb))),'LD50 predicted= %.3f, AD: %i,AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_LD50_pred(i),res.AD_LD50(i),res.AD_index_LD50(i),res.Conf_index_LD50(i));
                        fprintf(output(Locb(find(Locb))),'VT predicted= %i, NT predicted= %i, EPA category predicted= %i, GHS category predicted= %i, LD50 predicted= %.2f\n', res.CATMoS_VT_pred(i),...
                            res.CATMoS_NT_pred(i),res.CATMoS_EPA_pred(i),res.CATMoS_GHS_pred(i),res.CATMoS_LD50_pred(i));
                        
                        fprintf(output(Locb(find(Locb))),'AD: %i,AD_index= %.2f, Conf_index= %.2f\n',res.AD_CATMoS(i),res.AD_index_CATMoS(i),res.Conf_index_CATMoS(i));
                        if neighbors==1
                            %                     fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMOS.model_VT.set.K, res.VT_CAS_neighbor{i,1:5});
                            %                     fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMOS.model_NT.set.K, res.NT_CAS_neighbor{i,1:5});
                            %                     fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMOS.model_EPA.set.K, res.EPA_CAS_neighbor{i,1:5});
                            %                     fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMOS.model_GHS.set.K, res.GHS_CAS_neighbor{i,1:5});
                            fprintf(output(Locb(find(Locb))),'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMOS.model_LD50.set.K, res.CAS_neighbor{i,1:5});
                        end
                        
                    elseif strcmpi(ext,'.txt') && sep==0
                        fprintf(output,'\t Molecule %s:\n', MoleculeNames{i});
                        if exp
                            fprintf(output,'LD50 experimental= %s\n',res.CATMoS_LD50_exp{i,1});
                        end
                        %                 fprintf(output,'VT predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_VT_pred(i),res.AD_VT(i),res.AD_index_VT(i),res.Conf_index_VT(i));
                        %                 fprintf(output,'NT predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_NT_pred(i),res.AD_NT(i),res.AD_index_NT(i),res.Conf_index_NT(i));
                        %                 fprintf(output,'EPA category predicted= %i, AD: %i, AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_EPA_pred(i),res.AD_EPA(i),res.AD_index_EPA(i),res.Conf_index_EPA(i));
                        %                 fprintf(output,'GHS category predicted= %i, AD: %i,AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_GHS_pred(i),res.AD_GHS(i),res.AD_index_GHS(i),res.Conf_index_GHS(i));
                        %                 fprintf(output,'LD50 predicted= %.3f, AD: %i,AD_index= %.2f, Conf_index= %.2f\n', res.CATMoS_LD50_pred(i),res.AD_LD50(i),res.AD_index_LD50(i),res.Conf_index_LD50(i));
                        fprintf(output,'VT predicted= %i, NT predicted= %i, EPA category predicted= %i, GHS category predicted= %i, LD50 predicted= %.2f\n',res.CATMoS_VT_pred(i),...
                            res.CATMoS_NT_pred(i),res.CATMoS_EPA_pred(i),res.CATMoS_GHS_pred(i),res.CATMoS_LD50_pred(i));
                        
                        fprintf(output,'AD: %i,AD_index= %.2f, Conf_index= %.2f\n',res.AD_CATMoS(i),res.AD_index_CATMoS(i),res.Conf_index_CATMoS(i));
                        
                        if neighbors==1
                            %                     fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMOS.model_VT.set.K, res.VT_CAS_neighbor{i,1:5});
                            %                     fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMOS.model_NT.set.K, res.NT_CAS_neighbor{i,1:5});
                            %                     fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMOS.model_EPA.set.K, res.EPA_CAS_neighbor{i,1:5});
                            %                     fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMOS.model_GHS.set.K, res.GHS_CAS_neighbor{i,1:5});
                            fprintf(output,'CAS of the %i nearest neighbors:%15s,%15s,%15s,%15s,%15s\n',train.CATMOS.model_LD50.set.K, res.CAS_neighbor{i,1:5});
                        end
                        
                    end
                end
                
                res=rmfield(res,{'AD_VT','AD_index_VT','Conf_index_VT','AD_NT','AD_index_NT','Conf_index_NT','AD_EPA','AD_index_EPA','Conf_index_EPA',...
                    'AD_GHS','AD_index_GHS','Conf_index_GHS','AD_LD50','AD_index_LD50','Conf_index_LD50'});
                
                if nf>0 && strcmpi(ext,'.txt')
                    if sep==1
                        for i=(f+1):(f+nf)
                            fprintf(output(Locb(find(Locb))),'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output(Locb(find(Locb))),'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    elseif sep==0
                        for i=(f+1):(f+nf)
                            fprintf(output,'\t Molecule %s:\n', res.MoleculeID{i});
                            fprintf(output,'\t FoundBy: %s\n\n', FoundBy{i});
                        end
                    end
                end
                if sep==1 && strcmpi(ext,'.csv')
                    if nf>0
                        res=rmfield(res,'MoleculeID');
                        T=struct2table(res);
%                         T{end+1:end+nf,1:4}=nan(nf,4);
                        T{end+1:end+nf,4}=nan(nf,1);
                        for i=length(T{1:end-nf+1,1}):length(T{:,1})
                            for j=1:size(T(1,:),2)
                                if isnumeric(T{i,j})
                                    T{i,j}=nan;
                                else
                                    T{i,j}={'NA'};
                                end
                            end
                        end
                        %T{end-nf:end,1:4}(T{end-nf:end,1:4}==0)=nan;
                        %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                        T=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) T];
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    else
                        T=struct2table(res);
                    end
                    if printtDesc==1
                        %Xtest=[XtestVT; XtestNT; XtestEPA; XtestGHS; XtestLD50];
                        Xtest=array2table(Xtest,'VariableNames',Desc);
                        %Xtest=array2table(XtestLD50);
                        T=[T Xtest];
                        res.Descriptors=Xtest;
                    end
                    writetable(T,FileOut{Locb(find(Locb))},'Delimiter',',');%,'QuoteStrings',true);
                    fclose(output(Locb(find(Locb))));
                    clear('T');
                    
                elseif sep==0 && printtDesc==1 && strcmpi(ext,'.csv')
                    if nf>0
                        Xtest(end+1:end+nf,:)=nan(nf,size(Xtest,2));
                    end
                    %Xtest=[XtestVT; XtestNT; XtestEPA; XtestGHS; XtestLD50];
                    Xtest(:,ismember(Desc,DescNames))=[];
                    Desc(ismember(Desc,DescNames))=[];
                    DescNames=[DescNames Desc];
                    DescMat=[DescMat Xtest];
                end
                
                if sep==1
                    resf.CATMoS=res;
                    clear('res');
                    
                end
                % Clean memory
                clear('XinCDK_CATMoS');
                clear('Xtest');
                clear('XtestVT');
                clear('XtestNT');
                clear('XtestEPA');
                clear('XtestGHS');
                clear('XtestLD50');
                clear('predVT');
                clear('predNT');
                clear('predEPA');
                clear('predGHS');
                clear('predLD50');
                clear('AD');
                %train = rmfield(train, 'CATMOS');
                %end clean memory
            end
            %--------------------------------------------------------------------------
            %========== End Of Calculation ==========
%             if isdeployed
%                 resp=res;
%             end
            if sep==0 && strcmpi(ext,'.csv')
                step=step+1;
                d.Value=step/steps;
                if d.CancelRequested
                    msgbox('Canceled by user.', 'Error','error');
                    return;
                end
                d.Message='Writing output file';
                if nf>0
                    res=rmfield(res,'MoleculeID');
                    res=struct2table(res);
%                     if exp && (strcmpi('cerapp',prop(1))||strcmpi('compara',prop(1))||strcmpi('er',prop(1))||strcmpi('ar',prop(1)))
%                         res{end+1:end+nf,2:5}=nan(nf,4);
%                     else
%                         res{end+1:end+nf,1:4}=nan(nf,4);
%                     end
%                     if neighbors==0 && ~any(ismember({'catmos','acutetox'},lower(prop)))&& ((~any(ismember({'cerapp','er','compara','ar'},lower(prop)))&&exp)|| (any(ismember({'cerapp','er','compara','ar'},lower(prop)))&&~exp))
%                         res{end-nf:end,:}(res{end-nf:end,:}==0)=nan;
%                     end
%                     %T{end-nf:end,find(isnumeric(T{end-nf:end,:}))};
                    res{end+1:end+nf,4}=nan(nf,1);
                    for i=length(res{1:end-nf+1,1}):length(res{:,1})
                        for j=1:size(res(1,:),2)
                            if isnumeric(res{i,j})
                                res{i,j}=nan;
                            else
                                res{i,j}={'NA'};
                            end
                        end
                    end
                    res=[array2table(MoleculeNames,'VariableNames',{'MoleculeID'}) array2table(FoundBy,'VariableNames',{'FoundBy'}) res];
                else
                    res=struct2table(res);
                end
                if printtDesc==1
                    
                    DescMat=array2table(DescMat,'VariableNames',DescNames);
                    res=[res DescMat];
                end
                writetable(res,FileOut,'Delimiter',',');
                %fclose('all');
            end
            %app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'Predicted structures: ' num2str(length(MoleculeNames)) ' (' num2str(round(toc(timerCalc))) ' sec)'];
            app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'Predicted structures: ' num2str(size(Xin,1)) ' (' num2str(round(toc(timerCalc))) ' sec)'];
            %if sep==1
            %    res=resf;
            %end
            fclose('all');
            tElapsed = toc(timerAll);
            %app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'Total processing time: ' num2str(round(tElapsed,2)) ' seconds.'];
            app.ResultssummaryTextArea.Value=[app.ResultssummaryTextArea.Value;'Total processing time: ' char(duration(0,0,tElapsed)) '.'];
            myicon = imread('Icon_ba.png');
            %msgbox(['Calculations done in: ', num2str(round(tElapsed,2)),' seconds.'],'Success!','custom',myicon);
            msgbox(['Calculations done in: ', char(duration(0,0,tElapsed)),'.'],'Success!','custom',myicon);
        end

        % Value changed function: PhyschempropertiesCheckBox
        function PhyschempropertiesCheckBoxValueChanged(app, event)
            value = app.PhyschempropertiesCheckBox.Value;
            if value
                app.LogPCheckBox.Value=1;
                app.MPCheckBox.Value=1;
                app.BPCheckBox.Value=1;
                app.VPCheckBox.Value=1;
                app.WSCheckBox.Value=1;
                app.HLCheckBox.Value=1;
                app.KOACheckBox.Value=1;
                %app.RTCheckBox.Value=1;
                app.pKaCheckBox.Value=1;
                app.LogDCheckBox.Value=1;
            else
                app.LogPCheckBox.Value=0;
                app.MPCheckBox.Value=0;
                app.BPCheckBox.Value=0;
                app.VPCheckBox.Value=0;
                app.WSCheckBox.Value=0;
                app.HLCheckBox.Value=0;
                app.KOACheckBox.Value=0;
                %app.RTCheckBox.Value=0;
                app.pKaCheckBox.Value=0;
                app.LogDCheckBox.Value=0;
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
myicon = imread('Icon_ba.png');
msgbox(sprintf(['OPERA ',app.SubVersion,' ',app.Date,'\nhttps://github.com/NIEHS/OPERA\nSupport: kamel.mansouri@nih.gov']),'About','custom',myicon);
        end

        % Value changed function: EnvironmentalfateCheckBox
        function EnvironmentalfateCheckBoxValueChanged(app, event)
            value = app.EnvironmentalfateCheckBox.Value;
            if value
                app.LogBCFCheckBox.Value=1;
                app.AOHCheckBox.Value=1;
                app.BiodegCheckBox.Value=1;
                app.RBiodegCheckBox.Value=1;
                app.KMCheckBox.Value=1;
                app.KOCCheckBox.Value=1;
            else
                app.LogBCFCheckBox.Value=0;
                app.AOHCheckBox.Value=0;
                app.BiodegCheckBox.Value=0;
                app.RBiodegCheckBox.Value=0;
                app.KMCheckBox.Value=0;
                app.KOCCheckBox.Value=0;
            end
        end

        % Value changed function: ToxicityendpointsCheckBox
        function ToxicityendpointsCheckBoxValueChanged(app, event)
            value = app.ToxicityendpointsCheckBox.Value;
            if value
                app.ERCERAPPCheckBox.Value=1;
                app.ARCoMPARACheckBox.Value=1;
                app.AcuteToxCATMoSCheckBox.Value=1;
            else
                app.ERCERAPPCheckBox.Value=0;
                app.ARCoMPARACheckBox.Value=0;
                app.AcuteToxCATMoSCheckBox.Value=0;
            end
        end

        % Button pushed function: InfoModels
        function InfoModelsPushed(app, event)
            %
            %f = figure('menu','none','toolbar','none','Name','Models','NumberTitle','off');
            fid = fopen('Endpoints.txt');
            %ph = uipanel(f,'Units','normalized'); %'title','Display window');
            %lbh = uicontrol(ph,'style','listbox','Units','normalized','position',[0 0 1 1],'FontSize',9);
            indic = 1;
            while 1
                tline = fgetl(fid);
                if ~ischar(tline)
                    break
                end
                strings{indic}=tline;
                indic = indic + 1;
            end
            fclose(fid);
            %set(lbh,'string',strings);
            %set(lbh,'Value',1);
            %set(lbh,'Selected','on');
            msgbox(strings,['Models (V',app.SubVersion,')'],'modal');
        end

        % Button pushed function: InfoOutput
        function InfoOutputButtonPushed(app, event)
            %f = figure('menu','none','toolbar','none','Name','Models','NumberTitle','off');
            fid = fopen('output_options.txt');
            %ph = uipanel(f,'Units','normalized'); %'title','Display window');
            %lbh = uicontrol(ph,'style','listbox','Units','normalized','position',[0 0 1 1],'FontSize',9);
            indic = 1;
            while 1
                tline = fgetl(fid);
                if ~ischar(tline)
                    break
                end
                strings{indic}=tline;
                indic = indic + 1;
            end
            fclose(fid);
            %set(lbh,'string',strings);
            %set(lbh,'Value',1);
            %set(lbh,'Selected','on');
            msgbox(strings,'Output options','modal');
        end

        % Button pushed function: InfoResutls
        function InfoResutlsButtonPushed(app, event)
            msgbox(sprintf('Different steps and time of excecution:\n - Loading structures from SMI, SDF or MOL input file\n - Descriptor calcualtion using PaDEL & CDK)\n - Running the models and writing resutls'),'Results summary');
        end

        % Callback function
        function StandardizeButtonValueChanged(app, event)
            msgbox(sprintf('Generate QSAR-ready structures.'), 'Coming soon!','warn');
        end

        % Value changed function: ADMEpropertiesCheckBox
        function ADMEpropertiesCheckBoxValueChanged(app, event)
            value = app.ADMEpropertiesCheckBox.Value;
            if value
                app.FUBCheckBox.Value=1;
                app.ClintCheckBox.Value=1;
                app.Caco2CheckBox.Value=1;
            else
                app.FUBCheckBox.Value=0;
                app.ClintCheckBox.Value=0;
                app.Caco2CheckBox.Value=0;
            end
        end

        % Button pushed function: InfoModels_2
        function InfoModels_2ButtonPushed(app, event)
            %f = figure('menu','none','toolbar','none','Name','Models','NumberTitle','off');
            fid = fopen('input_file.txt');
            %ph = uipanel(f,'Units','normalized'); %'title','Display window');
            %lbh = uicontrol(ph,'style','listbox','Units','normalized','position',[0 0 1 1],'FontSize',9);
            indic = 1;
            while 1
                tline = fgetl(fid);
                if ~ischar(tline)
                    break
                end
                strings{indic}=tline;
                indic = indic + 1;
            end
            fclose(fid);
            %set(lbh,'string',strings);
            %set(lbh,'Value',1);
            %set(lbh,'Selected','on');
            msgbox(strings,'Input file','modal');
        end

        % Button pushed function: InfoModels_3
        function InfoModels_3ButtonPushed(app, event)
            %f = figure('menu','none','toolbar','none','Name','Models','NumberTitle','off');
            fid = fopen('output_file.txt');
            %ph = uipanel(f,'Units','normalized'); %'title','Display window');
            %lbh = uicontrol(ph,'style','listbox','Units','normalized','position',[0 0 1 1],'FontSize',9);
            indic = 1;
            while 1
                tline = fgetl(fid);
                if ~ischar(tline)
                    break
                end
                strings{indic}=tline;
                indic = indic + 1;
            end
            fclose(fid);
            %set(lbh,'string',strings);
            %set(lbh,'Value',1);
            %set(lbh,'Selected','on');
            msgbox(strings,'Output file','modal');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OPERA = uifigure('Visible', 'off');
            app.OPERA.Name = 'OPERA 2.9';
            app.BrowseButton = uibutton(app.OPERA, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.InputLabel = uilabel(app.OPERA);
            app.InputLabel.Text = 'Input';
            app.InputTextArea = uitextarea(app.OPERA);
            app.OutputTextAreaLabel = uilabel(app.OPERA);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.OPERA);
            app.CalculateButton = uibutton(app.OPERA, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ModelsPanel = uipanel(app.OPERA);
            app.ModelsPanel.Title = 'Models';
            app.LogPCheckBox = uicheckbox(app.ModelsPanel);
            app.LogPCheckBox.Text = 'LogP';
            app.MPCheckBox = uicheckbox(app.ModelsPanel);
            app.MPCheckBox.Text = 'MP';
            app.PhyschempropertiesCheckBox = uicheckbox(app.ModelsPanel);
            app.PhyschempropertiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhyschempropertiesCheckBoxValueChanged, true);
            app.PhyschempropertiesCheckBox.Text = 'Physchem properties';
            app.BPCheckBox = uicheckbox(app.ModelsPanel);
            app.BPCheckBox.Text = 'BP';
            app.VPCheckBox = uicheckbox(app.ModelsPanel);
            app.VPCheckBox.Text = 'VP';
            app.WSCheckBox = uicheckbox(app.ModelsPanel);
            app.WSCheckBox.Text = 'WS';
            app.HLCheckBox = uicheckbox(app.ModelsPanel);
            app.HLCheckBox.Text = 'HL';
            app.KOACheckBox = uicheckbox(app.ModelsPanel);
            app.KOACheckBox.Text = 'KOA';
            app.pKaCheckBox = uicheckbox(app.ModelsPanel);
            app.pKaCheckBox.Text = 'pKa';
            app.LogDCheckBox = uicheckbox(app.ModelsPanel);
            app.LogDCheckBox.Text = 'LogD';
            app.EnvironmentalfateCheckBox = uicheckbox(app.ModelsPanel);
            app.EnvironmentalfateCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnvironmentalfateCheckBoxValueChanged, true);
            app.EnvironmentalfateCheckBox.Text = 'Environmental fate';
            app.ToxicityendpointsCheckBox = uicheckbox(app.ModelsPanel);
            app.ToxicityendpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToxicityendpointsCheckBoxValueChanged, true);
            app.ToxicityendpointsCheckBox.Text = 'Toxicity endpoints';
            app.LogBCFCheckBox = uicheckbox(app.ModelsPanel);
            app.LogBCFCheckBox.Text = 'LogBCF';
            app.AOHCheckBox = uicheckbox(app.ModelsPanel);
            app.AOHCheckBox.Text = 'AOH';
            app.BiodegCheckBox = uicheckbox(app.ModelsPanel);
            app.BiodegCheckBox.Text = 'Biodeg';
            app.RBiodegCheckBox = uicheckbox(app.ModelsPanel);
            app.RBiodegCheckBox.Text = 'R-Biodeg';
            app.KMCheckBox = uicheckbox(app.ModelsPanel);
            app.KMCheckBox.Text = 'KM';
            app.KOCCheckBox = uicheckbox(app.ModelsPanel);
            app.KOCCheckBox.Text = 'KOC';
            app.ERCERAPPCheckBox = uicheckbox(app.ModelsPanel);
            app.ERCERAPPCheckBox.Text = 'ER (CERAPP)';
            app.ARCoMPARACheckBox = uicheckbox(app.ModelsPanel);
            app.ARCoMPARACheckBox.Text = 'AR (CoMPARA)';
            app.AcuteToxCATMoSCheckBox = uicheckbox(app.ModelsPanel);
            app.AcuteToxCATMoSCheckBox.Text = 'AcuteTox (CATMoS)';
            app.ADMEpropertiesCheckBox = uicheckbox(app.ModelsPanel);
            app.ADMEpropertiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ADMEpropertiesCheckBoxValueChanged, true);
            app.ADMEpropertiesCheckBox.Text = 'ADME properties';
            app.FUBCheckBox = uicheckbox(app.ModelsPanel);
            app.FUBCheckBox.Text = 'FUB';
            app.ClintCheckBox = uicheckbox(app.ModelsPanel);
            app.ClintCheckBox.Text = 'Clint';
            app.Caco2CheckBox = uicheckbox(app.ModelsPanel);
            app.Caco2CheckBox.Text = 'Caco2';
            app.ResultssummaryTextAreaLabel = uilabel(app.OPERA);
            app.ResultssummaryTextAreaLabel.Text = 'Results summary';
            app.ResultssummaryTextArea = uitextarea(app.OPERA);
            app.ResultssummaryTextArea.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.ResultssummaryTextArea.Editable = 'off';
            app.OutputoptionsPanel = uipanel(app.OPERA);
            app.OutputoptionsPanel.Title = 'Output options';
            app.SeparatefilesCheckBox = uicheckbox(app.OutputoptionsPanel);
            app.SeparatefilesCheckBox.Text = 'Separate files';
            app.NearestneighborsCheckBox = uicheckbox(app.OutputoptionsPanel);
            app.NearestneighborsCheckBox.Text = 'Nearest neighbors';
            app.KeepfulldescriptorsfilesCheckBox = uicheckbox(app.OutputoptionsPanel);
            app.KeepfulldescriptorsfilesCheckBox.Text = 'Keep full descriptors files';
            app.IncludedescriptorvaluesCheckBox = uicheckbox(app.OutputoptionsPanel);
            app.IncludedescriptorvaluesCheckBox.Text = 'Include descriptor values';
            app.ExperimentalvaluesCheckBox = uicheckbox(app.OutputoptionsPanel);
            app.ExperimentalvaluesCheckBox.Text = 'Experimental values';
            app.Button_2 = uibutton(app.OPERA, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'Logo2.png';
            app.Button_2.Text = '';
            app.InfoModels = uibutton(app.OPERA, 'push');
            app.InfoModels.ButtonPushedFcn = createCallbackFcn(app, @InfoModelsPushed, true);
            app.InfoModels.Icon = '996108.png';
            app.InfoModels.Text = '';
            app.InfoOutput = uibutton(app.OPERA, 'push');
            app.InfoOutput.ButtonPushedFcn = createCallbackFcn(app, @InfoOutputButtonPushed, true);
            app.InfoOutput.Icon = '996108.png';
            app.InfoOutput.Text = '';
            app.BrowseButton_2 = uibutton(app.OPERA, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.InfoResutls = uibutton(app.OPERA, 'push');
            app.InfoResutls.ButtonPushedFcn = createCallbackFcn(app, @InfoResutlsButtonPushed, true);
            app.InfoResutls.Icon = '996108.png';
            app.InfoResutls.Text = '';
            app.InfoModels_2 = uibutton(app.OPERA, 'push');
            app.InfoModels_2.ButtonPushedFcn = createCallbackFcn(app, @InfoModels_2ButtonPushed, true);
            app.InfoModels_2.Icon = '996108.png';
            app.InfoModels_2.Text = '';
            app.InfoModels_3 = uibutton(app.OPERA, 'push');
            app.InfoModels_3.ButtonPushedFcn = createCallbackFcn(app, @InfoModels_3ButtonPushed, true);
            app.InfoModels_3.Icon = '996108.png';
            app.InfoModels_3.Text = '';
            app.StandardizeSwitch = uiswitch(app.OPERA, 'slider');
            app.StandardizeSwitch.Value = 'On';
            app.StandardizeLabel = uilabel(app.OPERA);
            app.StandardizeLabel.Text = 'Standardize';
            % Show the figure after all components are created
            app.OPERA.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OPERA_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OPERA)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OPERA)
        end
    end
end